classdef ea_discfiberexplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ToolsMenu                       matlab.ui.container.Menu
        RefreshInformationfromLeadGroupprojectMenu  matlab.ui.container.Menu
        SetseedforrandomnumbergeneratorMenu  matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        SingleTractAnalysisTab          matlab.ui.container.Tab
        MultTestStrategy                matlab.ui.control.DropDown
        AutoRefreshCheckBox             matlab.ui.control.CheckBox
        RefreshViewButton               matlab.ui.control.Button
        ConsiderSignificantFibersonlyCheckBox  matlab.ui.control.CheckBox
        ExportasAtlasButton             matlab.ui.control.Button
        ShowNegativeFibersCheckBox      matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox      matlab.ui.control.CheckBox
        ColorButtonNegative             matlab.ui.control.Button
        ShowAmountSliderPositive        matlab.ui.control.Slider
        ShowAmountSliderLabel           matlab.ui.control.Label
        LeftSliderPositive              matlab.ui.control.Slider
        LeftSliderLabel                 matlab.ui.control.Label
        RightSliderPositive             matlab.ui.control.Slider
        RightSliderLabel                matlab.ui.control.Label
        LeftSliderNegative              matlab.ui.control.Slider
        LeftSlider_2Label               matlab.ui.control.Label
        ShowAmountSliderNegative        matlab.ui.control.Slider
        ShowAmountSlider_2Label         matlab.ui.control.Label
        RightSliderNegative             matlab.ui.control.Slider
        RightSlider_2Label              matlab.ui.control.Label
        levelEditField                  matlab.ui.control.NumericEditField
        levelEditFieldLabel             matlab.ui.control.Label
        ColorButtonPositive             matlab.ui.control.Button
        ColorBar                        matlab.ui.control.UIAxes
        Subscore_1Tab                   matlab.ui.container.Tab
        AutoRefreshCheckBox_12          matlab.ui.control.CheckBox
        RefreshViewButton_12            matlab.ui.control.Button
        ShowNegativeFibersCheckBox_2    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_2    matlab.ui.control.CheckBox
        ColorButtonNegative_2           matlab.ui.control.Button
        ShowAmountSliderPositive_2      matlab.ui.control.Slider
        ShowAmountSliderLabel_2         matlab.ui.control.Label
        LeftSliderPositive_2            matlab.ui.control.Slider
        LeftSliderLabel_2               matlab.ui.control.Label
        RightSliderPositive_2           matlab.ui.control.Slider
        RightSliderLabel_2              matlab.ui.control.Label
        LeftSliderNegative_2            matlab.ui.control.Slider
        LeftSlider_2Label_2             matlab.ui.control.Label
        ShowAmountSliderNegative_2      matlab.ui.control.Slider
        ShowAmountSlider_2Label_2       matlab.ui.control.Label
        RightSliderNegative_2           matlab.ui.control.Slider
        RightSlider_2Label_2            matlab.ui.control.Label
        ColorButtonPositive_2           matlab.ui.control.Button
        Subscore_2Tab                   matlab.ui.container.Tab
        AutoRefreshCheckBox_3           matlab.ui.control.CheckBox
        RefreshViewButton_3             matlab.ui.control.Button
        ShowNegativeFibersCheckBox_3    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_3    matlab.ui.control.CheckBox
        ColorButtonNegative_3           matlab.ui.control.Button
        ShowAmountSliderPositive_3      matlab.ui.control.Slider
        ShowAmountSliderLabel_3         matlab.ui.control.Label
        LeftSliderPositive_3            matlab.ui.control.Slider
        LeftSliderLabel_3               matlab.ui.control.Label
        RightSliderPositive_3           matlab.ui.control.Slider
        RightSliderLabel_3              matlab.ui.control.Label
        LeftSliderNegative_3            matlab.ui.control.Slider
        LeftSlider_2Label_3             matlab.ui.control.Label
        ShowAmountSliderNegative_3      matlab.ui.control.Slider
        ShowAmountSlider_2Label_3       matlab.ui.control.Label
        RightSliderNegative_3           matlab.ui.control.Slider
        RightSlider_2Label_3            matlab.ui.control.Label
        ColorButtonPositive_3           matlab.ui.control.Button
        Subscore_3Tab                   matlab.ui.container.Tab
        AutoRefreshCheckBox_4           matlab.ui.control.CheckBox
        RefreshViewButton_4             matlab.ui.control.Button
        ShowNegativeFibersCheckBox_4    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_4    matlab.ui.control.CheckBox
        ColorButtonNegative_4           matlab.ui.control.Button
        ShowAmountSliderPositive_4      matlab.ui.control.Slider
        ShowAmountSliderLabel_4         matlab.ui.control.Label
        LeftSliderPositive_4            matlab.ui.control.Slider
        LeftSliderLabel_4               matlab.ui.control.Label
        RightSliderPositive_4           matlab.ui.control.Slider
        RightSliderLabel_4              matlab.ui.control.Label
        LeftSliderNegative_4            matlab.ui.control.Slider
        LeftSlider_2Label_4             matlab.ui.control.Label
        ShowAmountSliderNegative_4      matlab.ui.control.Slider
        ShowAmountSlider_2Label_4       matlab.ui.control.Label
        RightSliderNegative_4           matlab.ui.control.Slider
        RightSlider_2Label_4            matlab.ui.control.Label
        ColorButtonPositive_4           matlab.ui.control.Button
        Subscore_4Tab                   matlab.ui.container.Tab
        AutoRefreshCheckBox_5           matlab.ui.control.CheckBox
        RefreshViewButton_5             matlab.ui.control.Button
        ShowNegativeFibersCheckBox_5    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_5    matlab.ui.control.CheckBox
        ColorButtonNegative_5           matlab.ui.control.Button
        ShowAmountSliderPositive_5      matlab.ui.control.Slider
        ShowAmountSliderLabel_5         matlab.ui.control.Label
        LeftSliderPositive_5            matlab.ui.control.Slider
        LeftSliderLabel_5               matlab.ui.control.Label
        RightSliderPositive_5           matlab.ui.control.Slider
        RightSliderLabel_5              matlab.ui.control.Label
        LeftSliderNegative_5            matlab.ui.control.Slider
        LeftSlider_2Label_5             matlab.ui.control.Label
        ShowAmountSliderNegative_5      matlab.ui.control.Slider
        ShowAmountSlider_2Label_5       matlab.ui.control.Label
        RightSliderNegative_5           matlab.ui.control.Slider
        RightSlider_2Label_5            matlab.ui.control.Label
        ColorButtonPositive_5           matlab.ui.control.Button
        Subscore_5Tab                   matlab.ui.container.Tab
        AutoRefreshCheckBox_6           matlab.ui.control.CheckBox
        RefreshViewButton_6             matlab.ui.control.Button
        ShowNegativeFibersCheckBox_6    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_6    matlab.ui.control.CheckBox
        ColorButtonNegative_6           matlab.ui.control.Button
        ShowAmountSliderPositive_6      matlab.ui.control.Slider
        ShowAmountSliderLabel_6         matlab.ui.control.Label
        LeftSliderPositive_6            matlab.ui.control.Slider
        LeftSliderLabel_6               matlab.ui.control.Label
        RightSliderPositive_6           matlab.ui.control.Slider
        RightSliderLabel_6              matlab.ui.control.Label
        LeftSliderNegative_6            matlab.ui.control.Slider
        LeftSlider_2Label_6             matlab.ui.control.Label
        ShowAmountSliderNegative_6      matlab.ui.control.Slider
        ShowAmountSlider_2Label_6       matlab.ui.control.Label
        RightSliderNegative_6           matlab.ui.control.Slider
        RightSlider_2Label_6            matlab.ui.control.Label
        ColorButtonPositive_6           matlab.ui.control.Button
        Subscore_6Tab                   matlab.ui.container.Tab
        AutoRefreshCheckBox_7           matlab.ui.control.CheckBox
        RefreshViewButton_7             matlab.ui.control.Button
        ShowNegativeFibersCheckBox_7    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_7    matlab.ui.control.CheckBox
        ColorButtonNegative_7           matlab.ui.control.Button
        ShowAmountSliderPositive_7      matlab.ui.control.Slider
        ShowAmountSliderLabel_7         matlab.ui.control.Label
        LeftSliderPositive_7            matlab.ui.control.Slider
        LeftSliderLabel_7               matlab.ui.control.Label
        RightSliderPositive_7           matlab.ui.control.Slider
        RightSliderLabel_7              matlab.ui.control.Label
        LeftSliderNegative_7            matlab.ui.control.Slider
        LeftSlider_2Label_7             matlab.ui.control.Label
        ShowAmountSliderNegative_7      matlab.ui.control.Slider
        ShowAmountSlider_2Label_7       matlab.ui.control.Label
        RightSliderNegative_7           matlab.ui.control.Slider
        RightSlider_2Label_7            matlab.ui.control.Label
        ColorButtonPositive_7           matlab.ui.control.Button
        Subscore_7Tab                   matlab.ui.container.Tab
        AutoRefreshCheckBox_8           matlab.ui.control.CheckBox
        RefreshViewButton_8             matlab.ui.control.Button
        ShowNegativeFibersCheckBox_8    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_8    matlab.ui.control.CheckBox
        ColorButtonNegative_8           matlab.ui.control.Button
        ShowAmountSliderPositive_8      matlab.ui.control.Slider
        ShowAmountSliderLabel_8         matlab.ui.control.Label
        LeftSliderPositive_8            matlab.ui.control.Slider
        LeftSliderLabel_8               matlab.ui.control.Label
        RightSliderPositive_8           matlab.ui.control.Slider
        RightSliderLabel_8              matlab.ui.control.Label
        LeftSliderNegative_8            matlab.ui.control.Slider
        LeftSlider_2Label_8             matlab.ui.control.Label
        ShowAmountSliderNegative_8      matlab.ui.control.Slider
        ShowAmountSlider_2Label_8       matlab.ui.control.Label
        RightSliderNegative_8           matlab.ui.control.Slider
        RightSlider_2Label_8            matlab.ui.control.Label
        ColorButtonPositive_8           matlab.ui.control.Button
        Subscore_8Tab                   matlab.ui.container.Tab
        AutoRefreshCheckBox_9           matlab.ui.control.CheckBox
        RefreshViewButton_9             matlab.ui.control.Button
        ShowNegativeFibersCheckBox_9    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_9    matlab.ui.control.CheckBox
        ColorButtonNegative_9           matlab.ui.control.Button
        ShowAmountSliderPositive_9      matlab.ui.control.Slider
        ShowAmountSliderLabel_9         matlab.ui.control.Label
        LeftSliderPositive_9            matlab.ui.control.Slider
        LeftSliderLabel_9               matlab.ui.control.Label
        RightSliderPositive_9           matlab.ui.control.Slider
        RightSliderLabel_9              matlab.ui.control.Label
        LeftSliderNegative_9            matlab.ui.control.Slider
        LeftSlider_2Label_9             matlab.ui.control.Label
        ShowAmountSliderNegative_9      matlab.ui.control.Slider
        ShowAmountSlider_2Label_9       matlab.ui.control.Label
        RightSliderNegative_9           matlab.ui.control.Slider
        RightSlider_2Label_9            matlab.ui.control.Label
        ColorButtonPositive_9           matlab.ui.control.Button
        Subscore_9Tab                   matlab.ui.container.Tab
        AutoRefreshCheckBox_10          matlab.ui.control.CheckBox
        RefreshViewButton_10            matlab.ui.control.Button
        ShowNegativeFibersCheckBox_10   matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_10   matlab.ui.control.CheckBox
        ColorButtonNegative_10          matlab.ui.control.Button
        ShowAmountSliderPositive_10     matlab.ui.control.Slider
        ShowAmountSliderLabel_10        matlab.ui.control.Label
        LeftSliderPositive_10           matlab.ui.control.Slider
        LeftSliderLabel_10              matlab.ui.control.Label
        RightSliderPositive_10          matlab.ui.control.Slider
        RightSliderLabel_10             matlab.ui.control.Label
        LeftSliderNegative_10           matlab.ui.control.Slider
        LeftSlider_2Label_10            matlab.ui.control.Label
        ShowAmountSliderNegative_10     matlab.ui.control.Slider
        ShowAmountSlider_2Label_10      matlab.ui.control.Label
        RightSliderNegative_10          matlab.ui.control.Slider
        RightSlider_2Label_10           matlab.ui.control.Label
        ColorButtonPositive_10          matlab.ui.control.Button
        Subscore_10Tab                  matlab.ui.container.Tab
        AutoRefreshCheckBox_11          matlab.ui.control.CheckBox
        RefreshViewButton_11            matlab.ui.control.Button
        ShowNegativeFibersCheckBox_11   matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_11   matlab.ui.control.CheckBox
        ColorButtonNegative_11          matlab.ui.control.Button
        ShowAmountSliderPositive_11     matlab.ui.control.Slider
        ShowAmountSliderLabel_11        matlab.ui.control.Label
        LeftSliderPositive_11           matlab.ui.control.Slider
        LeftSliderLabel_11              matlab.ui.control.Label
        RightSliderPositive_11          matlab.ui.control.Slider
        RightSliderLabel_11             matlab.ui.control.Label
        LeftSliderNegative_11           matlab.ui.control.Slider
        LeftSlider_2Label_11            matlab.ui.control.Label
        ShowAmountSliderNegative_11     matlab.ui.control.Slider
        ShowAmountSlider_2Label_11      matlab.ui.control.Label
        RightSliderNegative_11          matlab.ui.control.Slider
        RightSlider_2Label_11           matlab.ui.control.Label
        ColorButtonPositive_11          matlab.ui.control.Button
        MultitractAnalysisPanel         matlab.ui.container.Panel
        VarianceExpSlider               matlab.ui.control.Slider
        VarianceExpSliderLabel          matlab.ui.control.Label
        PCAsettingsLabel                matlab.ui.control.Label
        PCsEditField                    matlab.ui.control.NumericEditField
        PCsEditFieldLabel               matlab.ui.control.Label
        SubscoresListBox                matlab.ui.control.ListBox
        ModelSetupOptionButtonGroup     matlab.ui.container.ButtonGroup
        SplitColorByPCAButton           matlab.ui.control.RadioButton
        SplitColorBySubscoreButton      matlab.ui.control.RadioButton
        SplitColorByGroupButton         matlab.ui.control.RadioButton
        SingleTractAnalysisButton       matlab.ui.control.RadioButton
        CrossvalidationPredictionPanel  matlab.ui.container.Panel
        NestedCheckBox                  matlab.ui.control.CheckBox
        IterationsEditField             matlab.ui.control.NumericEditField
        IterationsEditFieldLabel        matlab.ui.control.Label
        OpeninCleartuneButton           matlab.ui.control.Button
        MultitractWeightsButton         matlab.ui.control.Button
        ModeltypeDropDown               matlab.ui.control.DropDown
        FittoscoresCheckBox             matlab.ui.control.CheckBox
        BinsEditField                   matlab.ui.control.NumericEditField
        BinsEditFieldLabel              matlab.ui.control.Label
        ModelNormalizationDropDown      matlab.ui.control.DropDown
        ModelNormalizationDropDownLabel  matlab.ui.control.Label
        BasePredictiononDropDown        matlab.ui.control.DropDown
        BasePredictiononDropDownLabel   matlab.ui.control.Label
        LiveVisualizeCheckBox           matlab.ui.control.CheckBox
        kEditField                      matlab.ui.control.EditField
        kEditFieldLabel                 matlab.ui.control.Label
        StrategyDropDown                matlab.ui.control.DropDown
        StrategyDropDownLabel           matlab.ui.control.Label
        PosthoccorrectforgroupCheckBox  matlab.ui.control.CheckBox
        RunCVButton                     matlab.ui.control.Button
        PredictListBox                  matlab.ui.control.ListBox
        PredictListBoxLabel             matlab.ui.control.Label
        TrainOnListBox                  matlab.ui.control.ListBox
        TrainOnListBoxLabel             matlab.ui.control.Label
        VisualizationThresholdsPanel    matlab.ui.container.Panel
        BusyLamp                        matlab.ui.control.Lamp
        BusyLampLabel                   matlab.ui.control.Label
        InteractiveModelSetupPanel      matlab.ui.container.Panel
        SubcohortsDropDown              matlab.ui.control.DropDown
        SubcohortsDropDownLabel         matlab.ui.control.Label
        Corrtype                        matlab.ui.control.DropDown
        ModelSetupDropDown              matlab.ui.control.DropDown
        ModelSetupDropDownLabel         matlab.ui.control.Label
        Efieldmetric                    matlab.ui.control.DropDown
        CovariatesCheckBox              matlab.ui.control.CheckBox
        EfieldthresholdLabel            matlab.ui.control.Label
        EfieldthresholdSlider           matlab.ui.control.Slider
        ConnthresholdLabel              matlab.ui.control.Label
        ConnthresholdSlider             matlab.ui.control.Slider
        CovariatesListBox               matlab.ui.control.ListBox
        VariableofInterestDropDown      matlab.ui.control.DropDown
        VariableofInterestDropDownLabel  matlab.ui.control.Label
        PatientsListBox                 matlab.ui.control.ListBox
        PatientsListBoxLabel            matlab.ui.control.Label
        MirrorSidesCheckBox             matlab.ui.control.CheckBox
        ConnectomeSelectionPanel        matlab.ui.container.Panel
        ConnectivityModelButtonGroup    matlab.ui.container.ButtonGroup
        PAMButton                       matlab.ui.control.RadioButton
        StimulationVolumeButton         matlab.ui.control.RadioButton
        VmLabel                         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ConnectomeDropDown              matlab.ui.control.DropDown
        ConnectomeDropDownLabel         matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        LeadGroupPathLabel              matlab.ui.control.Label
        AnalysisIDEditField             matlab.ui.control.EditField
        AnalysisIDEditFieldLabel        matlab.ui.control.Label
    end


    properties (Access = public)
        tractset % associated ea_disctract object.
     
    end


    methods (Access = private)

        function results = drawcb(app)
            app.isbusy(1);
            if ~isempty(app.tractset.colorbar)
                if strcmp(app.TabGroup.SelectedTab.Title, 'Single Tract Analysis')
                    app.ColorBar.Visible = 'on';
                    cb = app.tractset.colorbar;
                    ea_plot_colorbar(cb.cmap{1}, [], 'h', '', cb.tick{1}, cb.ticklabel{1}, app.ColorBar);
                else
                    app.ColorBar.Visible = 'off';
                    cla(app.ColorBar);
                end
                app.ColorBar.Visible = 'on';
                cb = app.tractset.colorbar;
                ea_plot_colorbar(cb.cmap{1}, [], 'h', '', cb.tick{1}, cb.ticklabel{1}, app.ColorBar);
            else
                app.ColorBar.Visible = 'off';
                cla(app.ColorBar);
            end
            app.isbusy(0);
        end

        function results = enablerules(app,flag)
            app.isbusy(1);
            if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                val='off';
            else
                val=ea_bool2onoff(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_method2methodid(app.tractset)));
            end
            try
                numsides=numel(app.tractset.results.(ea_conn2connid(app.tractset.connectome)).(ea_method2methodid(app.tractset)).fibsval);
            catch % default
                numsides=2;
            end
            % enable controls:
            app.ModelSetupDropDown.Enable = val;
            app.ModelSetupDropDownLabel.Enable = val;


            app.MirrorSidesCheckBox.Enable = val;
            app.VariableofInterestDropDown.Enable = val;

            groupsassigned=(length(unique(app.tractset.M.patient.group))>1);
            if groupsassigned
                app.SplitColorByGroupButton.Enable = val;
            else
                app.SplitColorByGroupButton.Enable = 'off';
                app.SplitColorByGroupButton.Value = 0;
            end

            app.SplitColorBySubscoreButton.Enable = val;
            app.SingleTractAnalysisButton.Enable = val;
            app.SplitColorByPCAButton.Enable = val;


            app.ShowPositiveFibersCheckBox.Enable = val;
            app.ShowNegativeFibersCheckBox.Enable = val;
            app.ShowAmountSliderPositive.Enable = val;
            app.LeftSliderPositive.Enable = val;
            app.RightSliderPositive.Enable = val;
            app.ColorButtonPositive.Enable = val;
            app.ShowNegativeFibersCheckBox.Enable = val;

            app.ConnthresholdSlider.Enable = val;
            app.ConnthresholdLabel.Enable = val;
            app.ShowAmountSliderLabel.Enable = val;
            app.LeftSliderLabel.Enable = val;
            app.RightSliderLabel.Enable = val;
            app.VariableofInterestDropDownLabel.Enable = val;
            app.CovariatesCheckBox.Enable = val;

            if app.SplitColorByGroupButton.Value
                app.ColorBar.Visible = 'off';
                cla(app.ColorBar);
                app.ExportasAtlasButton.Enable = 'off';
                cvEnable = 'off';
            else
                app.ColorBar.Visible = val;
                if strcmp(val, 'off')
                    cla(app.ColorBar);
                end
                app.ExportasAtlasButton.Enable = val;
                cvEnable = val;
            end

            app.SubscoresListBox.Enable = val;
            app.MultitractWeightsButton.Enable = val;
            app.OpeninCleartuneButton.Enable = val;

            % CV Panel:
            app.StrategyDropDown.Enable = cvEnable;
            app.StrategyDropDownLabel.Enable = cvEnable;
            app.kEditField.Enable = cvEnable;
            app.kEditFieldLabel.Enable = cvEnable;
            app.RunCVButton.Enable = cvEnable;
            app.PosthoccorrectforgroupCheckBox.Enable = cvEnable;
            app.ModelNormalizationDropDown.Enable = cvEnable;
            app.ModelNormalizationDropDownLabel.Enable = cvEnable;


            switch app.BasePredictiononDropDown.Value
                case {'Histogram','z-scored Histogram'}
                    app.BinsEditField.Enable = cvEnable;
                    app.BinsEditFieldLabel.Enable = cvEnable;
                otherwise
                    app.BinsEditField.Enable = 'off';
                    app.BinsEditFieldLabel.Enable = 'off';
            end

            switch app.StrategyDropDown.Value
                case 'k-fold (randomized)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = 'k:';
                    app.kEditField.Tooltip = '';
                    app.kEditField.Value = num2str(app.tractset.kfold);
                case 'Leave-Nothing-Out (Permutation-Based)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = '# perms:';
                    app.kEditField.Tooltip = '';
                    app.kEditField.Value = num2str(app.tractset.Nperm);
                case 'Custom (Sets)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = '# sets:';
                    app.kEditField.Tooltip = 'Randomly split the data into N sets';
                    app.kEditField.Value = num2str(app.tractset.Nsets);
                otherwise
                    app.kEditField.Visible = 'off';
                    app.kEditFieldLabel.Visible = 'off';
                    app.kEditField.Tooltip = '';
            end

            switch app.StrategyDropDown.Value
                case 'Custom (Patients)'
                    app.TrainOnListBox.Items=app.tractset.M.patient.list;
                    app.PredictListBox.Items=app.tractset.M.patient.list;
                case 'Custom (Cohorts)'
                    if length(app.tractset.M.groups.group)>1
                        app.TrainOnListBox.Items=sprintfc('%d',(app.tractset.M.groups.group));
                        app.PredictListBox.Items=sprintfc('%d',(app.tractset.M.groups.group));
                    end
                case 'Custom (Sets)'
                    if str2double(app.kEditField.Value)>1
                        app.TrainOnListBox.Items=sprintfc('%d',[1:str2double(app.kEditField.Value)]');
                        app.PredictListBox.Items=sprintfc('%d',[1:str2double(app.kEditField.Value)]');
                    end
                case 'Custom (Subcohorts)'
                    app.TrainOnListBox.Items=app.tractset.setlabels;
                    app.PredictListBox.Items=app.tractset.setlabels;
            end

            switch app.StrategyDropDown.Value
                case 'Custom (Patients)'
                    cvlEnable=cvEnable;
                    if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                case 'Custom (Cohorts)'
                    if length(app.tractset.M.groups.group)>1
                        cvlEnable=cvEnable;
                        if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                            app.RunCVButton.Enable = 'off';
                        end
                    else
                        app.RunCVButton.Enable = 'off';
                        cvlEnable='off';
                    end

                case 'Custom (Sets)'
                    if str2double(app.kEditField.Value)>1
                        if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                            app.RunCVButton.Enable = 'off';
                        end
                        cvlEnable=cvEnable;
                    else
                        app.RunCVButton.Enable = 'off';
                        cvlEnable='off';
                    end
                case 'Custom (Subcohorts)'
                    if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                    cvlEnable='on';
                otherwise
                    cvlEnable='off';
            end

            % CV Listboxes
            app.PredictListBox.Enable = cvlEnable;
            app.PredictListBoxLabel.Enable = cvlEnable;
            app.TrainOnListBox.Enable = cvlEnable;
            app.TrainOnListBoxLabel.Enable = cvlEnable;
            app.ModeltypeDropDown.Enable = cvlEnable;

            app.AutoRefreshCheckBox.Enable = val;
            app.RefreshViewButton.Enable = val;
            app.LiveVisualizeCheckBox.Enable = val;

            if ismember(app.Corrtype.Value,{'Skipped Spearman','Skipped Pearson'})
                app.ConsiderSignificantFibersonlyCheckBox.Enable = 'off';
                app.ConsiderSignificantFibersonlyCheckBox.Value = 0;
                app.levelEditField.Enable = 'off';
                app.levelEditFieldLabel.Enable = 'off';
                app.MultTestStrategy.Enable = 'off';
            else
                app.ConsiderSignificantFibersonlyCheckBox.Enable = val;
                app.levelEditField.Enable = val;
                app.levelEditFieldLabel.Enable = val;
                app.MultTestStrategy.Enable = val;
            end

            if ismember(app.Corrtype.Value,{'Skipped Spearman','Skipped Pearson','Bend'})
                app.CovariatesCheckBox.Enable = 'off';
                app.CovariatesCheckBox.Value = 0;
            end

            app.BasePredictiononDropDown.Enable = val;
            app.BasePredictiononDropDownLabel.Enable = val;
            %app.ModelSetupOptionButtonGroup.Enable = val;


            app.FittoscoresCheckBox.Enable = cvEnable;

            if app.SplitColorByPCAButton.Value % when doing a PCA based prediction, we need to fit back tract estimates to responsevars.
                app.FittoscoresCheckBox.Value = 1;
                app.FittoscoresCheckBox.Enable = 'off';
                app.tractset.doactualprediction = 1; % redundancy just to make sure in fact this should not be needed.
            end

            switch app.FittoscoresCheckBox.Value
                case 1
                    app.ModeltypeDropDown.Enable = cvEnable;
                case 0
                    app.ModeltypeDropDown.Enable = 'off';
            end

            if app.CovariatesCheckBox.Value
                app.CovariatesListBox.Enable = val;
            else
                app.CovariatesListBox.Enable = 'off';
            end

            switch app.ModelSetupDropDown.Value
                case {'T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)','T-Tests / PAM (OSS-DBS)'}
                    app.EfieldthresholdSlider.Enable = 'off';
                    app.EfieldthresholdLabel.Enable = 'off';
                    app.Efieldmetric.Enable = 'off';
                    app.Corrtype.Enable = 'off';
                    app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Histogram','z-scored Histogram'};
                    if ~ismember(app.tractset.basepredictionon,lower(app.BasePredictiononDropDown.Items))
                        app.tractset.basepredictionon=lower(app.BasePredictiononDropDown.Value);
                    end
                case 'Correlations / E-fields (Irmen 2020)'
                    app.EfieldthresholdSlider.Enable = val;
                    app.EfieldthresholdLabel.Enable = val;
                    app.Efieldmetric.Enable = val;
                    app.Corrtype.Enable = val;
                    app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Profile of Scores: Spearman','Profile of Scores: Pearson','Profile of Scores: Bend','Histogram','z-scored Histogram'};
                case 'Dice Coeffs / VTAs (binary vars)'
                    app.EfieldthresholdSlider.Enable = 'off';
                    app.EfieldthresholdLabel.Enable = 'off';
                    app.Efieldmetric.Enable = 'off';
                    app.Corrtype.Enable = 'off';
                    app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Histogram','z-scored Histogram'};
                    if ~ismember(app.tractset.basepredictionon,lower(app.BasePredictiononDropDown.Items))
                        app.tractset.basepredictionon=lower(app.BasePredictiononDropDown.Value);
                    end
                case 'Reverse T-Tests / E-Fields (binary vars)'
                    app.EfieldthresholdSlider.Enable = val;
                    app.EfieldthresholdLabel.Enable = val;
                    app.Efieldmetric.Enable = val;
                    app.Corrtype.Enable = val;
                    app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Profile of Scores: Spearman','Profile of Scores: Pearson','Profile of Scores: Bend','Histogram','z-scored Histogram'};
                case 'Plain Connections'
                    app.EfieldthresholdSlider.Enable = 'off';
                    app.EfieldthresholdLabel.Enable = 'off';
                    app.Efieldmetric.Enable = 'off';
                    app.CovariatesCheckBox.Value = 0;
                    app.CovariatesCheckBox.Enable = 'off';
                    app.CovariatesListBox.Enable = 'off';
                    app.Corrtype.Enable = 'off';
                    app.ConsiderSignificantFibersonlyCheckBox.Value = 0;
                    app.ConsiderSignificantFibersonlyCheckBox.Enable = 'off';
                    app.levelEditField.Enable = 'off';
                    app.MultTestStrategy.Enable = 'off';
                    app.ShowNegativeFibersCheckBox.Value = 0;
                    app.ShowNegativeFibersCheckBox.Enable = 'off';
                    app.BasePredictiononDropDown.Items={'Sum of Scores','Mean of Scores','Peak of Scores','Peak 5% of Scores'};
                    if ~ismember(app.tractset.basepredictionon,lower(app.BasePredictiononDropDown.Items))
                        app.tractset.basepredictionon=lower(app.BasePredictiononDropDown.Value);
                    end
                    app.PosthoccorrectforgroupCheckBox.Value = 0;
            end

            app.PatientsListBox.Enable=val;

            app.SubcohortsDropDown.Enable=val;
            app.SubcohortsDropDown.Items=[{'Choose...', 'All Patients', 'Create Subcohort from Selection', 'Create Subcohort from Inverse Selection'},app.tractset.setlabels];

            switch app.ShowNegativeFibersCheckBox.Value
                case 0
                    app.ShowAmountSliderNegative.Enable = 'off';
                    app.ColorButtonNegative.Enable = 'off';
                    app.ShowAmountSlider_2Label.Enable = 'off';

                    app.LeftSliderNegative.Enable = 'off';
                    app.RightSliderNegative.Enable = 'off';

                    app.LeftSlider_2Label.Enable = 'off';
                    app.RightSlider_2Label.Enable = 'off';

                case 1
                    app.ShowAmountSliderNegative.Enable = val;
                    app.ColorButtonNegative.Enable = val;
                    app.ShowAmountSlider_2Label.Enable = val;
                    if numsides==2 % usual bilateral case
                        app.LeftSliderNegative.Enable = val;
                        app.RightSliderNegative.Enable = val;

                        app.LeftSlider_2Label.Enable = val;
                        app.RightSlider_2Label.Enable = val;
                    else % scripting case e.g. when using only one side
                        app.LeftSliderNegative.Enable = 'off';
                        app.RightSliderNegative.Enable = 'off';

                        app.LeftSlider_2Label.Enable = 'off';
                        app.RightSlider_2Label.Enable = 'off';

                    end
            end

            switch app.ShowPositiveFibersCheckBox.Value
                case 0
                    app.ShowAmountSliderPositive.Enable = 'off';
                    app.ColorButtonPositive.Enable = 'off';
                    app.LeftSliderPositive.Enable = 'off';
                    app.RightSliderPositive.Enable = 'off';

                    app.ShowAmountSliderLabel.Enable = 'off';
                    app.LeftSliderLabel.Enable = 'off';
                    app.RightSliderLabel.Enable = 'off';
                case 1
                    app.ShowAmountSliderPositive.Enable = val;
                    app.ColorButtonPositive.Enable = val;
                    app.ShowAmountSliderLabel.Enable = val;
                    if numsides==2 % usual bilateral case
                        app.LeftSliderPositive.Enable = val;
                        app.RightSliderPositive.Enable = val;

                        app.LeftSliderLabel.Enable = val;
                        app.RightSliderLabel.Enable = val;
                    else % scripting case e.g. when using only one side
                        app.LeftSliderPositive.Enable = 'off';
                        app.RightSliderPositive.Enable = 'off';
                        app.LeftSliderLabel.Enable = 'off';
                        app.RightSliderLabel.Enable = 'off';
                    end

            end

            % Logic of multitract panel radio buttons - enabling of pca panel is
            % handled in updatepca()
            if app.SingleTractAnalysisButton.Value
                app.ColorButtonPositive.Enable = val;
                app.ColorButtonNegative.Enable = val;
                app.SubscoresListBox.Enable = 'off';
                app.CovariatesCheckBox.Enable = val;
                app.CovariatesListBox.Enable = val;
                app.MultitractWeightsButton.Enable = 'off';
                app.SingleTractAnalysisTab.Parent = app.TabGroup;
                app.SingleTractAnalysisTab.Title = 'Single Tract Analysis';
                for i=1:10
                    tab_title = (['Subscore_' num2str(i) 'Tab']);
                    app.(tab_title).Parent = [];
                end
            end
            if app.SplitColorByGroupButton.Value
                app.ColorButtonPositive.Enable = 'off';
                app.ColorButtonNegative.Enable = 'off';
                app.SubscoresListBox.Enable = 'off';
                app.CovariatesCheckBox.Enable = val;
                app.CovariatesListBox.Enable = val;
                app.MultitractWeightsButton.Enable = 'off';
                n_groups = unique(app.tractset.M.patient.group);
                app.SingleTractAnalysisTab.Title = 'Group Fiber Visualization';
                for i=1:10
                    tab_title = (['Subscore_' num2str(i) 'Tab']);
                    if i <= length(n_groups)
                        app.(tab_title).Parent = app.TabGroup;
                        app.(tab_title).Title = ['Group_' num2str(i)];
                    else
                        app.(tab_title).Parent = [];
                    end
                end

            end
            if app.SplitColorBySubscoreButton.Value
                app.ColorButtonPositive.Enable = 'off';
                app.ColorButtonNegative.Enable = 'off';
                app.SubscoresListBox.Enable = val;
                app.CovariatesCheckBox.Enable = 'on';
                app.CovariatesListBox.Enable = 'on';
                app.MultitractWeightsButton.Enable = cvEnable;
                app.OpeninCleartuneButton.Enable = cvEnable;
                app.SingleTractAnalysisTab.Title = 'Mixed Fiber Visualization';
                for i=1:10
                    tab_title = (['Subscore_' num2str(i) 'Tab']);
                    if i <= length(app.tractset.subscore.labels)
                        app.(tab_title).Parent = app.TabGroup;
                        app.(tab_title).Title = app.tractset.subscore.labels{i};
                    else
                        app.(tab_title).Parent = [];
                    end
                end
            end
            if app.SplitColorByPCAButton.Value
                app.ColorButtonPositive.Enable = val;
                app.ColorButtonNegative.Enable = val;
                app.SubscoresListBox.Enable = val;
                app.CovariatesCheckBox.Enable = 'off';
                app.CovariatesListBox.Enable = 'off';
                app.MultitractWeightsButton.Enable = 'off';
                app.SingleTractAnalysisTab.Title = 'PCA Fiber Visualization';
                for i=1:10
                    tab_title = (['Subscore_' num2str(i) 'Tab']);
                    if i <= length(app.tractset.numpcs)
                        app.(tab_title).Parent = app.TabGroup;
                        app.(tab_title).Title = ['PC_' num2str(i)];
                    else
                        app.(tab_title).Parent = [];
                    end
                end
            end
            %end
            app.updatepca;
            % turn light back to green
            app.isbusy(0);
        end


        function results = tractset2UI(app,flag)
            app.isbusy(1);

            app.ModelSetupDropDown.Enable = 'on';
            app.ModelSetupDropDownLabel.Enable = 'on';
            app.ConnectomeDropDown.Enable = 'on';
            app.ConnectomeDropDownLabel.Enable = 'on';
            app.CalculateButton.Enable = 'on';

            % derive everything from tractset obj:
            app.LeadGroupPathLabel.Text=app.tractset.leadgroup;
            app.LeadGroupPathLabel.Tooltip=app.tractset.leadgroup;
            app.AnalysisIDEditField.Value = app.tractset.ID;
            switch app.tractset.statmetric
                case {1,3}
                    app.ModelSetupDropDown.Value = 'T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)';
                    if isfield(app.tractset.M,'pseudoM') % custom scripting input
                        app.CovariatesListBox.Items=['Group / Cohort regressor',app.tractset.M.clinical.labels];
                    else % regular case
                        app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','VTA Volume',app.tractset.M.clinical.labels];
                    end
                    app.ConnthresholdSlider.Limits = [1,49];
                case 2
                    app.ModelSetupDropDown.Value = 'Correlations / E-fields (Irmen 2020)';
                    if isfield(app.tractset.M,'pseudoM') % custom scripting input
                        app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','E-Field Magnitude Sum',app.tractset.M.clinical.labels];
                    else % regular case
                        app.CovariatesListBox.Items=['Group / Cohort regressor',app.tractset.M.clinical.labels];
                    end
                    app.ConnthresholdSlider.Limits = [0,100];
                
                case 4
                    app.ModelSetupDropDown.Value = 'Dice Coeffs / VTAs (binary vars)';
                    if isfield(app.tractset.M,'pseudoM') % custom scripting input
                        app.CovariatesListBox.Items=['Group / Cohort regressor',app.tractset.M.clinical.labels];
                    else % regular case
                        app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','VTA Volume',app.tractset.M.clinical.labels];
                    end
                    app.ConnthresholdSlider.Limits = [1,49];
                case 5
                    app.ModelSetupDropDown.Value = 'Reverse T-Tests / E-Fields (binary vars)';
                    if isfield(app.tractset.M,'pseudoM') % custom scripting input
                        app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','E-Field Magnitude Sum',app.tractset.M.clinical.labels];
                    else % regular case
                        app.CovariatesListBox.Items=['Group / Cohort regressor',app.tractset.M.clinical.labels];
                    end
                    app.ConnthresholdSlider.Limits = [0,100];
                case 6
                    app.ModelSetupDropDown.Value = 'Plain Connections';
                    if isfield(app.tractset.M,'pseudoM') % custom scripting input
                        app.CovariatesListBox.Items=['Group / Cohort regressor',app.tractset.M.clinical.labels];
                    else % regular case
                        app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','VTA Volume',app.tractset.M.clinical.labels];
                    end
                    app.ConnthresholdSlider.Limits = [1,100];
            end

            app.Corrtype.Value=app.tractset.corrtype;
            app.ConsiderSignificantFibersonlyCheckBox.Value = app.tractset.showsignificantonly;
            app.levelEditField.Value = (app.tractset.alphalevel);
            app.MultTestStrategy.Value = app.tractset.multcompstrategy;
            try % could be that entry is not in the items list.
                app.BasePredictiononDropDown.Value = app.tractset.basepredictionon;
            end
            if ~isempty(app.tractset.connectome) && ismember(app.tractset.connectome, app.ConnectomeDropDown.Items)
                app.ConnectomeDropDown.Value = app.tractset.connectome;
            end

            app.MirrorSidesCheckBox.Value = app.tractset.mirrorsides;

            app.PatientsListBox.Items=app.tractset.M.patient.list;
            app.PatientsListBox.Value=app.PatientsListBox.Items(app.tractset.patientselection);
            app.SubcohortsDropDown.Value='Choose...';
            app.SubcohortsDropDown.Items=[{'Choose...'},{'All Patients'},{'Set from Selection'},{'Inverse Set from Selection'},...
                app.tractset.setlabels];
            for set=1:length(app.tractset.setlabels)
                if isequal(app.tractset.patientselection,find(app.tractset.setselections{set}))
                    app.SubcohortsDropDown.Value=app.tractset.setlabels{set};
                end
            end
            if isequal(app.tractset.patientselection,1:length(app.tractset.M.patient.list))
                app.SubcohortsDropDown.Value='All Patients';
            end

            app.Efieldmetric.Value=app.tractset.efieldmetric;
            if isempty(app.tractset.subscore.labels)
                app.tractset.multitractmode = 'Single Tract Analysis';
            end
            % setup calculation threshold (only used when calculating
            % fibers for a new connectome).
            if ~isempty(app.tractset.calcthreshold)
                app.ThresholdEditField.Value = app.tractset.calcthreshold;
                if isfield(app.tractset.M,'pseudoM')
                    app.VmLabel.Text = ''; % not a VTA setting, V/m does not apply. Could be arbitrary units here.
                end
            else
                prefs=ea_prefs;
                if isfield(app.tractset.M,'pseudoM')
                    app.VmLabel.Text = ''; % not a VTA setting, V/m does not apply. Could be arbitrary units here.
                    app.ThresholdEditField.Value=0;
                else
                    app.ThresholdEditField.Value=prefs.machine.vatsettings.horn_ethresh*1000; % set to a default value based on prefs.
                end
            end

            if ~isempty(app.tractset.efieldthreshold)
                app.setefieldsliders;
                if strcmp(app.Efieldmetric.Value,'Mean')
                    app.EfieldthresholdSlider.Value = app.tractset.efieldthreshold;
                else
                    app.EfieldthresholdSlider.Value = app.tractset.efieldthreshold/1000;
                end
            end

            app.EfieldthresholdLabel.Text={['Tracts connected if ',lower(app.tractset.efieldmetric),' E-Field Magnitude'] ['they traverse is > ',sprintf('%.1f',app.EfieldthresholdSlider.Value),' ',app.getvms]};

            app.ConnthresholdSlider.Value = app.tractset.connthreshold;

            switch app.tractset.statmetric
                case {1,3,4}
                    bubbles='VTAs';
                    app.ConnthresholdLabel.Text = {['Tracts connected to > ',sprintf('%.1f',app.ConnthresholdSlider.Value),' %'],['and < ',sprintf('%.1f',100-app.ConnthresholdSlider.Value),' % of ',bubbles]};
                case {2,5}
                    bubbles='E-Fields';
                    app.ConnthresholdLabel.Text = ['Tracts connected to > ',sprintf('%.1f',app.ConnthresholdSlider.Value),' % of ',bubbles];
                case 6
                    bubbles='VTAs';
                    app.ConnthresholdLabel.Text = ['Tracts connected to > ',sprintf('%.1f',app.ConnthresholdSlider.Value),' % of ',bubbles];
            end

            app.PosthoccorrectforgroupCheckBox.Value = app.tractset.adjustforgroups;

            app.VariableofInterestDropDown.Items = app.tractset.M.clinical.labels;
            if ismember(app.tractset.responsevarlabel,app.tractset.M.clinical.labels)
               app.VariableofInterestDropDown.Value = app.tractset.responsevarlabel;
            else
                app.VariableofInterestDropDown.Value = app.tractset.M.clinical.labels{1};
            end
            app.FittoscoresCheckBox.Value = app.tractset.doactualprediction;

            app.SubscoresListBox.Items = app.tractset.M.clinical.labels;
            if ~isempty(app.tractset.subscore.labels)
                app.SubscoresListBox.Value = app.tractset.subscore.labels;
            end
            [~,ix]=ismember(app.VariableofInterestDropDown.Value,app.CovariatesListBox.Items);
            if ~isempty(ix)
                app.CovariatesListBox.Items(ix)=[];
            end

            app.CovariatesListBox.Value=app.tractset.covarlabels;

            if ~isempty(app.tractset.results)
                app.CovariatesCheckBox.Value=~isempty(app.tractset.covarlabels);
            end
            switch app.tractset.multitractmode
                case 'Single Tract Analysis'
                    app.SplitColorByGroupButton.Value = 0;
                    app.SplitColorByPCAButton.Value = 0;
                    app.SplitColorBySubscoreButton.Value = 0;
                    app.SingleTractAnalysisButton.Value = 1;
                case 'Split & Color By Group'
                    app.SplitColorByGroupButton.Value = 1;
                    app.SplitColorByPCAButton.Value = 0;
                    app.SplitColorBySubscoreButton.Value = 0;
                    app.SingleTractAnalysisButton.Value = 0;
                case 'Split & Color By PCA'
                    app.SplitColorByGroupButton.Value = 0;
                    app.SplitColorByPCAButton.Value = 1;
                    app.SplitColorBySubscoreButton.Value = 0;
                    app.SingleTractAnalysisButton.Value = 0;
                case 'Split & Color By Subscore'
                    app.SplitColorByGroupButton.Value = 0;
                    app.SplitColorByPCAButton.Value = 0;
                    app.SplitColorBySubscoreButton.Value = 1;
                    app.SingleTractAnalysisButton.Value = 0;
            end


            app.PCsEditField.Value = app.tractset.numpcs;

            app.FittoscoresCheckBox.Value = app.tractset.doactualprediction;

            app.ShowPositiveFibersCheckBox.Value = app.tractset.posvisible;
            try
                if app.tractset.statmetric == 6
                    app.ShowPositiveFibersCheckBox.Text = ['Show Connected Fibers (',num2str(sum(app.tractset.stats.pos.available)),')'];
                else
                    app.ShowPositiveFibersCheckBox.Text = ['Show Positive Fibers (',num2str(sum(app.tractset.stats.pos.available)),')'];
                end
            end

            app.ShowNegativeFibersCheckBox.Value = app.tractset.negvisible;
            try
                app.ShowNegativeFibersCheckBox.Text = ['Show Negative Fibers (',num2str(sum(app.tractset.stats.neg.available)),')'];
            end
            % Amount sliders:
            app.ShowAmountSliderPositive.Value = mean(app.tractset.showposamount);
            app.ShowAmountSliderPositive.Tooltip = sprintf('%d%%', round(app.ShowAmountSliderPositive.Value));
            try
                app.ShowAmountSliderLabel.Text = {'Show Amount', ['(',num2str(sum(app.tractset.stats.pos.shown)),')']};
            end
            

            app.ShowAmountSliderNegative.Value = mean(app.tractset.shownegamount);
            app.ShowAmountSliderNegative.Tooltip = sprintf('%d%%', round(app.ShowAmountSliderNegative.Value));
            try
                app.ShowAmountSlider_2Label.Text = {'Show Amount', ['(',num2str(sum(app.tractset.stats.neg.shown)),')']};
            end

            app.LeftSliderPositive.Value = app.tractset.showposamount(2);
            app.LeftSliderPositive.Tooltip = sprintf('%d%%', round(app.LeftSliderPositive.Value));
            try
                app.LeftSliderLabel.Text = {'Left', ['(',num2str(app.tractset.stats.pos.shown(2)),')']};
            end

            app.RightSliderPositive.Value = app.tractset.showposamount(1);
            app.RightSliderPositive.Tooltip = sprintf('%d%%', round(app.RightSliderPositive.Value));
            try
                app.RightSliderLabel.Text = {'Right', ['(',num2str(app.tractset.stats.pos.shown(1)),')']};
            end

            app.LeftSliderNegative.Value = app.tractset.shownegamount(2);
            app.LeftSliderNegative.Tooltip = sprintf('%d%%', round(app.LeftSliderNegative.Value));
            try
                app.LeftSlider_2Label.Text = {'Left', ['(',num2str(app.tractset.stats.neg.shown(2)),')']};
            end

            app.RightSliderNegative.Value = app.tractset.shownegamount(1);
            app.RightSliderNegative.Tooltip = sprintf('%d%%', round(app.RightSliderNegative.Value));
            try
                app.RightSlider_2Label.Text = {'Right', ['(',num2str(app.tractset.stats.neg.shown(1)),')']};
            end
            if size(app.tractset.subscore.labels,2) > 1
                keyboard
            end
            %%%Slider Labels for subscores
            if strcmp(app.tractset.multitractmode,'Split & Color By Subscore')
                %find the index of the current subscore you are working
                %with
                selected_indx = cellfun(@(x)isequal(x,app.TabGroup.SelectedTab.Title),app.tractset.subscore.labels);
                if ~isempty(find(selected_indx)) %will be empty when single tract analysis is chosen
                    indx = find(selected_indx);
                end
            elseif strcmp(app.tractset.multitractmode,'Split & Color By PCA') || strcmp(app.tractset.multitractmode,'Split & Color By Group') %usually,in PCAs and Groups
                % there is no reason to use the isequal cell function, because
                % they are already ordered (and will be when they are
                % called in the ea_distract)
                selected_indx = strsplit(app.TabGroup.SelectedTab.Title,'_');
                if length(selected_indx) > 1
                    indx = str2double(selected_indx{2});
                end
            end
            if exist('indx','var')
                checkbox_pos = ['ShowPositiveFibersCheckBox_' num2str(indx+1)];
                pos_slider = ['ShowAmountSliderLabel_' num2str(indx+1)];
                left_pos_slider = ['LeftSliderLabel_' num2str(indx+1)];
                right_pos_slider = ['RightSliderLabel_' num2str(indx+1)];
                app.(checkbox_pos).Text = ['Show Positive Fibers (',num2str(sum(app.tractset.subscore.vis.pos_available(indx,:))),')'];
                app.(pos_slider).Text = {'Show Amount', ['(',num2str(sum(app.tractset.subscore.vis.pos_shown(indx,:))),')']};
                app.(left_pos_slider).Text = {'Left', ['(',num2str(app.tractset.subscore.vis.pos_shown(indx,2)),')']};
                app.(right_pos_slider).Text = {'Right', ['(',num2str(app.tractset.subscore.vis.pos_shown(indx,1)),')']};
                checkbox_neg = ['ShowNegativeFibersCheckBox_' num2str(indx+1)];
                neg_slider = ['ShowAmountSlider_2Label_' num2str(indx+1)];
                left_slider = ['LeftSlider_2Label_' num2str(indx+1)];
                right_slider = ['RightSlider_2Label_' num2str(indx+1)];
                app.(checkbox_neg).Text = ['Show Negative Fibers (',num2str(sum(app.tractset.subscore.vis.neg_available(indx,:))),')'];
                app.(neg_slider).Text = {'Show Amount', ['(',num2str(sum(app.tractset.subscore.vis.neg_shown(indx,:))),')']};
                app.(left_slider).Text = {'Left', ['(',num2str(app.tractset.subscore.vis.neg_shown(indx,2)),')']};
                app.(right_slider).Text = {'Right', ['(',num2str(app.tractset.subscore.vis.neg_shown(indx,1)),')']};
            end

            %%%
            app.ModeltypeDropDown.Value = app.tractset.predictionmodel;

            app.LiveVisualizeCheckBox.Value = app.tractset.cvlivevisualize;

            app.BinsEditField.Value = app.tractset.numBins;
            app.ModelNormalizationDropDown.Value = app.tractset.modelNormalization;

            % Color buttons:
            app.ColorButtonPositive.BackgroundColor = app.tractset.poscolor;
            app.ColorButtonNegative.BackgroundColor = app.tractset.negcolor;


            % Base prediction on:
            try
                app.BasePredictiononDropDown.Value = app.tractset.basepredictionon;
            end
            if app.tractset.nestedLOO
                app.NestedCheckBox.Value = 1;
            end
            app.IterationsEditField.Value = app.tractset.kIter;
            app.enablerules;

            % refresh enablerules
            app.isbusy(0);
        end

        function results = isbusy(app,business,msg)
            if business == 1 % red
                app.BusyLamp.Color = [1.00,0.41,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Busy';
                end
            elseif business == 0 % green
                app.BusyLamp.Color = [0.41,1.00,0.16];
                app.BusyLampLabel.Text = '';
            elseif business == 2 % yellow
                app.BusyLamp.Color = [1.00,0.8,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Not Refreshed';
                end
            end
        end

        function results = draw(app,force)
            if ~exist('force','var')
                force=0;
            end
            app.RefreshViewButton.BackgroundColor = [0.96,0.96,0.96]; % standard grey
            dorefresh=1;

            if app.SplitColorByPCAButton.Value && strcmp(app.PCAsettingsLabel.Enable,'off') % case in which PCA would fail and hence drawing would run into errors.
                dorefresh=0;
            end


            if (app.AutoRefreshCheckBox.Value || force) && dorefresh
                app.isbusy(1);
                drawnow;
                app.tractset.draw;
                app.drawcb;
                app.isbusy(0);
                drawnow;
                app.tractset2UI;
            else
                app.isbusy(2);
                app.RefreshViewButton.BackgroundColor = [1,0.8,0.16]; % yellow
                drawnow;
            end
        end

        function Vms = getvms(app)
            switch app.tractset.efieldmetric
                case 'Mean'
                    Vms='V/mm';
                otherwise
                    Vms='V/m';
            end
        end

        function ts = setcovars(app) % transfer covariates from app to tractset
            if app.CovariatesCheckBox.Value % include covariates at all
                [is]=ismember(app.tractset.M.clinical.labels,app.CovariatesListBox.Value);
                if any(is)
                    app.tractset.covars = app.tractset.M.clinical.vars(is);
                    app.tractset.covarlabels = app.tractset.M.clinical.labels(is);
                else % none selected
                    app.tractset.covars = {};
                    app.tractset.covarlabels = {};
                end
                % Check whether Amplitude or VTA volumes should be
                % included, as well.
                if ismember('Stimulation Amplitude',app.CovariatesListBox.Value)
                    app.tractset.covars = [{app.tractset.getstimamp},app.tractset.covars];
                    app.tractset.covarlabels = [{'Stimulation Amplitude'},app.tractset.covarlabels];
                end

                if ismember('VTA Volume',app.CovariatesListBox.Value)
                    app.tractset.covars = [{app.tractset.getvtavolumes},app.tractset.covars];
                    app.tractset.covarlabels = [{'VTA Volume'},app.tractset.covarlabels];
                end

                if ismember('E-Field Magnitude Sum',app.CovariatesListBox.Value)
                    app.tractset.covars = [{app.tractset.getefieldmagnitudes},app.tractset.covars];
                    app.tractset.covarlabels = [{'E-Field Magnitude Sum'},app.tractset.covarlabels];
                end
                if ismember('Group / Cohort regressor',app.CovariatesListBox.Value)
                    app.tractset.covars = [{app.tractset.getcohortregressor},app.tractset.covars];
                    app.tractset.covarlabels = [{'Group / Cohort regressor'},app.tractset.covarlabels];
                end
            else
                app.tractset.covars = {};
                app.tractset.covarlabels = {};
            end
        end

        function setefieldsliders(app)
            value = app.tractset.efieldmetric;
            dimval=1000;
            switch lower(value)
                case 'sum'
                    app.EfieldthresholdSlider.Limits=[0.5,5]; % in V/m
                case 'mean'
                    app.EfieldthresholdSlider.Limits=[2.5,1000]; % in V/mm (!)
                    dimval=1;
                case 'peak'
                    app.EfieldthresholdSlider.Limits=[0.05,5]; % in V/m
                case 'peak 5%'
                    app.EfieldthresholdSlider.Limits=[0.05,5]; % in V/m
            end


            if app.tractset.efieldthreshold/dimval < app.EfieldthresholdSlider.Limits(1) % it is below min, set to min
                app.tractset.efieldthreshold = app.EfieldthresholdSlider.Limits(1)*dimval;
            end
            if app.tractset.efieldthreshold/dimval > app.EfieldthresholdSlider.Limits(2) % it is above max, set to max
                app.tractset.efieldthreshold = app.EfieldthresholdSlider.Limits(2)*dimval;
            end
        end

        function results = updatepca(app,whatchanged)
            if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                generalEnableVal='off';
            else
                generalEnableVal=ea_bool2onoff(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_method2methodid(app.tractset)));
            end


            if app.SplitColorByPCAButton.Value
                app.PCAsettingsLabel.Enable = generalEnableVal;
                app.VarianceExpSlider.Enable = generalEnableVal;
                app.VarianceExpSliderLabel.Enable = generalEnableVal;
                app.PCsEditField.Enable = generalEnableVal;
                app.PCsEditFieldLabel.Enable = generalEnableVal;
            else
                app.PCAsettingsLabel.Enable = 'off';
                app.VarianceExpSlider.Enable = 'off';
                app.VarianceExpSliderLabel.Enable = 'off';

                app.PCsEditField.Enable = 'off';
                app.PCsEditFieldLabel.Enable = 'off';
                return
            end

            % prep PCA:
            subvars=ea_nanzscore(cell2mat(app.tractset.subscore.vars'));
            try
                [coeff,score,latent,tsquared,explained]=pca(subvars,'rows','pairwise');
            catch % PCA failed, likely no / too few scores selected
                app.PCAsettingsLabel.Enable = 'off';
                app.VarianceExpSlider.Enable = 'off';
                app.VarianceExpSliderLabel.Enable = 'off';
                app.PCsEditField.Enable = 'off';
                app.PCsEditFieldLabel.Enable = 'off';
                return
            end
            if isnan(explained) || isempty(explained) %PCA function did not fail, but the values are empty
                app.PCAsettingsLabel.Enable = 'off';
                app.VarianceExpSlider.Enable = 'off';
                app.VarianceExpSliderLabel.Enable = 'off';
                app.PCsEditField.Enable = 'off';
                app.PCsEditFieldLabel.Enable = 'off';
                return
            end
            cumexplained=cumsum(explained);
            app.VarianceExpSlider.MajorTickLabelsMode='manual';
            app.VarianceExpSlider.MajorTicks=cumexplained;
            app.VarianceExpSlider.MinorTicksMode='manual';

            app.VarianceExpSlider.MajorTickLabels(ea_searchclosest(cumexplained,80)+1:end)={''}; % display var exp up until 80% on PCs to avoid crowding of tick labels.
            for pc=1:ea_searchclosest(cumexplained,80)
                app.VarianceExpSlider.MajorTickLabels{pc}=[sprintf('%.0f',cumexplained(pc)),' %'];
            end
            app.VarianceExpSlider.MinorTicks=[];
            if app.PCsEditField.Value > length(cumexplained)
                app.PCsEditField.Value = length(cumexplained);
            end
            if exist('whatchanged','var')
                switch whatchanged
                    case 'slider' % user has changed the slider
                        correctcomponent=ea_searchclosest(cumexplained,app.VarianceExpSlider.Value);
                        app.PCsEditField.Value=correctcomponent;
                    case 'edit' % user has edited the edit field
                        % nothing additional to do in this case.
                end
            end
            app.VarianceExpSlider.Value=cumexplained(app.PCsEditField.Value);
            app.tractset.numpcs=app.PCsEditField.Value; % also feed back to model
            app.VarianceExpSliderLabel.Text = [sprintf('%0.1f',cumexplained(app.tractset.numpcs)),'% Variance Explained'];

        end


        function results = tabupdate(app)
            n_group = unique(app.tractset.M.patient.group);
            if  app.SplitColorBySubscoreButton.Value
                app.SingleTractAnalysisTab.Title = 'Mixed Fiber Visualization';
                iter = length(app.SubscoresListBox.Value);
            elseif app.SplitColorByGroupButton.Value
                app.SingleTractAnalysisTab.Title = 'Group:Combined Visualization';
                iter = length(n_group);
            elseif app.SplitColorByPCAButton.Value
                app.SingleTractAnalysisTab.Title = 'PCA:Combined Visualization';
                iter = app.PCsEditField.Value;
            else
                app.SingleTractAnalysisTab.Parent = app.TabGroup;
                app.SingleTractAnalysisTab.Title = 'Single Tract Analysis';
                for i=1:10
                    tab_title = (['Subscore_' num2str(i) 'Tab']);
                    app.(tab_title).Parent = [];
                end
                app.ColorBar.Visible = 'on';

                return
            end

            app.ColorButtonNegative.Enable = 'off';
            app.ColorButtonPositive.Enable = 'off';
            for i=1:10
                tab_title = (['Subscore_' num2str(i) 'Tab']);
                negcolor_btn_title = (['ColorButtonNegative_' num2str(i+1)]);
                poscolor_btn_title = (['ColorButtonPositive_' num2str(i+1)]);
                showposfib_cb = ['ShowPositiveFibersCheckBox_' num2str(i+1)];
                shownegfib_cb = ['ShowNegativeFibersCheckBox_' num2str(i+1)];
                showposamt_slider = ['ShowAmountSliderPositive_' num2str(i+1)];
                showposamt_label = ['ShowAmountSliderLabel_' num2str(i+1)];
                shownegamt_slider = ['ShowAmountSliderNegative_' num2str(i+1)];
                shownegamt_label = ['ShowAmountSlider_2Label_' num2str(i+1)];

                showposamt_l_slider = ['LeftSliderPositive_' num2str(i+1)];
                showposamt_l_label = ['LeftSliderLabel_' num2str(i+1)];
                showposamt_r_slider = ['RightSliderPositive_' num2str(i+1)];
                showposamt_r_label = ['RightSliderLabel_' num2str(i+1)];

                shownegamt_l_slider = ['LeftSliderNegative_' num2str(i+1)];
                shownegamt_l_label = ['LeftSlider_2Label_' num2str(i+1)];
                shownegamt_r_slider = ['RightSliderNegative_' num2str(i+1)];
                shownegamt_r_label = ['RightSlider_2Label_' num2str(i+1)];

                if i <= iter
                    %find the tab that has the title
                    app.(tab_title).Parent = app.TabGroup;
                    if app.SplitColorBySubscoreButton.Value
                        
                        app.(negcolor_btn_title).BackgroundColor = app.tractset.subscore.colors{1,1}(i,:);
                        app.(poscolor_btn_title).BackgroundColor = app.tractset.subscore.colors{1,2}(i,:);
                    elseif app.SplitColorByPCAButton.Value
                        app.(negcolor_btn_title).BackgroundColor = app.tractset.subscore.pcacolors(i,:);
                        app.(poscolor_btn_title).BackgroundColor = app.tractset.subscore.pcacolors(i,:);
                    elseif app.SplitColorByGroupButton.Value
                        app.(negcolor_btn_title).BackgroundColor = app.tractset.groupcolors(i,:);
                        app.(poscolor_btn_title).BackgroundColor = app.tractset.groupcolors(i,:);
                    end
                    if app.ShowPositiveFibersCheckBox.Value
                        app.(showposfib_cb).Value = 1;
                        app.tractset.subscore.posvisible(i) = 1;
                        app.(showposfib_cb).Enable = 'on';
                        app.(poscolor_btn_title).Enable = 'on';
                    else
                        app.(showposfib_cb).Value = 0;
                        app.tractset.subscore.posvisible(i) = 0;
                    end
                    if app.ShowNegativeFibersCheckBox.Value
                        app.(shownegfib_cb).Value = 1;
                        app.tractset.subscore.negvisible(i) = 1;
                        app.(shownegfib_cb).Enable = 'on';
                        app.(negcolor_btn_title).Enable = 'on';
                    else
                        app.(shownegfib_cb).Value = 0;
                        app.tractset.subscore.negvisible(i) = 0;
                    end
                    if app.ShowAmountSliderPositive.Value
                        app.(showposamt_slider).Value = app.ShowAmountSliderPositive.Value;
                        app.(showposamt_slider).Enable = 'on';
                        app.(showposamt_label).Enable = 'on';
                    else
                        app.(showposamt_slider).Value = app.ShowAmountSliderPositive.Value;
                        app.(showposamt_slider).Enable = 'off';
                        app.(showposamt_label).Enable = 'off';
                    end
                    if app.ShowAmountSliderNegative.Value
                        app.(shownegamt_slider).Value = app.ShowAmountSliderNegative.Value;
                        app.(shownegamt_slider).Enable = 'on';
                        app.(shownegamt_label).Enable = 'on';
                    else
                        app.(shownegamt_slider).Value = app.ShowAmountSliderNegative.Value;
                        app.(shownegamt_slider).Enable = 'off';
                        app.(shownegamt_label).Enable = 'off';
                    end
                    if app.LeftSliderPositive.Value
                        app.(showposamt_l_slider).Value = app.LeftSliderPositive.Value;
                        app.tractset.subscore.vis.showposamount(i,2) = app.LeftSliderPositive.Value;  
                        app.(showposamt_l_slider).Enable = 'on';
                        app.(showposamt_l_label).Enable = 'on';
                    else
                        app.(showposamt_l_slider).Value = app.LeftSliderPositive.Value;
                        app.(showposamt_l_slider).Enable = 'off';
                        app.(showposamt_l_label).Enable = 'off';
                    end
                    if app.RightSliderPositive.Value
                        app.(showposamt_r_slider).Value = app.RightSliderPositive.Value;
                        app.tractset.subscore.vis.showposamount(i,1) = app.RightSliderPositive.Value;  
                        app.(showposamt_r_slider).Enable = 'on';
                        app.(showposamt_r_label).Enable = 'on';
                    else
                        app.(showposamt_r_slider).Value = app.RightSliderPositive.Value;
                        app.(showposamt_r_slider).Enable = 'off';
                        app.(showposamt_r_label).Enable = 'off';
                    end
                    if app.LeftSliderNegative.Value
                        app.(shownegamt_l_slider).Value = app.LeftSliderNegative.Value;
                        %update the new value into the model
                        app.tractset.subscore.vis.shownegamount(i,2) = app.LeftSliderNegative.Value;  
                        app.(shownegamt_l_slider).Enable = 'on';
                        app.(shownegamt_l_label).Enable = 'on';
                    else
                        app.(shownegamt_l_slider).Value = app.LeftSliderNegative.Value;
                        app.(shownegamt_l_slider).Enable = 'off';
                        app.(shownegamt_l_label).Enable = 'off';
                    end
                    if app.RightSliderNegative.Value
                        app.(shownegamt_r_slider).Value = app.RightSliderNegative.Value;
                        app.tractset.subscore.vis.shownegamount(i,1) = app.RightSliderNegative.Value;  
                        app.(shownegamt_r_slider).Enable = 'on';
                        app.(shownegamt_r_label).Enable = 'on';
                    else
                        app.(shownegamt_r_slider).Value = app.RightSliderNegative.Value;
                        app.(shownegamt_r_slider).Enable = 'off';
                        app.(shownegamt_r_label).Enable = 'off';
                    end

                else
                    app.(tab_title).Parent = [];
                end
            end
        end
        function results = tabfill(app)
            
            app.ShowPositiveFibersCheckBox.Value = app.tractset.posvisible;
            app.ShowNegativeFibersCheckBox.Value = app.tractset.negvisible;
            app.ShowAmountSliderPositive.Value = mean(app.tractset.showposamount);
            app.ShowAmountSliderNegative.Value = mean(app.tractset.shownegamount);
            app.LeftSliderPositive.Value = app.tractset.showposamount(2);
            app.RightSliderPositive.Value = app.tractset.showposamount(1);
            app.LeftSliderNegative.Value = app.tractset.shownegamount(2);
            app.RightSliderNegative.Value = app.tractset.shownegamount(1);
            
            num_tabs = length(app.tractset.subscore.labels);
            for indx=1:num_tabs
                checkbox_pos = ['ShowPositiveFibersCheckBox_' num2str(indx+1)];
                pos_slider = ['ShowAmountSliderLabel_' num2str(indx+1)];
                left_pos_slider = ['LeftSliderLabel_' num2str(indx+1)];
                right_pos_slider = ['RightSliderLabel_' num2str(indx+1)];
                app.(checkbox_pos).Text = ['Show Positive Fibers (',num2str(sum(app.tractset.subscore.vis.pos_available(indx,:))),')'];
                app.(pos_slider).Text = {'Show Amount', ['(',num2str(sum(app.tractset.subscore.vis.pos_shown(indx,:))),')']};
                app.(left_pos_slider).Text = {'Left', ['(',num2str(app.tractset.subscore.vis.pos_shown(indx,2)),')']};
                app.(right_pos_slider).Text = {'Right', ['(',num2str(app.tractset.subscore.vis.pos_shown(indx,1)),')']};
                checkbox_neg = ['ShowNegativeFibersCheckBox_' num2str(indx+1)];
                neg_slider = ['ShowAmountSlider_2Label_' num2str(indx+1)];
                left_slider = ['LeftSlider_2Label_' num2str(indx+1)];
                right_slider = ['RightSlider_2Label_' num2str(indx+1)];
                app.(checkbox_neg).Text = ['Show Negative Fibers (',num2str(sum(app.tractset.subscore.vis.neg_available(indx,:))),')'];
                app.(neg_slider).Text = {'Show Amount', ['(',num2str(sum(app.tractset.subscore.vis.neg_shown(indx,:))),')']};
                app.(left_slider).Text = {'Left', ['(',num2str(app.tractset.subscore.vis.neg_shown(indx,2)),')']};
                app.(right_slider).Text = {'Right', ['(',num2str(app.tractset.subscore.vis.neg_shown(indx,1)),')']};
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, lgpath, resultfig)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Opening application');
            d.Value = 0;
            app.isbusy(1,'Loading');
            app.UIFigure.Name='Fiber Filtering Explorer';
            
            app.IterationsEditField.Visible = 'off';
            app.IterationsEditFieldLabel.Visible = 'off';
            % Add modlist (structural connectomes)
            [mdl,sf]=ea_genmodlist;
            dmri_connectomenames = mdl(sf==1);
            dmri_mtconnectomenames = mdl(sf==3);
            app.ConnectomeDropDown.Items = [dmri_connectomenames,dmri_mtconnectomenames];
            %app.ConnectomeDropDown.Items={mdl(sf==1)};

            % Add standard colors for tracts
            app.ColorButtonPositive.BackgroundColor='r';
            app.ColorButtonNegative.BackgroundColor='b';
            d.Value = 0.33;
            % Add Value to connthreshsliderlabel
            changingValue=app.EfieldthresholdSlider.Value;
            if ~exist('resultfig','var')
                resultfig = ea_mnifigure;
            end

            % use supplied information to setup analysis
            if ~exist('lgpath','var')
                [FileName,PathName]=uigetfile('*.mat','Please Choose Lead-Group Analysis project...');
                lgpath = fullfile(PathName,FileName);
            end
            letsdraw=0; % flag to state whether to draw directly.
            lgpath = GetFullPath(lgpath);
            varinfo=who('-file',lgpath); % check if file is a group analysis matfile or a stored disctract object with var name 'tractset'

            if ismember('tractset',varinfo)
                D = load(lgpath,'-mat');
                app.tractset = D.tractset; % load stored disctract object
                if isprop(app.tractset,'nestedLOO')
                    app.NestedCheckBox.Value = app.tractset.nestedLOO;
                end
                app.NestedCheckBox.Visible = 'off';
                app.tractset.activateby={};

                % Fix tractset ID in case it has been renamed
                [~, ID] = fileparts(lgpath);
                if ~strcmp(app.tractset.ID, ID)
                    app.tractset.ID = ID;
                end

                % Fix group folder in case it has been moved
                tractGroupFolder = regexprep(app.tractset.leadgroup, ['(?<=\',filesep,')[\w-]+\.mat$'], '');
                UIGroupFolder = strrep(lgpath,['fiberfiltering', filesep, app.tractset.ID, '.fibfilt'],'');
                if ~strcmp(tractGroupFolder, UIGroupFolder)
                    app.tractset.analysispath = strrep(app.tractset.analysispath, tractGroupFolder, UIGroupFolder);
                    app.tractset.leadgroup = strrep(app.tractset.leadgroup, tractGroupFolder, UIGroupFolder);
                    app.tractset.M.root = strrep(app.tractset.M.root, tractGroupFolder, UIGroupFolder);
                    if isfield(app.tractset.M,'ui')
                        app.tractset.M.ui.groupdir = strrep(app.tractset.M.ui.groupdir, tractGroupFolder, UIGroupFolder);
                    end
                end
                d.Value = 0.50;
                app.tractset.resultfig = resultfig; % attach to figure
                if ~isempty(app.tractset.results)
                    if isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                        letsdraw=1;
                    end
                end
                %initialize for legacy support
                if ~isstruct(app.tractset.subscore)
                    app.tractset.subscore.vars = {};
                    app.tractset.subscore.labels = {};
                    app.tractset.subscore.pcavars = {};
                    app.tractset.subscore.weights = [];
                    app.tractset.subscore.colors{1,1} = ea_color_wes('all');
                    app.tractset.subscore.colors{1,2} = flip(ea_color_wes('all'));
                    app.tractset.subscore.vis.showposamount = repmat([25,25],10,1); %total of 10 subscores - will delete when we know the total number of subscores
                    app.tractset.subscore.vis.shownegamount = repmat([25,25],10,1);
                    app.tractset.subscore.vis.pos_shown = repmat([25,25],10,1);
                    app.tractset.subscore.vis.neg_shown = repmat([25,25],10,1);
                    app.tractset.subscore.negvisible = zeros(10,1);
                    app.tractset.subscore.posvisible = ones(10,1);
                    app.tractset.subscore.spitbysubscore = 0;
                    app.tractset.subscore.special_case = 0;
                end
                if ~isprop(app.tractset,'multi_pathways')
                    app.tractset.multi_pathways = 0;
                end
                if ~isprop(app.tractset,'switch_connectivity')  
                  app.tractset.switch_connectivity = 0;
                end
                %  if old fibfilt is used, assume it was computed with VATs
                if ~isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)), 'VAT_Ttest') && ~isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),'PAM_Ttest')
                    app.tractset.results.(ea_conn2connid(app.tractset.connectome)).('VAT_Ttest') = app.tractset.results.(ea_conn2connid(app.tractset.connectome)).('ttests');
                end

                %if app.tractset.results.(ea_conn2connid(app.tractset.connectome)).connFiberInd
                %    app.tractset.results.(ea_conn2connid(app.tractset.connectome)).connFiberInd_VAT = app.tractset.results.(ea_conn2connid(app.tractset.connectome)).connFiberInd;
                %end
                
                
            else % init from M struct of LEAD group file
                app.tractset = ea_disctract;
                
                app.NestedCheckBox.Visible = 'off';
                app.tractset.initialize(lgpath,resultfig); % initialize which could lead to drawing in case of loaded obj.
            end
            if isfield(app.tractset.M,'ui')
                if ismember(app.tractset.M.ui.connectomename, app.ConnectomeDropDown.Items)
                    app.ConnectomeDropDown.Value = app.tractset.M.ui.connectomename;
                end
            end
            d.Value = 0.75;
            app.setefieldsliders;
            if letsdraw
                app.draw; % show its results
            else
                tractset2UI(app);
            end
            enablerules(app);
            if strcmp(app.tractset.multitractmode,'Split & Color By Subscore') && ~isempty(app.tractset.subscore.labels)
                app.tabfill;
            end
            app.isbusy(0);
            d.Value = 1.00;
            close(d);
            hint={'Welcome to the Lead-DBS Fiber Filtering Explorer!'
                'We are excited about your interest in this tool.'
                ''
                'While the explorer can be a powerful tool, the intended use-case is to'
                'create discriminative tract models on *part* (e.g. 70%) of your data only.'
                'You can do so by selecting only a subset of patients in the lower right.'
                'Then, the aim is to cross-validate'
                'results using boot-strapping methods (see lower panel).'
                ''
                'Once you are convinced that the model you created is robust and'
                'yields meaningful predictions within the training & cross-validation set,'
                '(e.g. using leave-one-out or k-fold CV routines in the lower left)'
                'it is intended to finally test the model on the remaining (e.g. 30%)'
                'test set of your data (e.g. using the Custom Strategies in the lower left).'
                ''
                'Please also consider choices of normative connectomes by reading up'
                'the original work and make sure to appropriately cite authors'
                'that acquired or constructed these datasets.'
                ''
                'If unsure, please consult the manual or use our Slack help-channel.'
                'Furthermore, we would like to encourage everyone to explore/adapt'
                'their own code & analyses tailored to the studies specific needs.'
                ''
                'In case of code changes that could be useful for others, as well,'
                'consider contributing them via a pull-request on github.'};
            uigetpref('LeadDBS','FiberFilteringShowUsageHint','Fiber Filtering Explorer',hint,'Makes Sense');

        end

        % Callback function
        function SplitColorbyGroupVariableCheckBoxValueChanged(app, event)


        end

        % Value changed function: ModelSetupDropDown
        function ModelSetupDropDownValueChanged(app, event)
            switch app.ModelSetupDropDown.Value
                case 'T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)'
                    app.ConnectivityModelButtonGroup.Children(1).Enable = 'on';
                    if strcmp(app.ConnectivityModelButtonGroup.SelectedObject.Text, 'Stimulation Volume')
                        app.tractset.connectivity_type = 1;
                        app.tractset.statmetric=1;
                        if app.tractset.connthreshold > 40
                            app.tractset.connthreshold = 20; % reset to a suitable value
                        end
                    else
                       app.tractset.connectivity_type = 2;
                       app.tractset.statmetric=3;
                    end
                case 'Correlations / E-fields (Irmen 2020)'
                    app.ConnectivityModelButtonGroup.SelectedObject = app.ConnectivityModelButtonGroup.Children(2);
                   % app.ConnectivityModelButtonGroup.Children(1).Enable = 'off';
                    app.tractset.statmetric=2;
                    app.tractset.connectivity_type = 1;
                    % also make sure efieldmetric sliders are properly set:
                    app.setefieldsliders;
                    %end
                %case 'T-Tests / PAM (OSS-DBS)'
                %    app.tractset.statmetric=3;
                    
                case 'Dice Coeffs / VTAs (binary vars)'
                    app.tractset.statmetric=4;
                    if app.tractset.connthreshold > 40
                        app.tractset.connthreshold = 20; % reset to a suitable value
                    end
                case 'Reverse T-Tests / E-Fields (binary vars)'
                    app.tractset.statmetric=5;
                    % also make sure efieldmetric sliders are properly set:
                    app.setefieldsliders;
                case 'Plain Connections'
                    app.tractset.statmetric=6;
                    if app.tractset.connthreshold < 1
                        app.tractset.connthreshold = 1; % reset to a suitable value
                    end
            end

            % check if the model changed from PAM to VAT or vice versa:
            % error thrown here if we remove PAM (OSS-DBS) as an option
            % from the menu
            
            %if (strcmp(app.ModelSetupDropDown.Value, 'T-Tests / PAM (OSS-DBS)') && strcmp('T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)', event.PreviousValue)) || (strcmp(app.ModelSetupDropDown.Value, 'T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)') && strcmp('T-Tests / PAM (OSS-DBS)', event.PreviousValue))
            %    app.tractset.switch_connectivity = 1;
            %else
            %    app.tractset.switch_connectivity = 0;
            %end

            tractset2UI(app);
            enablerules(app);
            if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                val=0;
            else
                val=(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_method2methodid(app.tractset)));
            end
            if val % already calculated
                app.draw;
            end
        end

        % Value changing function: EfieldthresholdSlider
        function EfieldthresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EfieldthresholdLabel.Text={['Tracts connected if ',lower(app.tractset.efieldmetric),' E-Field Magnitude'] ['they traverse is > ',sprintf('%.2f',changingValue),' ',app.getvms]};
        end

        % Value changed function: VariableofInterestDropDown
        function VariableofInterestDropDownValueChanged(app, event)
            app.CovariatesListBox.Items=['Stimulation Amplitude','VTA Volume',app.tractset.M.clinical.labels];
            [~,ix]=ismember(app.VariableofInterestDropDown.Value,app.CovariatesListBox.Items);
            app.CovariatesListBox.Items(ix)=[];

            [~, ix]=ismember(app.VariableofInterestDropDown.Value,app.tractset.M.clinical.labels);
            app.tractset.responsevar = app.tractset.M.clinical.vars{ix};
            app.tractset.responsevarlabel = app.tractset.M.clinical.labels{ix};
            app.draw;

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %app.tractset.app=app; % need to change
            app.tractset.leadgroup=app.LeadGroupPathLabel.Text;

            %% set up object properties:
            app.tractset.posvisible = app.ShowPositiveFibersCheckBox.Value;
            app.tractset.negvisible = app.ShowNegativeFibersCheckBox.Value;
            app.tractset.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
            app.tractset.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            app.tractset.poscolor = app.ColorButtonPositive.BackgroundColor;
            app.tractset.negcolor = app.ColorButtonNegative.BackgroundColor;
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.tractset.patientselection = ix;

            app.tractset.calcthreshold = app.ThresholdEditField.Value;

            [~, ix]=ismember(app.VariableofInterestDropDown.Value,app.tractset.M.clinical.labels);
            app.tractset.responsevar = app.tractset.M.clinical.vars{ix};
            app.tractset.responsevarlabel = app.tractset.M.clinical.labels{ix};

            setcovars(app);

            switch app.ModelSetupDropDown.Value
                case 'T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)'
                    if strcmp(app.ConnectivityModelButtonGroup.SelectedObject,'Stimulation Volume')
                        app.tractset.statmetric=1;
                    elseif strcmp(app.ConnectivityModelButtonGroup.SelectedObject,'PAM')
                        app.tractset.statmetric=3;
                    end
                case 'Correlations / E-fields (Irmen 2020)'
                    app.tractset.statmetric=2;
                case 'Dice Coeffs / VTAs (binary vars)'
                    app.tractset.statmetric=4;
                case 'Reverse T-Tests / E-Fields (binary vars)'
                    app.tractset.statmetric=5;
                case 'Plain Connections'
                    app.tractset.statmetric=6;
            end
            app.tractset.connectome = app.ConnectomeDropDown.Value;
            app.setefieldsliders;

            if strcmp(app.Efieldmetric.Value,'Mean')
                app.tractset.efieldthreshold = app.EfieldthresholdSlider.Value;
            else
                app.tractset.efieldthreshold = app.EfieldthresholdSlider.Value*1000;
            end
            app.tractset.connthreshold = app.ConnthresholdSlider.Value;
            app.tractset.mirrorsides = app.MirrorSidesCheckBox.Value;

            % calculate general setup
            app.tractset.calculate;

            % save dataset:
            app.tractset.save;

            enablerules(app);

            % draw results
            app.draw;
        end

        % Callback function
        function IncludeallpatientsCheckBoxValueChanged(app, event)
            value = app.IncludeallpatientsCheckBox.Value;
            switch value
                case 1
                    app.PatientsListBox.Value=app.PatientsListBox.Items;
                    app.tractset.patientselection = 1:length(app.PatientsListBox.Items);
                case 0
            end
            enablerules(app);
        end

        % Value changed function: ShowPositiveFibersCheckBox
        function ShowPositiveFibersCheckBoxValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox.Value;
            app.tractset.posvisible = value;
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.special_case = 1;
                %component_changed = 'ShowPositiveFibersCheckBox';
                tabupdate(app);
            end
            enablerules(app);
            app.draw;
            app.tractset.subscore.special_case = 0;
        end

        % Value changed function: ShowNegativeFibersCheckBox
        function ShowNegativeFibersCheckBoxValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox.Value;
            app.tractset.negvisible = value;
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.special_case = 1;
                tabupdate(app);
                %component_changed = 'ShowNegativeFibersCheckBox';
                %tabupdate(app,component_changed);
            end
            enablerules(app);
            app.draw;
            app.tractset.subscore.special_case = 0;
        end

        % Value changing function: ShowAmountSliderPositive
        function ShowAmountSliderPositiveValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderPositive.Value = changingValue;
            app.RightSliderPositive.Value = changingValue;
            app.tractset.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
        end

        % Value changing function: ShowAmountSliderNegative
        function ShowAmountSliderNegativeValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderNegative.Value = changingValue;
            app.RightSliderNegative.Value = changingValue;
            app.tractset.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];

        end

        % Value changing function: AnalysisIDEditField
        function AnalysisIDEditFieldValueChanging(app, event)
            changingValue = event.Value;
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,' ','_');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'(','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,')','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'-','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'.','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'"','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'~','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'#','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'^','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'|','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'$','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'%','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'&','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'*','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'!','');
        end

        % Value changed function: AnalysisIDEditField
        function AnalysisIDEditFieldValueChanged(app, event)
            value = app.AnalysisIDEditField.Value;
            if exist([fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,value,'.fibfilt'],'file')
                app.AnalysisIDEditField.Value = app.tractset.ID;
                msgbox('A tract with this name already exists. Please choose a different name.')
                return
            end

            if exist([fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,app.tractset.ID,'.fibfilt'],'file')
                movefile([fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,app.tractset.ID,'.fibfilt'],...
                    [fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,value,'.fibfilt']);
            end

            ht = getappdata(app.tractset.resultfig, 'ht');
            if ~isempty(ht)
                Tags = arrayfun(@(tool) tool.Tag, ht.Children, 'Uni', 0);
                pushToolInd = find(contains(Tags,['Explore discriminative fibertract ', app.tractset.ID]),1);
                set(ht.Children(pushToolInd), 'Tag', ['Explore discriminative fibertract ', value],...
                    'TooltipString', ['Explore discriminative fibertract ', value], ...
                    'ClickedCallback', {@ea_add_discfiber, [fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,value,'.fibfilt'], app.tractset.resultfig});
            end

            app.tractset.ID=value;
            app.tractset.save;

        end

        % Value changed function: PatientsListBox
        function PatientsListBoxValueChanged(app, event)
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.tractset.patientselection = ix;
            app.draw;
            app.tractset2UI;
        end

        % Value changed function: CovariatesListBox
        function CovariatesListBoxValueChanged(app, event)
            setcovars(app);
            app.draw;
        end

        % Value changed function: LeftSliderPositive
        function LeftSliderPositiveValueChanged(app, event)
            app.tractset.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
            app.LeftSliderPositive.Tooltip = sprintf('%d%%', round(app.LeftSliderPositive.Value));
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.special_case = 1;
                app.tabupdate;
            end
            app.draw;
            app.tractset.subscore.special_case = 0;
        end

        % Value changed function: RightSliderPositive
        function RightSliderPositiveValueChanged(app, event)
            app.tractset.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
            app.RightSliderPositive.Tooltip = sprintf('%d%%', round(app.RightSliderPositive.Value));
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.special_case = 1;
                app.tabupdate;
            end
            app.draw;
            app.tractset.subscore.special_case = 0;
        end

        % Value changed function: LeftSliderNegative
        function LeftSliderNegativeValueChanged(app, event)
            app.tractset.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            app.LeftSliderNegative.Tooltip = sprintf('%d%%', round(app.LeftSliderNegative.Value));
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.special_case = 1;
                app.tabupdate;
            end
            app.draw;
            app.tractset.subscore.special_case = 0;
        end

        % Value changed function: RightSliderNegative
        function RightSliderNegativeValueChanged(app, event)
            app.tractset.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            app.RightSliderNegative.Tooltip = sprintf('%d%%', round(app.RightSliderNegative.Value));
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.special_case = 1;
                app.tabupdate;
            end
            app.draw;
            app.tractset.subscore.special_case = 0;
        end

        % Button pushed function: ColorButtonPositive
        function ColorButtonPositivePushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive.BackgroundColor=c;
            app.tractset.poscolor = app.ColorButtonPositive.BackgroundColor;
            app.draw;
        end

        % Button pushed function: ColorButtonNegative
        function ColorButtonNegativePushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative.BackgroundColor=c;
            app.tractset.negcolor = app.ColorButtonNegative.BackgroundColor;
            app.draw;
        end

        % Value changed function: EfieldthresholdSlider
        function EfieldthresholdSliderValueChanged(app, event)
            if strcmp(app.Efieldmetric.Value,'Mean')
                app.tractset.efieldthreshold = app.EfieldthresholdSlider.Value;
            else
                app.tractset.efieldthreshold = app.EfieldthresholdSlider.Value*1000;
            end
            app.draw;
        end

        % Value changed function: ConnectomeDropDown
        function ConnectomeDropDownValueChanged(app, event)
            value = app.ConnectomeDropDown.Value;
            %if the selected connectomes have multiple .mat files inside
            %it, then it is a multi tract file and
            %app.tractset.multi_pathways = 1
            if exist(fullfile(ea_getconnectomebase('dMRI_multitract'),app.ConnectomeDropDown.Value),'dir') %this is inside the dmri_multitract folder
                if exist(fullfile(ea_getconnectomebase('dmri'),app.ConnectomeDropDown.Value),'dir') %same connectome exists in both
                   warndlg("The same connectome exists in Multitract format as well as in Singletract format. Please set unique names before continuing. Further results will only reflect Singletract mode.");
                   app.tractset.multi_pathways = 0;
                else 
                    %if it only exists in the multitract pathway, then
                    %check once more for the multiple .mat files and 
                    %set app.tractset.multi_pathways = 1;
                    mt_connectome = fullfile(ea_getconnectomebase('dMRI_multitract'),app.ConnectomeDropDown.Value);
                    mt_connectome_dir = dir(fullfile(mt_connectome,'*.mat'));
                    mt_files = {mt_connectome_dir.name};
                    if length(mt_files) > 1
                        app.tractset.multi_pathways = 1;
                    else
                        app.tractset.multi_pathways = 0;
                    end
                end
            else
                app.tractset.multi_pathways = 0;
            end
            app.tractset.connectome = value;
            enablerules(app);
            if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                val=0;
            else
                val=(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_method2methodid(app.tractset)));
            end
            if val % already calculated
                app.draw;
            end
        end

        % Value changed function: MirrorSidesCheckBox
        function MirrorSidesCheckBoxValueChanged(app, event)
            value = app.MirrorSidesCheckBox.Value;
            app.tractset.mirrorsides = app.MirrorSidesCheckBox.Value;
            app.draw;
        end

        % Value changed function: ShowAmountSliderPositive
        function ShowAmountSliderPositiveValueChanged(app, event)
            value = app.ShowAmountSliderPositive.Value;
            app.LeftSliderPositive.Value = value;
            app.RightSliderPositive.Value = value;
            app.ShowAmountSliderPositive.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderPositive.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderPositive.Tooltip = sprintf('%d%%', round(value));
            app.tractset.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.special_case = 1;
                app.tabupdate;
            end
            app.draw;
            app.tractset.subscore.special_case = 0;
        end

        % Value changed function: ShowAmountSliderNegative
        function ShowAmountSliderNegativeValueChanged(app, event)
            value = app.ShowAmountSliderNegative.Value;
            app.LeftSliderNegative.Value = value;
            app.RightSliderNegative.Value = value;
            app.ShowAmountSliderNegative.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderNegative.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderNegative.Tooltip = sprintf('%d%%', round(value));
            app.tractset.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.special_case = 1;
                app.tabupdate;
            end
            app.draw;
            app.tractset.subscore.special_case = 0;
        end

        % Value changing function: ConnthresholdSlider
        function ConnthresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            switch app.tractset.statmetric
                case {1,3,4,6}
                    bubbles='VTAs';
                    app.ConnthresholdLabel.Text = {['Tracts connected to > ',sprintf('%.1f',changingValue),' %'],['and < ',sprintf('%.1f',100-changingValue),' % of ',bubbles]};
                case {2,5}
                    bubbles='E-Fields';
                    app.ConnthresholdLabel.Text = ['Tracts connected to > ',sprintf('%.1f',changingValue),' % of ',bubbles];
                %case 6
                %    bubbles='VTAs';
                %    app.ConnthresholdLabel.Text = ['Tracts connected to > ',sprintf('%.1f',changingValue),' % of ',bubbles];
            end
        end

        % Value changed function: ConnthresholdSlider
        function ConnthresholdSliderValueChanged(app, event)
            value = app.ConnthresholdSlider.Value;
            app.tractset.connthreshold = value;
            app.draw;
        end

        % Button pushed function: RunCVButton
        function RunCVButtonPushed(app, event)
            switch app.StrategyDropDown.Value
                case 'Leave-Nothing-Out (Permutation-Based)'
                    app.tractset.nestedLOO = false;
                    app.tractset.customselection = [];
                    [I,Ihat,R0,R1,pperm]=app.tractset.lnopb;
                    I = I(:,1);
                    Ihat = Ihat{1};
                    cvs='lnopb';
                    sel=app.tractset.patientselection;
                    h1=ea_plothistperm([app.tractset.responsevarlabel,' [Permutation-Based Test]'],[R1;R0],{'Unpermuted prediction'},{1},1);
                    saveas(h1,[fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,app.tractset.ID,'_',app.tractset.responsevarlabel,'_',cvs,'_permtest.png']);
                case 'Leave-One-Patient-Out'
                    app.tractset.customselection = [];
                    [I,Ihat]=app.tractset.loocv;
                    cvs='loocv';
                    sel=app.tractset.patientselection;
                case 'Leave-One-Cohort-Out'
                    app.tractset.nestedLOO = false;
                    app.tractset.customselection = [];
                    [I,Ihat]=app.tractset.lococv;
                    cvs='lococv';
                    sel=app.tractset.patientselection;
                case 'k-fold (randomized)'
                    app.tractset.customselection = [];
                    app.tractset.kIter = app.IterationsEditField.Value;
                    [I,Ihat]=app.tractset.kfoldcv;
                    cvs='kfoldcv';
                    sel=app.tractset.patientselection;
                case 'Custom (Patients)'
                    app.tractset.nestedLOO = false;
                    cvp.NumTestSets = 1;
                    % training and test indices from the items list
                    training = find(ismember(app.TrainOnListBox.Items,app.TrainOnListBox.Value));
                    test = find(ismember(app.PredictListBox.Items,app.PredictListBox.Value));
                    % Patient selected based on the training and test indices
                    app.tractset.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.tractset.customselection, training);
                    cvp.test{1} = ismember(app.tractset.customselection, test);
                    [I, Ihat]=app.tractset.crossval(cvp);
                    cvs = 'custom_pts';
                    sel = test;
                case 'Custom (Cohorts)'
                    app.tractset.nestedLOO = false;
                    cvp.NumTestSets = 1;
                    % training and test indices from the items list
                    training = find(ismember(app.tractset.M.patient.group, str2double(app.TrainOnListBox.Value)))';
                    test = find(ismember(app.tractset.M.patient.group,str2double(app.PredictListBox.Value)))';
                    % Patient selected based on the training and test indices
                    app.tractset.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.tractset.customselection, training);
                    cvp.test{1} = ismember(app.tractset.customselection, test);
                    [I, Ihat]=app.tractset.crossval(cvp);
                    cvs = 'custom_cohs';
                    sel = test;
                case 'Custom (Subcohorts)'
                    app.tractset.nestedLOO = false;
                    cvp.NumTestSets = 1;
                    % training and test indices from the items list
                    tsets = find(ismember(app.tractset.setlabels, app.TrainOnListBox.Value));
                    training=[];
                    for ts=1:length(tsets)
                        training = [training,find(app.tractset.setselections{tsets(ts)})];
                    end
                    training=unique(training);
                    tsets = find(ismember(app.tractset.setlabels, app.PredictListBox.Value));
                    test=[];
                    for ts=1:length(tsets)
                        test = [test,find(app.tractset.setselections{tsets(ts)})];
                    end
                    test=unique(test);
                    % Patient selected based on the training and test indices
                    app.tractset.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.tractset.customselection, training);
                    cvp.test{1} = ismember(app.tractset.customselection, test);
                    [I, Ihat]=app.tractset.crossval(cvp);
                    cvs = 'custom_subcohs';
                    sel = test;
                case 'Custom (Sets)'
                    app.tractset.nestedLOO = false;
                    cvp.NumTestSets = 1;
                    % Generate cvpartition
                    rng(app.tractset.rngseed);
                    cvpKfold = cvpartition(length(app.tractset.M.patient.list), 'KFold', str2double(app.kEditField.Value));
                    % Calculate training and test indices based on the sets selected and the cvpartitions generated
                    trainSetInd = str2double(app.TrainOnListBox.Value');
                    testSetInd = str2double(app.PredictListBox.Value');
                    training = zeros(size(app.tractset.M.patient.list));
                    test = zeros(size(app.tractset.M.patient.list));
                    for i=1:length(trainSetInd)
                        training = training | cvpKfold.test(trainSetInd(i));
                    end
                    for i=1:length(testSetInd)
                        test = test | cvpKfold.test(testSetInd(i));
                    end
                    training = find(training);
                    test = find(test);
                    % Patient selected based on the training and test indices
                    app.tractset.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.tractset.customselection, training);
                    cvp.test{1} = ismember(app.tractset.customselection, test);
                    [I, Ihat]=app.tractset.crossval(cvp);

                    cvs = 'custom_sets';
                    sel = test;
            end
            % check if groups exist:
            if length(unique(app.tractset.M.patient.group))>1 && app.PosthoccorrectforgroupCheckBox.Value
                Ihat=ea_resid(ea_cohortregressor(app.tractset.M.patient.group(sel)),Ihat);
                suffix=' [Corrected for Cohort]';
            else
                suffix='';
            end

            groupID = app.tractset.M.patient.group(sel);
            groupColors = app.tractset.M.groups.color(unique(groupID),:);
            groupColors = rgb2hsv(groupColors);
            groupColors(:,2) = groupColors(:,2)+((1-groupColors(:,2))/2);
            groupColors=hsv2rgb(groupColors);
            empiricallabel=[strrep(app.tractset.responsevarlabel, '_', ' '), ' (Empirical)'];
            if app.tractset.doactualprediction
                fibscorelabel = [strrep(app.tractset.responsevarlabel, '_', ' '), ' (Predicted)'];
            else
                fibscorelabel=[];
                switch app.tractset.statmetric
                    case {2,3,5}
                        fibscorelabel=[fibscorelabel,'Weighted '];
                end
                switch lower(app.tractset.basepredictionon)
                    case 'mean of scores'
                        fibscorelabel=[fibscorelabel,'Mean of'];
                    case 'sum of scores'
                        fibscorelabel=[fibscorelabel,'Sum of'];
                    case 'peak of scores'
                        fibscorelabel=[fibscorelabel,'Peak of'];
                    case 'peak 5% of scores'
                        fibscorelabel=[fibscorelabel,'Peak 5% of'];
                    case 'profile of scores: spearman'
                        fibscorelabel=[fibscorelabel,'Spatial Spearman Correlation of'];
                    case 'profile of scores: pearson'
                        fibscorelabel=[fibscorelabel,'Spatial Pearson Correlation of'];
                    case 'profile of scores: bend'
                        fibscorelabel=[fibscorelabel,'Spatial Bend Correlation of'];
                end
                switch app.tractset.statmetric
                    case {1,3,5}
                        fibscorelabel=[fibscorelabel,' Fiber-T-Scores'];
                    case 2
                        fibscorelabel=[fibscorelabel,' Fiber-R-Scores'];
                    case 4
                        fibscorelabel=[fibscorelabel,' Fiber-DICE-Scores'];
                    case 6
                        fibscorelabel=[fibscorelabel,' Plain Connections'];
                end
                fibscorelabel=[fibscorelabel,suffix];
            end
            if iscell(I)

                for sc=1:length(I)
                    fibscorelabel = [strrep(app.tractset.subscore.labels{sc}, '_', ' '), ' (Predicted)'];
                    empiricallabel=[strrep(app.tractset.subscore.labels{sc}, '_', ' '), ' (Empirical)'];
                    h=ea_corrbox(I{sc},Ihat{sc},{['Disc. Fiber prediction ',upper(cvs)],empiricallabel,fibscorelabel},'permutation_spearman',groupID,[],[],groupColors);
                    saveas(h,[fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,app.tractset.ID,'_',app.tractset.subscore.labels{sc},'_',cvs,'_',num2str(app.tractset.numpcs),'_factor_pca.png']);
                end

            else
                if exist('pperm', 'var')
                    h=ea_corrbox(I,(Ihat),{['Disc. Fiber prediction ',upper(cvs)],empiricallabel,fibscorelabel},'permutation_spearman',groupID,[],pperm,groupColors);
                else
                    h=ea_corrbox(I,(Ihat),{['Disc. Fiber prediction ',upper(cvs)],empiricallabel,fibscorelabel},'permutation_spearman',groupID,[],[],groupColors);
                end
%                saveas(h,[fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,app.tractset.ID,'_',app.tractset.responsevarlabel,'_',cvs,'.png']);
            end
        end

        % Value changed function: StrategyDropDown
        function StrategyDropDownValueChanged(app, event)
            if strcmp(app.StrategyDropDown.Value,'Leave-One-Patient-Out') || strcmp(app.StrategyDropDown.Value,'k-fold (randomized)')
                app.NestedCheckBox.Visible = 'on';
            else
                app.NestedCheckBox.Visible = 'off';
            end
            if strcmp(app.StrategyDropDown.Value,'k-fold (randomized)')
                app.IterationsEditFieldLabel.Visible = 'on';
                app.IterationsEditField.Visible = 'on';
                app.IterationsEditFieldLabel.Enable = 'on';
                app.IterationsEditField.Enable = 'on';
                app.IterationsEditField.Value = 1;
            else
                app.IterationsEditFieldLabel.Visible = 'off';
                app.IterationsEditField.Visible = 'off';
                app.IterationsEditFieldLabel.Enable = 'off';
                app.IterationsEditField.Enable = 'off';
            end
            app.enablerules;
            app.tractset2UI;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            answer = questdlg("Save Changes?",'Close Discriminative Fiber Toolbox','Yes','No','Yes');
            switch answer
                case 'Yes'
                    app.isbusy(1,'saving');
                    if ~isempty(app.tractset)
                        app.tractset.save;
                        for tract=1:numel(app.tractset.drawobject)
                            delete(app.tractset.drawobject{tract});
                        end
                    end
                    app.isbusy(0);
                    delete(app)
                case 'No'
                    if ~isempty(app.tractset)
                        if iscell(app.tractset.drawobject)
                            for tract=1:numel(app.tractset.drawobject)
                                delete(app.tractset.drawobject{tract});
                            end
                        end
                    end
                    app.isbusy(0);
                    delete(app)
            end

        end

        % Value changed function: Efieldmetric
        function EfieldmetricValueChanged(app, event)
            value = app.Efieldmetric.Value;
            app.setefieldsliders;
            app.tractset.efieldmetric=value;

            app.enablerules;
            app.tractset2UI;

            if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                val=0;
            else
                val=(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_method2methodid(app.tractset)));
            end

            if val % already calculated
                app.draw;
            end
        end

        % Value changed function: kEditField
        function kEditFieldValueChanged(app, event)
            switch app.StrategyDropDown.Value
                case 'Leave-Nothing-Out (Permutation-Based)'
                    app.tractset.Nperm = str2double(app.kEditField.Value);
                case 'k-fold (randomized)'
                    app.tractset.kfold = str2double(app.kEditField.Value);
                case 'Custom (Sets)'
                    app.tractset.Nsets = str2double(app.kEditField.Value);
                    if str2double(app.kEditField.Value)>1
                        app.TrainOnListBox.Items=sprintfc('%d',[1:app.tractset.Nsets]');
                        app.PredictListBox.Items=sprintfc('%d',[1:app.tractset.Nsets]');
                    end
            end
        end

        % Value changed function: CovariatesCheckBox
        function CovariatesCheckBoxValueChanged(app, event)
            value = app.CovariatesCheckBox.Value;
            app.enablerules;
            if ~value
                app.tractset.covars = {};
                app.tractset.covarlabels = {};
                app.draw;
            else
                setcovars(app);
                app.draw;
            end

        end

        % Value changed function: PosthoccorrectforgroupCheckBox
        function PosthoccorrectforgroupCheckBoxValueChanged(app, event)
            value = app.PosthoccorrectforgroupCheckBox.Value;
            app.tractset.adjustforgroups=value;
        end

        % Menu selected function: 
        % RefreshInformationfromLeadGroupprojectMenu
        function RefreshInformationfromLeadGroupprojectMenuSelected(app, event)
            app.tractset.refreshlg;
            app.tractset2UI;
        end

        % Button pushed function: RefreshViewButton
        function RefreshViewButtonPushed(app, event)
            app.draw(1); % draw with force = 1.
        end

        % Value changed function: ConsiderSignificantFibersonlyCheckBox
        function ConsiderSignificantFibersonlyCheckBoxValueChanged(app, event)
            value = app.ConsiderSignificantFibersonlyCheckBox.Value;
            app.tractset.showsignificantonly=value;
            if app.SplitColorBySubscoreButton.Value
                app.tabupdate;
            end
            app.draw;
        end

        % Button pushed function: ExportasAtlasButton
        function ExportasAtlasButtonPushed(app, event)
            disctract = app.tractset.fiberdrawn;
            disctract.info.Connectome = app.tractset.connectome;
            if app.ShowPositiveFibersCheckBox.Value
                disctract.info.PosAmount = sum(app.tractset.stats.pos.shown);
                if numel(app.tractset.stats.pos.shown)==2
                    disctract.info.PosAmountRight = sum(app.tractset.stats.pos.shown(1));
                    disctract.info.PosAmountLeft = sum(app.tractset.stats.pos.shown(2));
                    disctract.info.PosPercentRight = app.RightSliderPositive.Value;
                    disctract.info.PosPercentLeft = app.LeftSliderPositive.Value;
                end
                disctract.info.PosPercent = app.ShowAmountSliderPositive.Value;

            end
            if app.ShowNegativeFibersCheckBox.Value
                disctract.info.NegAmount = sum(app.tractset.stats.neg.shown);
                if numel(app.tractset.stats.neg.shown)==2
                    disctract.info.NegAmountRight = sum(app.tractset.stats.neg.shown(1));
                    disctract.info.NegAmountLeft = sum(app.tractset.stats.neg.shown(2));
                    disctract.info.NegPercentRight = app.RightSliderNegative.Value;
                    disctract.info.NegPercentLeft = app.LeftSliderNegative.Value;
                end
                disctract.info.NegPercent = app.ShowAmountSliderNegative.Value;
            end
            atlasname = app.tractset.ID;
            tractname = 'Tract';
            negativecolor = app.tractset.negcolor;
            positivecolor = app.tractset.poscolor;
            ea_discfibers_exportatlas(disctract, atlasname, tractname, negativecolor, positivecolor);
        end

        % Callback function
        function CPSetsSetsEditFieldValueChanged(app, event)
            value = app.CPSetsSetsEditField.Value;
            app.tractset2UI;
        end

        % Value changed function: TrainOnListBox
        function TrainOnListBoxValueChanged(app, event)
            value = app.TrainOnListBox.Value;
            inverse = app.PredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.PredictListBox.Value=inverse;
            app.enablerules;
        end

        % Callback function
        function CPCohsTrainOnListBoxValueChanged(app, event)
            value = app.CPCohsTrainOnListBox.Value;
            inverse = app.CPCohsPredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.CPCohsPredictListBox.Value=inverse;
        end

        % Callback function
        function CPSetsTrainOnListBoxValueChanged(app, event)
            value = app.CPSetsTrainOnListBox.Value;
            inverse = app.CPSetsPredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.CPSetsPredictListBox.Value=inverse;
        end

        % Value changed function: PredictListBox
        function PredictListBoxValueChanged(app, event)
            value = app.PredictListBox.Value;
            app.enablerules;
        end

        % Value changed function: levelEditField
        function levelEditFieldValueChanged(app, event)
            value = app.levelEditField.Value;
            app.tractset.alphalevel=(value);
            app.draw;
        end

        % Value changed function: MultTestStrategy
        function MultTestStrategyValueChanged(app, event)
            value = app.MultTestStrategy.Value;
            app.tractset.multcompstrategy=value;
            app.draw;
        end

        % Value changed function: Corrtype
        function CorrtypeValueChanged(app, event)
            value = app.Corrtype.Value;
            app.tractset.corrtype=value;
            switch value
                case {'Skipped Pearson','Skipped Spearman'}
                    app.tractset.showsignificantonly=0;
            end
            app.enablerules;
            app.draw;
        end

        % Value changed function: LiveVisualizeCheckBox
        function LiveVisualizeCheckBoxValueChanged(app, event)
            value = app.LiveVisualizeCheckBox.Value;
            app.tractset.cvlivevisualize=value;
        end

        % Value changed function: BasePredictiononDropDown
        function BasePredictiononDropDownValueChanged(app, event)
            value = app.BasePredictiononDropDown.Value;
            app.tractset.basepredictionon=value;
            app.enablerules;
        end

        % Value changed function: SubcohortsDropDown
        function SubcohortsDropDownValueChanged(app, event)
            value = app.SubcohortsDropDown.Value;
            switch value
                case 'All Patients'
                    app.PatientsListBox.Value=app.PatientsListBox.Items;
                    app.tractset.patientselection = 1:length(app.PatientsListBox.Items);
                case 'Create Subcohort from Selection'
                    setname=inputdlg('Specify Set Name','Create New Set');
                    setname=setname{1};
                    setidx=ismember(app.PatientsListBox.Items,app.PatientsListBox.Value);
                    app.tractset.setlabels{end+1}=setname;
                    app.tractset.setselections{end+1}=setidx;
                case 'Create Subcohort from Inverse Selection'
                    setname=inputdlg('Specify Set Name','Create New Set');
                    setname=setname{1};
                    setidx=~ismember(app.PatientsListBox.Items,app.PatientsListBox.Value);
                    app.tractset.setlabels{end+1}=setname;
                    app.tractset.setselections{end+1}=setidx;
                case 'Choose...' % do nothing
                otherwise
                    [~,whichset]=ismember(value,app.tractset.setlabels);
                    app.PatientsListBox.Value=app.PatientsListBox.Items(app.tractset.setselections{whichset});
            end
            % draw:
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.tractset.patientselection = ix;
            app.draw;
            % always reset to "Choose..."
            app.enablerules;
        end

        % Menu selected function: SetseedforrandomnumbergeneratorMenu
        function SetseedforrandomnumbergeneratorMenuSelected(app, event)
            rngseed = app.tractset.rngseed;
            if ischar(rngseed) && strcmp(rngseed, 'default')
                rngseed = 0;
            end
            rngseed = inputdlg('Input seed for random number generator:', 'Random Seed', [1 40], {num2str(rngseed)});
            if ~isempty(rngseed)
                app.tractset.rngseed = str2double(rngseed{1});
            end
        end

        % Value changed function: ModelNormalizationDropDown
        function ModelNormalizationDropDownValueChanged(app, event)
            value = app.ModelNormalizationDropDown.Value;
            app.tractset.modelNormalization=value;
        end

        % Value changed function: BinsEditField
        function BinsEditFieldValueChanged(app, event)
            value = app.BinsEditField.Value;
            app.numBins=value;
        end

        % Selection changed function: ModelSetupOptionButtonGroup
        function ModelSetupOptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModelSetupOptionButtonGroup.SelectedObject;
            app.tractset.multitractmode=selectedButton.Text;
            if strcmp(selectedButton.Text,'Split & Color By Group')
                app.tractset.subscore.splitbysubscore = 0;
                app.tractset.doactualprediction = 0;
                enablerules(app);
                app.draw;
            elseif strcmp(selectedButton.Text,'Split & Color By Subscore')
                %removing the covariates box since it doesn't make sense
                %anymore
                app.tractset.subscore.splitbysubscore = 1;
                app.tractset.doactualprediction = 0;
                for subscore = 1:length(app.SubscoresListBox.Value)
                    app.tractset.subscore.labels{subscore,1} = app.SubscoresListBox.Value{subscore};
                    ix = cellfun(@(x)isequal(x,app.tractset.subscore.labels{subscore,1}),app.tractset.M.clinical.labels);
                    app.tractset.subscore.vars{subscore,1} = app.tractset.M.clinical.vars{ix};
                end
                enablerules(app);
                tabupdate(app);
                app.draw;
            elseif strcmp(selectedButton.Text,'Single Tract Analysis')
                app.tractset.subscore.splitbysubscore = 0;
                app.tractset.doactualprediction = 0;
                enablerules(app);
                app.draw;
            elseif strcmp(selectedButton.Text,'Split & Color By PCA')
                app.tractset.subscore.splitbysubscore = 0;
                app.tractset.doactualprediction = 1;
                app.FittoscoresCheckBox.Value = 1;
                for subscore = 1:length(app.SubscoresListBox.Value)
                    app.tractset.subscore.labels{subscore,1} = app.SubscoresListBox.Value{subscore};
                    ix = cellfun(@(x)isequal(x,app.tractset.subscore.labels{subscore,1}),app.tractset.M.clinical.labels);
                    app.tractset.subscore.vars{subscore,1} = app.tractset.M.clinical.vars{ix};
                end
                enablerules(app);
                app.draw;
            end
            % save dataset:
        end

        % Value changed function: SubscoresListBox
        function SubscoresListBoxValueChanged(app, event)

            app.tractset.subscore.labels = (app.SubscoresListBox.Value)';
            app.tractset.subscore.vars = cell(length(app.tractset.subscore.labels),1);
            try % keep prior weights if we can
                app.tractset.subscore.weights=app.tractset.subscore.weights(1:length(app.tractset.subscore.labels));
            catch
                app.tractset.subscore.weights = zeros(length(app.tractset.subscore.labels),1);
            end

            for subscore = 1:length(app.SubscoresListBox.Value)
                [is,ix] = ismember(app.tractset.subscore.labels{subscore,1},app.tractset.M.clinical.labels);
                app.tractset.subscore.vars{subscore,1} = app.tractset.M.clinical.vars{ix};
            end

            app.enablerules;
            app.draw;

        end

        % Button pushed function: MultitractWeightsButton
        function MultitractWeightsButtonPushed(app, event)

            f = uifigure('Scrollable','on');
            %f.Position(1) = 800;
            %f.Position(2) = 600;
            f.Position(3) = 630;
            f.Position(4) = 800;
            for i=1:length(app.tractset.subscore.vars)
                slider_name{i} = app.tractset.subscore.labels{i};
            end


            j = 720; %start position of the label
            k = 700; %start position of the slider
            l = 700;
            m = 682;

            for i=1:length(slider_name)
                slider_lab_name{i} = uilabel(f,'Text',slider_name{i},'Position',[20,j,500,22],'FontWeight','bold');
                slider_val_name{i} = uislider(f,'Limits',[0 100]);
                slider_val_name{i}.Position(1) = 280;
                slider_val_name{i}.Position(2) = k;
                slider_val_name{i}.Position(3) = 200;
                slider_text_box{i} = uieditfield(f,'numeric','Position',[500,l,50,22]);
                % ix = cellfun(@(x)isequal(x,app.tractset.subscore.labels{i,1}),app.tractset.M.clinical.labels);
                base_pred_lbl{i} = uilabel(f,'Text','Weight Using..','Position',[20,m,80,25]);
                base_pred_dd{i} = uidropdown(f,'Items',horzcat('None',app.tractset.M.clinical.labels),'Value','None','Position',[100,m,80,25]);
                j = j-70;
                k = k-70;
                l = l-70;
                m = m-70;

                % update values already stored
                if length(app.tractset.subscore.weights)>=i
                    if ~isnan(app.tractset.subscore.weights(i))
                        slider_val_name{i}.Value=app.tractset.subscore.weights(i)*100;
                        slider_text_box{i}.Value=app.tractset.subscore.weights(i)*100;
                        slider_val_name{i}.Enable='on';
                        slider_text_box{i}.Enable='on';
                    else
                        slider_val_name{i}.Enable='off';
                        slider_text_box{i}.Enable='off';
                        base_pred_dd{i}.Value=app.tractset.subscore.weightvarlabels{i};
                    end
                else
                    slider_val_name{i}.Enable='off';
                    slider_text_box{i}.Enable='off';
                    base_pred_dd{i}.Value=app.tractset.subscore.weightvarlabels{i};
                end

            end

            for i=1:length(slider_val_name)
                slider_val_name{i}.ValueChangedFcn = @(app,event) SliderChange(slider_text_box,slider_val_name,i);
                slider_val_name{i}.ValueChangingFcn = @(app,event) SliderChanging(event,slider_text_box,slider_val_name,i);
                slider_text_box{i}.ValueChangedFcn = @(app,event) SliderChange_text(slider_text_box,slider_val_name,i);
                base_pred_dd{i}.ValueChangedFcn = @(app,event) base_pred_fcn(base_pred_dd,slider_text_box,slider_lab_name,slider_val_name,i);
            end


        function base_pred_fcn(base_pred_dd,slider_text_box,slider_lab_name,slider_val_name,i)
            saved_labels = app.tractset.subscore.labels{i};
            saved_vars = app.tractset.subscore.vars{i};
            if strcmp(base_pred_dd{i}.Value,'None')
                slider_text_box{i}.Enable = 'on';
                slider_val_name{i}.Enable = 'on';
                app.tractset.subscore.weightvars{i,1} = '';
                app.tractset.subscore.weightvars{i,1} = [];
                app.tractset.subscore.weights(i)=slider_val_name{i}.Value/100;
            else
                slider_text_box{i}.Enable = 'off';
                slider_val_name{i}.Enable = 'off';
                index_of_new_subscore = cellfun(@(x)isequal(x,base_pred_dd{i}.Value),app.tractset.M.clinical.labels);
                app.tractset.subscore.weightvars{i,1} = app.tractset.M.clinical.vars{index_of_new_subscore};
                app.tractset.subscore.weightvarlabels{i,1} = base_pred_dd{i}.Value;
                app.tractset.subscore.weights(i)=nan;
            end

        end

        function SliderChange_text(slider_text_box,slider_val_name,i)

            text_value = slider_text_box{i}.Value;
            remainingamount=1-(text_value/100);
            otherentries=1:length(slider_val_name);
            otherentries(i)=[];
            weights=app.tractset.subscore.weights;
            weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
            weights(i)=slider_val_name{i}.Value/100;

            for slider=1:length(slider_val_name)
                if ~isnan(weights(slider))
                    slider_text_box{slider}.Value=weights(slider)*100;
                    app.tractset.subscore.weights(slider) = [weights(slider)];
                    slider_val_name{slider}.Value = weights(slider)*100;
                end
            end
            app.draw;
        end


        function SliderChange(slider_text_box,slider_val_name,i)
            %change value using sliders
            remainingamount=1-(slider_val_name{i}.Value/100);
            otherentries=1:length(slider_val_name);
            otherentries(i)=[];
            weights=app.tractset.subscore.weights;
            weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
            weights(i)=slider_val_name{i}.Value/100;

            for slider=1:length(slider_val_name)
                if ~isnan(weights(slider))
                    slider_val_name{slider}.Value=weights(slider)*100;
                    app.tractset.subscore.weights(slider) = [weights(slider)];
                    slider_text_box{slider}.Value = weights(slider)*100;
                end
            end

            app.draw;
        end

        function SliderChanging(event,slider_text_box,slider_val_name,i)
            remainingamount=1-(event.Value/100);
            otherentries=1:length(slider_val_name);
            otherentries(i)=[];
            weights=app.tractset.subscore.weights;
            weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
            weights(i)=event.Value/100;

            for slider=1:length(slider_val_name)
                if ~isnan(weights(slider))
                    slider_val_name{slider}.Value=weights(slider)*100;
                    app.tractset.subscore.weights(slider) = [weights(slider)];
                    slider_text_box{slider}.Value = weights(slider)*100;
                end
            end
        end
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.tractset.calcthreshold = value;


            hint={'Note that changing this value will not have an effect on values already calculated.'
                'You would need to repeat the calculation for each connectome of interest for changes to have an effect.'};
            uigetpref('LeadDBS','FiberFilteringShowCalcThreshHint','Fiber Filtering Explorer',hint,'Makes Sense');


        end

        % Value changed function: FittoscoresCheckBox
        function FittoscoresCheckBoxValueChanged(app, event)
            value = app.FittoscoresCheckBox.Value;
            app.tractset.doactualprediction = value;
            app.ModeltypeDropDown.Enable = ea_bool2onoff(value);

        end

        % Value changed function: ModeltypeDropDown
        function ModeltypeDropDownValueChanged(app, event)
            app.tractset.predictionmodel = app.ModeltypeDropDown.Value;
        end

        % Value changed function: PCsEditField
        function PCsEditFieldValueChanged(app, event)
            value = app.PCsEditField.Value;
            if value > length(app.tractset.subscore.vars)
                app.PCsEditField.Value = length(app.tractset.subscore.vars);
                msgbox("You cannot set more PCs than the number of variables");
            else
                app.tractset.numpcs=value;
                app.updatepca('edit');
                enablerules(app);
                app.draw;
            end
        end

        % Value changed function: VarianceExpSlider
        function VarianceExpSliderValueChanged(app, event)
            value = app.VarianceExpSlider.Value;
            app.updatepca('slider');
            app.draw;
        end

        % Value changing function: VarianceExpSlider
        function VarianceExpSliderValueChanging(app, event)
            changingValue = event.Value;
        end

        % Value changing function: RightSliderNegative
        function RightSliderNegativeValueChanging(app, event)
            changingValue = event.Value;

        end

        % Value changed function: AutoRefreshCheckBox
        function AutoRefreshCheckBoxValueChanged(app, event)
            value = app.AutoRefreshCheckBox.Value;

        end

        % Callback function
        function ConsiderSignificantFibersonlyCheckBox_2ValueChanged(app, event)
            value = app.ConsiderSignificantFibersonlyCheckBox_2.Value;
            app.tractset.subscore.showsignificantonly{1}=value;
            app.draw;
        end

        % Callback function
        function levelEditField_2ValueChanged(app, event)
            value = app.levelEditField_2.Value;
            app.tractset.subscore.alphalevel(1)=(value);
            app.draw;
        end

        % Callback function
        function MultTestStrategy_2ValueChanged(app, event)
            value = app.MultTestStrategy_2.Value;
            app.tractset.subscore.multcompstrategy{1}=value;
            app.draw;
        end

        % Value changed function: ShowNegativeFibersCheckBox_2
        function ShowNegativeFibersCheckBox_2ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_2.Value;
            app.tractset.subscore.negvisible(1) = value;
            if ~value
                %                 app.ShowAmountSliderNegative_2.Enable = 'off';
                %                 app.ShowAmountSlider_2Label_2.Enable = 'off';
                %                 app.LeftSliderNegative_2.Enable = 'off';
                %                 app.RightSliderNegative_2.Enable = 'off';
                %                 app.ColorButtonNegative_2.Enable = 'off';
                %                 app.LeftSlider_2Label_2.Enable = 'off';
                %                 app.RightSlider_2Label_2.Enable = 'off';
                app.draw;
            else
                app.ShowAmountSliderNegative_2.Enable = 'on';
                app.ShowAmountSlider_2Label_2.Enable = 'on';
                app.LeftSliderNegative_2.Enable = 'on';
                app.RightSliderNegative_2.Enable = 'on';
                app.ColorButtonNegative_2.Enable = 'on';
                app.LeftSlider_2Label_2.Enable = 'on';
                app.RightSlider_2Label_2.Enable = 'on';
                app.draw;
            end


        end

        % Value changed function: ShowPositiveFibersCheckBox_2
        function ShowPositiveFibersCheckBox_2ValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox_2.Value;
            app.tractset.subscore.posvisible(1) = value;

            if ~value
                % app.ShowAmountSliderPositive_2.Enable = 'off';
                % app.ShowAmountSliderLabel_2.Enable = 'off';
                % app.LeftSliderPositive_2.Enable = 'off';
                % app.RightSliderPositive_2.Enable = 'off';
                % app.ColorButtonPositive_2.Enable = 'off';
                % app.LeftSliderLabel_2.Enable = 'off';
                % app.RightSliderLabel_2.Enable = 'off';
                app.draw;
            else
                app.ShowAmountSliderPositive_2.Enable = 'on';
                app.ShowAmountSliderLabel_2.Enable = 'on';
                app.LeftSliderPositive_2.Enable = 'on';
                app.RightSliderPositive_2.Enable = 'on';
                app.ColorButtonPositive_2.Enable = 'on';
                app.LeftSliderLabel_2.Enable = 'on';
                app.RightSliderLabel_2.Enable = 'on';
                app.draw;
            end

        end

        % Value changed function: ShowAmountSliderNegative_2
        function ShowAmountSliderNegative_2ValueChanged(app, event)
            value = app.ShowAmountSliderNegative_2.Value;
            app.LeftSliderNegative_2.Value = value;
            app.RightSliderNegative_2.Value = value;
            app.ShowAmountSliderNegative_2.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderNegative_2.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderNegative_2.Tooltip = sprintf('%d%%', round(value));
            app.tractset.subscore.vis.shownegamount(1,:) = [app.RightSliderNegative_2.Value, app.LeftSliderNegative_2.Value];
            app.draw;
        end

        % Value changed function: ShowAmountSliderPositive_2
        function ShowAmountSliderPositive_2ValueChanged(app, event)
            value = app.ShowAmountSliderPositive_2.Value;
            app.LeftSliderPositive_2.Value = value;
            app.RightSliderPositive_2.Value = value;
            app.ShowAmountSliderPositive_2.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderPositive_2.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderPositive_2.Tooltip = sprintf('%d%%', round(value));
            app.tractset.subscore.vis.showposamount(1,:) = [app.RightSliderPositive_2.Value, app.LeftSliderPositive_2.Value];
            app.draw;
        end

        % Value changing function: ShowAmountSliderNegative_2
        function ShowAmountSliderNegative_2ValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderNegative_2.Value = changingValue;
            app.RightSliderNegative_2.Value = changingValue;
            app.tractset.subscore.vis.shownegamount(1,:) = [app.RightSliderNegative_2.Value, app.LeftSliderNegative_2.Value];

        end

        % Value changing function: ShowAmountSliderPositive_2
        function ShowAmountSliderPositive_2ValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderPositive_2.Value = changingValue;
            app.RightSliderPositive_2.Value = changingValue;
            app.tractset.subscore.vis.showposamount(1,:) = [app.RightSliderPositive_2.Value, app.LeftSliderPositive_2.Value];

        end

        % Value changed function: LeftSliderNegative_2
        function LeftSliderNegative_2ValueChanged(app, event)
            value = app.LeftSliderNegative_2.Value;
            app.tractset.subscore.vis.shownegamount(1,:) = [app.RightSliderNegative_2.Value, app.LeftSliderNegative_2.Value];
            app.LeftSliderNegative_2.Tooltip = sprintf('%d%%', round(app.LeftSliderNegative_2.Value));
            app.draw;
        end

        % Value changed function: RightSliderNegative_2
        function RightSliderNegative_2ValueChanged(app, event)
            value = app.RightSliderNegative_2.Value;
            app.tractset.subscore.vis.shownegamount(1,:) = [app.RightSliderNegative_2.Value, app.RightSliderNegative_2.Value];
            app.RightSliderNegative_2.Tooltip = sprintf('%d%%', round(app.RightSliderNegative_2.Value));
            app.draw;
        end

        % Value changing function: LeftSliderNegative_2
        function LeftSliderNegative_2ValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: LeftSliderNegative
        function LeftSliderNegativeValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: LeftSliderPositive_2
        function LeftSliderPositive_2ValueChanged(app, event)
            value = app.LeftSliderPositive_2.Value;
            app.tractset.subscore.vis.showposamount(1,:) = [app.RightSliderPositive_2.Value, app.LeftSliderPositive_2.Value];
            app.LeftSliderPositive_2.Tooltip = sprintf('%d%%', round(app.LeftSliderPositive_2.Value));
            app.draw;
        end

        % Value changed function: RightSliderPositive_2
        function RightSliderPositive_2ValueChanged(app, event)
            value = app.RightSliderPositive_2.Value;
            app.tractset.subscore.vis.showposamount(1,:) = [app.RightSliderPositive_2.Value, app.LeftSliderPositive_2.Value];
            app.RightSliderPositive_2.Tooltip = sprintf('%d%%', round(app.LeftSliderPositive_2.Value));
            app.draw;
        end

        % Button down function: ColorBar
        function ColorBarButtonDown(app, event)
            
        end

        % Button pushed function: ColorButtonNegative_2
        function ColorButtonNegative_2Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_2.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,1}(1,:) = app.ColorButtonNegative_2.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(1,:) = app.ColorButtonNegative_2.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.groupcolors(1,:) = app.ColorButtonNegative_2.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_2
        function ColorButtonPositive_2Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_2.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,2}(1,:) = app.ColorButtonPositive_2.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(1,:) = app.ColorButtonPositive_2.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.groupcolors(1,:) = app.ColorButtonPositive_2.BackgroundColor;
            end
            app.draw;
        end

        % Value changed function: ShowAmountSliderPositive_3
        function ShowAmountSliderPositive_3ValueChanged(app, event)
            value = app.ShowAmountSliderPositive_3.Value;
            app.LeftSliderPositive_3.Value = value;
            app.RightSliderPositive_3.Value = value;
            app.ShowAmountSliderPositive_3.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderPositive_3.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderPositive_3.Tooltip = sprintf('%d%%', round(value));
            app.tractset.subscore.vis.showposamount(2,:) = [app.RightSliderPositive_3.Value, app.LeftSliderPositive_3.Value];
            app.draw;
        end

        % Value changing function: ShowAmountSliderPositive_3
        function ShowAmountSliderPositive_3ValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderPositive_3.Value = changingValue;
            app.RightSliderPositive_3.Value = changingValue;
            app.ShowAmountSliderPositive_3.Tooltip = sprintf('%d%%', round(changingValue));
            app.LeftSliderPositive_3.Tooltip = sprintf('%d%%', round(changingValue));
            app.RightSliderPositive_3.Tooltip = sprintf('%d%%', round(changingValue));
            app.tractset.subscore.vis.showposamount(2,:) = [app.RightSliderPositive_3.Value, app.LeftSliderPositive_3.Value];
        end

        % Value changed function: LeftSliderPositive_3
        function LeftSliderPositive_3ValueChanged(app, event)
            value = app.LeftSliderPositive_3.Value;
            app.tractset.subscore.vis.showposamount(2,:) = [app.RightSliderPositive_3.Value, app.LeftSliderPositive_3.Value];
            app.LeftSliderPositive_3.Tooltip = sprintf('%d%%', round(app.LeftSliderPositive_3.Value));
            app.draw;
        end

        % Value changed function: RightSliderPositive_3
        function RightSliderPositive_3ValueChanged(app, event)
            value = app.RightSliderPositive_3.Value;
            app.tractset.subscore.vis.showposamount(2,:) = [app.RightSliderPositive_3.Value, app.LeftSliderPositive_3.Value];
            app.RightSliderPositive_3.Tooltip = sprintf('%d%%', round(app.RightSliderPositive_3.Value));
            app.draw;
        end

        % Value changed function: ShowNegativeFibersCheckBox_3
        function ShowNegativeFibersCheckBox_3ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_3.Value;
            app.tractset.subscore.negvisible(2) = value;
            if ~value
                %                 app.ShowAmountSliderNegative_3.Enable = 'off';
                %                 app.ShowAmountSlider_2Label_3.Enable = 'off';
                %                 app.LeftSliderNegative_3.Enable = 'off';
                %                 app.RightSliderNegative_3.Enable = 'off';
                %                 app.ColorButtonNegative_3.Enable = 'off';
                %                 app.LeftSlider_2Label_3.Enable = 'off';
                %                 app.RightSlider_2Label_3.Enable = 'off';
                app.draw;
            else
                app.ShowAmountSliderNegative_3.Enable = 'on';
                app.ShowAmountSlider_2Label_3.Enable = 'on';
                app.LeftSliderNegative_3.Enable = 'on';
                app.RightSliderNegative_3.Enable = 'on';
                app.ColorButtonNegative_3.Enable = 'on';
                app.LeftSlider_2Label_3.Enable = 'on';
                app.RightSlider_2Label_3.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowPositiveFibersCheckBox_3
        function ShowPositiveFibersCheckBox_3ValueChanged(app, event)

            value = app.ShowPositiveFibersCheckBox_3.Value;
            app.tractset.subscore.posvisible(2) = value;

            if ~value
                %                 app.ShowAmountSliderPositive_3.Enable = 'off';
                %                 app.ShowAmountSliderLabel_3.Enable = 'off';
                %                 app.LeftSliderPositive_3.Enable = 'off';
                %                 app.RightSliderPositive_3.Enable = 'off';
                %                 app.ColorButtonPositive_3.Enable = 'off';
                %                 app.LeftSliderLabel_3.Enable = 'off';
                %                 app.RightSliderLabel_3.Enable = 'off';
                app.draw;
            else
                app.ShowAmountSliderPositive_3.Enable = 'on';
                app.ShowAmountSliderLabel_3.Enable = 'on';
                app.LeftSliderPositive_3.Enable = 'on';
                app.RightSliderPositive_3.Enable = 'on';
                app.ColorButtonPositive_3.Enable = 'on';
                app.LeftSliderLabel_3.Enable = 'on';
                app.RightSliderLabel_3.Enable = 'on';
                app.draw;
            end

        end

        % Value changed function: ShowAmountSliderNegative_3
        function ShowAmountSliderNegative_3ValueChanged(app, event)
            value = app.ShowAmountSliderNegative_3.Value;
            app.LeftSliderNegative_3.Value = value;
            app.RightSliderNegative_3.Value = value;
            app.ShowAmountSliderNegative_3.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderNegative_3.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderNegative_3.Tooltip = sprintf('%d%%', round(value));
            app.tractset.subscore.vis.shownegamount(2,:) = [app.RightSliderNegative_3.Value, app.LeftSliderNegative_3.Value];
            app.draw;
        end

        % Value changing function: ShowAmountSliderNegative_3
        function ShowAmountSliderNegative_3ValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderNegative_3.Value = changingValue;
            app.RightSliderNegative_3.Value = changingValue;
            app.ShowAmountSliderNegative_3.Tooltip = sprintf('%d%%', round(changingValue));
            app.LeftSliderNegative_3.Tooltip = sprintf('%d%%', round(changingValue));
            app.RightSliderNegative_3.Tooltip = sprintf('%d%%', round(changingValue));
            app.tractset.subscore.vis.shownegamount(2,:) = [app.RightSliderNegative_3.Value, app.LeftSliderNegative_3.Value];
        end

        % Value changed function: LeftSliderNegative_3
        function LeftSliderNegative_3ValueChanged(app, event)
            value = app.LeftSliderNegative_3.Value;
            app.tractset.subscore.vis.shownegamount(2,:) = [app.RightSliderNegative_3.Value, app.LeftSliderNegative_3.Value];
            app.LeftSliderNegative_3.Tooltip = sprintf('%d%%', round(app.LeftSliderNegative_3.Value));
            app.draw;
        end

        % Value changed function: RightSliderNegative_3
        function RightSliderNegative_3ValueChanged(app, event)
            value = app.RightSliderNegative_3.Value;
            app.tractset.subscore.vis.shownegamount(2,:) = [app.RightSliderNegative_3.Value, app.LeftSliderNegative_3.Value];
            app.RightSliderNegative_3.Tooltip = sprintf('%d%%', round(app.RightSliderNegative_3.Value));
            flag = 1;
            app.draw;
        end

        % Value changed function: ShowPositiveFibersCheckBox_4
        function ShowPositiveFibersCheckBox_4ValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox_4.Value;
            app.tractset.subscore.posvisible(3) = value;
            if ~value
                %                 app.ShowAmountSliderPositive_4.Enable = 'off';
                %                 app.ShowAmountSliderLabel_4.Enable = 'off';
                %                 app.LeftSliderPositive_4.Enable = 'off';
                %                 app.RightSliderPositive_4.Enable = 'off';
                %                 app.ColorButtonPositive_4.Enable = 'off';
                %                 app.LeftSliderLabel_4.Enable = 'off';
                %                 app.RightSliderLabel_4.Enable = 'off';
                app.draw;
            else
                app.ShowAmountSliderPositive_4.Enable = 'on';
                app.ShowAmountSliderLabel_4.Enable = 'on';
                app.LeftSliderPositive_4.Enable = 'on';
                app.RightSliderPositive_4.Enable = 'on';
                app.ColorButtonPositive_4.Enable = 'on';
                app.LeftSliderLabel_4.Enable = 'on';
                app.RightSliderLabel_4.Enable = 'on';
                app.draw;
            end

        end

        % Value changed function: ShowAmountSliderPositive_4
        function ShowAmountSliderPositive_4ValueChanged(app, event)
            value = app.ShowAmountSliderPositive_4.Value;
            app.LeftSliderPositive_4.Value = value;
            app.RightSliderPositive_4.Value = value;
            app.ShowAmountSliderPositive_4.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderPositive_4.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderPositive_4.Tooltip = sprintf('%d%%', round(value));
            app.tractset.subscore.vis.showposamount(3,:) = [app.RightSliderPositive_4.Value, app.LeftSliderPositive_4.Value];
            app.draw;
        end

        % Value changed function: LeftSliderPositive_4
        function LeftSliderPositive_4ValueChanged(app, event)
            value = app.LeftSliderPositive_4.Value;
            app.tractset.subscore.vis.showposamount(3,:) = [app.RightSliderPositive_4.Value, app.LeftSliderPositive_4.Value];
            app.LeftSliderPositive_4.Tooltip = sprintf('%d%%', round(app.LeftSliderPositive_4.Value));
            app.draw;
        end

        % Value changing function: ShowAmountSliderPositive_4
        function ShowAmountSliderPositive_4ValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderPositive_4.Value = changingValue;
            app.RightSliderPositive_4.Value = changingValue;
            app.ShowAmountSliderPositive_4.Tooltip = sprintf('%d%%', round(changingValue));
            app.LeftSliderPositive_4.Tooltip = sprintf('%d%%', round(changingValue));
            app.RightSliderPositive_4.Tooltip = sprintf('%d%%', round(changingValue));
            app.tractset.subscore.vis.showposamount(3,:) = [app.RightSliderPositive_4.Value, app.LeftSliderPositive_4.Value];
        end

        % Value changed function: RightSliderPositive_4
        function RightSliderPositive_4ValueChanged(app, event)
            value = app.RightSliderPositive_4.Value;
            app.tractset.subscore.vis.showposamount(3,:) = [app.RightSliderPositive_4.Value, app.LeftSliderPositive_4.Value];
            app.RightSliderPositive_4.Tooltip = sprintf('%d%%', round(app.RightSliderPositive_4.Value));
            app.draw;
        end

        % Value changed function: ShowNegativeFibersCheckBox_4
        function ShowNegativeFibersCheckBox_4ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_4.Value;
            app.tractset.subscore.negvisible(3) = value;
            if ~value
                %                 app.ShowAmountSliderNegative_4.Enable = 'off';
                %                 app.ShowAmountSlider_2Label_4.Enable = 'off';
                %                 app.LeftSliderNegative_4.Enable = 'off';
                %                 app.RightSliderNegative_4.Enable = 'off';
                %                 app.ColorButtonNegative_4.Enable = 'off';
                %                 app.LeftSlider_2Label_4.Enable = 'off';
                %                 app.RightSlider_2Label_4.Enable = 'off';
                app.draw;
            else
                app.ShowAmountSliderNegative_4.Enable = 'on';
                app.ShowAmountSlider_2Label_4.Enable = 'on';
                app.LeftSliderNegative_4.Enable = 'on';
                app.RightSliderNegative_4.Enable = 'on';
                app.ColorButtonNegative_4.Enable = 'on';
                app.LeftSlider_2Label_4.Enable = 'on';
                app.RightSlider_2Label_4.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowAmountSliderNegative_4
        function ShowAmountSliderNegative_4ValueChanged(app, event)
            value = app.ShowAmountSliderNegative_4.Value;
            app.LeftSliderNegative_4.Value = value;
            app.RightSliderNegative_4.Value = value;
            app.ShowAmountSliderNegative_4.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderNegative_4.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderNegative_4.Tooltip = sprintf('%d%%', round(value));
            app.tractset.subscore.vis.shownegamount(3,:) = [app.RightSliderNegative_4.Value, app.LeftSliderNegative_4.Value];
            app.draw;
        end

        % Value changed function: LeftSliderNegative_4
        function LeftSliderNegative_4ValueChanged(app, event)
            value = app.LeftSliderNegative_4.Value;
            app.tractset.subscore.vis.shownegamount(3,:) = [app.RightSliderNegative_4.Value, app.LeftSliderNegative_4.Value];
            app.LeftSliderNegative_4.Tooltip = sprintf('%d%%', round(app.LeftSliderNegative_4.Value));
            app.draw;

        end

        % Value changing function: ShowAmountSliderNegative_4
        function ShowAmountSliderNegative_4ValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderNegative_4.Value = changingValue;
            app.RightSliderNegative_4.Value = changingValue;
            app.ShowAmountSliderNegative_4.Tooltip = sprintf('%d%%', round(changingValue));
            app.LeftSliderNegative_4.Tooltip = sprintf('%d%%', round(changingValue));
            app.RightSliderNegative_4.Tooltip = sprintf('%d%%', round(changingValue));
            app.tractset.subscore.vis.shownegamount(3,:) = [app.RightSliderNegative_4.Value, app.LeftSliderNegative_4.Value];
        end

        % Value changed function: RightSliderNegative_4
        function RightSliderNegative_4ValueChanged(app, event)
            value = app.RightSliderNegative_4.Value;
            app.tractset.subscore.vis.shownegamount(3,:) = [app.RightSliderNegative_4.Value, app.LeftSliderNegative_4.Value];
            app.RightSliderNegative_4.Tooltip = sprintf('%d%%', round(app.RightSliderNegative_4.Value));
            app.draw;
        end

        % Value changed function: ShowPositiveFibersCheckBox_5
        function ShowPositiveFibersCheckBox_5ValueChanged(app, event)

            value = app.ShowPositiveFibersCheckBox_5.Value;

            app.tractset.subscore.posvisible(4) = value;

            if ~value
                %                 app.ShowAmountSliderPositive_5.Enable = 'off';
                %                 app.ShowAmountSliderLabel_5.Enable = 'off';
                %                 app.LeftSliderPositive_5.Enable = 'off';
                %                 app.RightSliderPositive_5.Enable = 'off';
                %                 app.ColorButtonPositive_5.Enable = 'off';
                %                 app.LeftSliderLabel_5.Enable = 'off';
                %                 app.RightSliderLabel_5.Enable = 'off';
                app.draw;
            else
                app.ShowAmountSliderPositive_5.Enable = 'on';
                app.ShowAmountSliderLabel_5.Enable = 'on';
                app.LeftSliderPositive_5.Enable = 'on';
                app.RightSliderPositive_5.Enable = 'on';
                app.ColorButtonPositive_5.Enable = 'on';
                app.LeftSliderLabel_5.Enable = 'on';
                app.RightSliderLabel_5.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowAmountSliderPositive_5
        function ShowAmountSliderPositive_5ValueChanged(app, event)
            value = app.ShowAmountSliderPositive_5.Value;
            app.LeftSliderPositive_5.Value = value;
            app.RightSliderPositive_5.Value = value;
            app.ShowAmountSliderPositive_5.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderPositive_5.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderPositive_5.Tooltip = sprintf('%d%%', round(value));
            app.tractset.subscore.vis.showposamount(4,:) = [app.RightSliderPositive_5.Value, app.LeftSliderPositive_5.Value];
            app.draw;
        end

        % Value changing function: ShowAmountSliderPositive_5
        function ShowAmountSliderPositive_5ValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderPositive_5.Value = changingValue;
            app.RightSliderPositive_5.Value = changingValue;
            app.ShowAmountSliderPositive_5.Tooltip = sprintf('%d%%', round(changingValue));
            app.LeftSliderPositive_5.Tooltip = sprintf('%d%%', round(changingValue));
            app.RightSliderPositive_5.Tooltip = sprintf('%d%%', round(changingValue));
            app.tractset.subscore.vis.showposamount(4,:) = [app.RightSliderPositive_5.Value, app.LeftSliderPositive_5.Value];
        end

        % Value changed function: LeftSliderPositive_5
        function LeftSliderPositive_5ValueChanged(app, event)
            value = app.LeftSliderPositive_5.Value;
            app.tractset.subscore.vis.showposamount(4,:) = [app.RightSliderPositive_5.Value, app.LeftSliderPositive_5.Value];
            app.LeftSliderPositive_5.Tooltip = sprintf('%d%%', round(app.LeftSliderPositive_5.Value));
            app.draw;
        end

        % Value changed function: RightSliderPositive_5
        function RightSliderPositive_5ValueChanged(app, event)
            value = app.RightSliderPositive_5.Value;
            app.tractset.subscore.vis.showposamount(4,:) = [app.RightSliderPositive_5.Value, app.LeftSliderPositive_5.Value];
            app.RightSliderPositive_5.Tooltip = sprintf('%d%%', round(app.RightSliderPositive_5.Value));
            app.draw;

        end

        % Value changed function: ShowNegativeFibersCheckBox_5
        function ShowNegativeFibersCheckBox_5ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_5.Value;
            app.tractset.subscore.negvisible(4) = value;
            if ~value
                %                 app.ShowAmountSliderNegative_5.Enable = 'off';
                %                 app.ShowAmountSlider_2Label_5.Enable = 'off';
                %                 app.LeftSliderNegative_5.Enable = 'off';
                %                 app.RightSliderNegative_5.Enable = 'off';
                %                 app.ColorButtonNegative_5.Enable = 'off';
                %                 app.LeftSlider_2Label_5.Enable = 'off';
                %                 app.RightSlider_2Label_5.Enable = 'off';
                app.draw;
            else
                app.ShowAmountSliderNegative_5.Enable = 'on';
                app.ShowAmountSlider_2Label_5.Enable = 'on';
                app.LeftSliderNegative_5.Enable = 'on';
                app.RightSliderNegative_5.Enable = 'on';
                app.ColorButtonNegative_5.Enable = 'on';
                app.LeftSlider_2Label_5.Enable = 'on';
                app.RightSlider_2Label_5.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowAmountSliderNegative_5
        function ShowAmountSliderNegative_5ValueChanged(app, event)
            value = app.ShowAmountSliderNegative_5.Value;
            app.LeftSliderNegative_5.Value = value;
            app.RightSliderNegative_5.Value = value;
            app.ShowAmountSliderNegative_5.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderNegative_5.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderNegative_5.Tooltip = sprintf('%d%%', round(value));
            app.tractset.subscore.vis.shownegamount(4,:) = [app.RightSliderNegative_5.Value, app.LeftSliderNegative_5.Value];
            app.draw;

        end

        % Value changing function: ShowAmountSliderNegative_5
        function ShowAmountSliderNegative_5ValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderNegative_5.Value = changingValue;
            app.RightSliderNegative_5.Value = changingValue;
            app.ShowAmountSliderNegative_5.Tooltip = sprintf('%d%%', round(changingValue));
            app.LeftSliderNegative_5.Tooltip = sprintf('%d%%', round(changingValue));
            app.RightSliderNegative_5.Tooltip = sprintf('%d%%', round(changingValue));
            app.tractset.subscore.vis.shownegamount(4,:) = [app.RightSliderNegative_5.Value, app.LeftSliderNegative_5.Value];
        end

        % Value changed function: LeftSliderNegative_5
        function LeftSliderNegative_5ValueChanged(app, event)
            value = app.LeftSliderNegative_5.Value;
            app.tractset.subscore.vis.shownegamount(4,:) = [app.RightSliderNegative_4.Value, app.LeftSliderNegative_4.Value];
            app.LeftSliderNegative_5.Tooltip = sprintf('%d%%', round(app.LeftSliderNegative_5.Value));
            app.draw;
        end

        % Value changed function: RightSliderNegative_5
        function RightSliderNegative_5ValueChanged(app, event)
            value = app.RightSliderNegative_5.Value;
            app.tractset.subscore.vis.shownegamount(4,:) = [app.RightSliderNegative_5.Value, app.LeftSliderNegative_5.Value];
            app.RightSliderNegative_5.Tooltip = sprintf('%d%%', round(app.RightSliderNegative_5.Value));
            app.draw;
        end

        % Value changed function: ShowPositiveFibersCheckBox_6
        function ShowPositiveFibersCheckBox_6ValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox_6.Value;

            app.tractset.subscore.posvisible(5) = value;

            if ~value
                %                 app.ShowAmountSliderPositive_6.Enable = 'off';
                %                 app.ShowAmountSliderLabel_6.Enable = 'off';
                %                 app.LeftSliderPositive_6.Enable = 'off';
                %                 app.RightSliderPositive_6.Enable = 'off';
                %                 app.ColorButtonPositive_6.Enable = 'off';
                %                 app.LeftSliderLabel_6.Enable = 'off';
                %                 app.RightSliderLabel_6.Enable = 'off';
                app.draw;
            else
                app.ShowAmountSliderPositive_6.Enable = 'on';
                app.ShowAmountSliderLabel_6.Enable = 'on';
                app.LeftSliderPositive_6.Enable = 'on';
                app.RightSliderPositive_6.Enable = 'on';
                app.ColorButtonPositive_6.Enable = 'on';
                app.LeftSliderLabel_6.Enable = 'on';
                app.RightSliderLabel_6.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowAmountSliderPositive_6
        function ShowAmountSliderPositive_6ValueChanged(app, event)
            value = app.ShowAmountSliderPositive_6.Value;
            app.LeftSliderPositive_6.Value = value;
            app.RightSliderPositive_6.Value = value;
            app.ShowAmountSliderPositive_6.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderPositive_6.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderPositive_6.Tooltip = sprintf('%d%%', round(value));
            app.tractset.subscore.vis.showposamount(5,:) = [app.RightSliderPositive_6.Value, app.LeftSliderPositive_6.Value];
            app.draw;
        end

        % Value changing function: ShowAmountSliderPositive_6
        function ShowAmountSliderPositive_6ValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderPositive_6.Value = changingValue;
            app.RightSliderPositive_6.Value = changingValue;
            app.ShowAmountSliderPositive_6.Tooltip = sprintf('%d%%', round(changingValue));
            app.LeftSliderPositive_6.Tooltip = sprintf('%d%%', round(changingValue));
            app.RightSliderPositive_6.Tooltip = sprintf('%d%%', round(changingValue));
            app.tractset.subscore.vis.showposamount(5,:) = [app.RightSliderPositive_6.Value, app.LeftSliderPositive_6.Value];
        end

        % Value changed function: LeftSliderPositive_6
        function LeftSliderPositive_6ValueChanged(app, event)
            value = app.LeftSliderPositive_6.Value;
            app.tractset.subscore.vis.showposamount(5,:) = [app.RightSliderPositive_6.Value, app.LeftSliderPositive_6.Value];
            app.LeftSliderPositive_6.Tooltip = sprintf('%d%%', round(app.LeftSliderPositive_6.Value));
            app.draw;
        end

        % Value changed function: RightSliderPositive_6
        function RightSliderPositive_6ValueChanged(app, event)
            value = app.RightSliderPositive_6.Value;
            app.tractset.subscore.vis.showposamount(5,:) = [app.RightSliderPositive_6.Value, app.LeftSliderPositive_6.Value];
            app.RightSliderPositive_6.Tooltip = sprintf('%d%%', round(app.RightSliderPositive_6.Value));
            app.draw;

        end

        % Value changed function: ShowNegativeFibersCheckBox_6
        function ShowNegativeFibersCheckBox_6ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_6.Value;
            app.tractset.subscore.negvisible(5) = value;
            if ~value
                %                 app.ShowAmountSliderNegative_6.Enable = 'off';
                %                 app.ShowAmountSlider_2Label_6.Enable = 'off';
                %                 app.LeftSliderNegative_6.Enable = 'off';
                %                 app.RightSliderNegative_6.Enable = 'off';
                %                 app.ColorButtonNegative_6.Enable = 'off';
                %                 app.LeftSlider_2Label_6.Enable = 'off';
                %                 app.RightSlider_2Label_6.Enable = 'off';
                app.draw;
            else
                app.ShowAmountSliderNegative_6.Enable = 'on';
                app.ShowAmountSlider_2Label_6.Enable = 'on';
                app.LeftSliderNegative_6.Enable = 'on';
                app.RightSliderNegative_6.Enable = 'on';
                app.ColorButtonNegative_6.Enable = 'on';
                app.LeftSlider_2Label_6.Enable = 'on';
                app.RightSlider_2Label_6.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowAmountSliderNegative_6
        function ShowAmountSliderNegative_6ValueChanged(app, event)
            value = app.ShowAmountSliderNegative_6.Value;
            app.LeftSliderNegative_6.Value = value;
            app.RightSliderNegative_6.Value = value;
            app.ShowAmountSliderNegative_6.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderNegative_6.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderNegative_6.Tooltip = sprintf('%d%%', round(value));
            app.tractset.subscore.vis.shownegamount(5,:) = [app.RightSliderNegative_6.Value, app.LeftSliderNegative_6.Value];
            app.draw;

        end

        % Value changing function: ShowAmountSliderNegative_6
        function ShowAmountSliderNegative_6ValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderNegative_6.Value = changingValue;
            app.RightSliderNegative_6.Value = changingValue;
            app.ShowAmountSliderNegative_6.Tooltip = sprintf('%d%%', round(changingValue));
            app.LeftSliderNegative_6.Tooltip = sprintf('%d%%', round(changingValue));
            app.RightSliderNegative_6.Tooltip = sprintf('%d%%', round(changingValue));
            app.tractset.subscore.vis.shownegamount(5,:) = [app.RightSliderNegative_6.Value, app.LeftSliderNegative_6.Value];
        end

        % Value changed function: LeftSliderNegative_6
        function LeftSliderNegative_6ValueChanged(app, event)
            value = app.LeftSliderNegative_6.Value;
            app.tractset.subscore.vis.shownegamount(5,:) = [app.RightSliderNegative_6.Value, app.LeftSliderNegative_6.Value];
            app.LeftSliderNegative_6.Tooltip = sprintf('%d%%', round(app.LeftSliderNegative_6.Value));
            app.draw;

        end

        % Value changed function: RightSliderNegative_6
        function RightSliderNegative_6ValueChanged(app, event)
            value = app.RightSliderNegative_6.Value;
            app.tractset.subscore.vis.shownegamount(5,:) = [app.RightSliderNegative_6.Value, app.LeftSliderNegative_6.Value];
            app.RightSliderNegative_6.Tooltip = sprintf('%d%%', round(app.RightSliderNegative_6.Value));
            app.draw;

        end

        % Value changed function: ShowPositiveFibersCheckBox_7
        function ShowPositiveFibersCheckBox_7ValueChanged(app, event)

            value = app.ShowPositiveFibersCheckBox_7.Value;
            app.tractset.subscore.posvisible(6) = value;

            if ~value
                %                 app.ShowAmountSliderPositive_7.Enable = 'off';
                %                 app.ShowAmountSliderLabel_7.Enable = 'off';
                %                 app.LeftSliderPositive_7.Enable = 'off';
                %                 app.RightSliderPositive_7.Enable = 'off';
                %                 app.ColorButtonPositive_7.Enable = 'off';
                %                 app.LeftSliderLabel_7.Enable = 'off';
                %                 app.RightSliderLabel_7.Enable = 'off';
                app.draw;
            else
                app.ShowAmountSliderPositive_7.Enable = 'on';
                app.ShowAmountSliderLabel_7.Enable = 'on';
                app.LeftSliderPositive_7.Enable = 'on';
                app.RightSliderPositive_7.Enable = 'on';
                app.ColorButtonPositive_7.Enable = 'on';
                app.LeftSliderLabel_7.Enable = 'on';
                app.RightSliderLabel_7.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowAmountSliderPositive_7
        function ShowAmountSliderPositive_7ValueChanged(app, event)
            value = app.ShowAmountSliderPositive_7.Value;
            app.LeftSliderPositive_7.Value = value;
            app.RightSliderPositive_7.Value = value;
            app.ShowAmountSliderPositive_7.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderPositive_7.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderPositive_7.Tooltip = sprintf('%d%%', round(value));
            app.tractset.subscore.vis.showposamount(6,:) = [app.RightSliderPositive_7.Value, app.LeftSliderPositive_7.Value];
            app.draw;
        end

        % Value changing function: ShowAmountSliderPositive_7
        function ShowAmountSliderPositive_7ValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderPositive_7.Value = changingValue;
            app.RightSliderPositive_7.Value = changingValue;
            app.ShowAmountSliderPositive_7.Tooltip = sprintf('%d%%', round(changingValue));
            app.LeftSliderPositive_7.Tooltip = sprintf('%d%%', round(changingValue));
            app.RightSliderPositive_7.Tooltip = sprintf('%d%%', round(changingValue));
            app.tractset.subscore.vis.showposamount(6,:) = [app.RightSliderPositive_7.Value, app.LeftSliderPositive_7.Value];
        end

        % Value changed function: LeftSliderPositive_7
        function LeftSliderPositive_7ValueChanged(app, event)
            value = app.LeftSliderPositive_7.Value;
            app.tractset.subscore.vis.showposamount(6,:) = [app.RightSliderPositive_7.Value, app.LeftSliderPositive_7.Value];
            app.LeftSliderPositive_7.Tooltip = sprintf('%d%%', round(app.LeftSliderPositive_7.Value));
            app.draw;

        end

        % Value changed function: RightSliderPositive_7
        function RightSliderPositive_7ValueChanged(app, event)
            value = app.RightSliderPositive_7.Value;
            app.tractset.subscore.vis.showposamount(6,:) = [app.RightSliderPositive_7.Value, app.LeftSliderPositive_7.Value];
            app.RightSliderPositive_7.Tooltip = sprintf('%d%%', round(app.RightSliderPositive_7.Value));
            app.draw;
        end

        % Value changed function: ShowNegativeFibersCheckBox_7
        function ShowNegativeFibersCheckBox_7ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_7.Value;
            app.tractset.subscore.negvisible(6) = value;
            if ~value
                %                 app.ShowAmountSliderNegative_7.Enable = 'off';
                %                 app.ShowAmountSlider_2Label_7.Enable = 'off';
                %                 app.LeftSliderNegative_7.Enable = 'off';
                %                 app.RightSliderNegative_7.Enable = 'off';
                %                 app.ColorButtonNegative_7.Enable = 'off';
                %                 app.LeftSlider_2Label_7.Enable = 'off';
                %                 app.RightSlider_2Label_7.Enable = 'off';
                app.draw;
            else
                app.ShowAmountSliderNegative_7.Enable = 'on';
                app.ShowAmountSlider_2Label_7.Enable = 'on';
                app.LeftSliderNegative_7.Enable = 'on';
                app.RightSliderNegative_7.Enable = 'on';
                app.ColorButtonNegative_7.Enable = 'on';
                app.LeftSlider_2Label_7.Enable = 'on';
                app.RightSlider_2Label_7.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowAmountSliderNegative_7
        function ShowAmountSliderNegative_7ValueChanged(app, event)
            value = app.ShowAmountSliderNegative_7.Value;
            app.LeftSliderNegative_7.Value = value;
            app.RightSliderNegative_7.Value = value;
            app.ShowAmountSliderNegative_7.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderNegative_7.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderNegative_7.Tooltip = sprintf('%d%%', round(value));
            app.tractset.subscore.vis.shownegamount(6,:) = [app.RightSliderNegative_7.Value, app.LeftSliderNegative_7.Value];
            app.draw;
        end

        % Value changing function: ShowAmountSliderNegative_7
        function ShowAmountSliderNegative_7ValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderNegative_7.Value = changingValue;
            app.RightSliderNegative_7.Value = changingValue;
            app.ShowAmountSliderNegative_7.Tooltip = sprintf('%d%%', round(changingValue));
            app.LeftSliderNegative_7.Tooltip = sprintf('%d%%', round(changingValue));
            app.RightSliderNegative_7.Tooltip = sprintf('%d%%', round(changingValue));
            app.tractset.subscore.vis.shownegamount(6,:) = [app.RightSliderNegative_7.Value, app.LeftSliderNegative_7.Value];
        end

        % Value changed function: LeftSliderNegative_7
        function LeftSliderNegative_7ValueChanged(app, event)
            value = app.LeftSliderNegative_7.Value;
            app.tractset.subscore.vis.shownegamount(6,:) = [app.RightSliderNegative_7.Value, app.LeftSliderNegative_7.Value];
            app.LeftSliderNegative_7.Tooltip = sprintf('%d%%', round(app.LeftSliderNegative_7.Value));
            app.draw;
        end

        % Value changed function: RightSliderNegative_7
        function RightSliderNegative_7ValueChanged(app, event)
            value = app.RightSliderNegative_7.Value;
            app.tractset.subscore.vis.shownegamount(6,:) = [app.RightSliderNegative_7.Value, app.LeftSliderNegative_7.Value];
            app.RightSliderNegative_7.Tooltip = sprintf('%d%%', round(app.RightSliderNegative_7.Value));
            app.draw;
        end

        % Value changed function: ShowPositiveFibersCheckBox_8
        function ShowPositiveFibersCheckBox_8ValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox_8.Value;
            app.tractset.subscore.posvisible(7) = value;

            if ~value
                %                 app.ShowAmountSliderPositive_8.Enable = 'off';
                %                 app.ShowAmountSliderLabel_8.Enable = 'off';
                %                 app.LeftSliderPositive_8.Enable = 'off';
                %                 app.RightSliderPositive_8.Enable = 'off';
                %                 app.ColorButtonPositive_8.Enable = 'off';
                %                 app.LeftSliderLabel_8.Enable = 'off';
                %                 app.RightSliderLabel_8.Enable = 'off';
                app.draw;
            else
                app.ShowAmountSliderPositive_8.Enable = 'on';
                app.ShowAmountSliderLabel_8.Enable = 'on';
                app.LeftSliderPositive_8.Enable = 'on';
                app.RightSliderPositive_8.Enable = 'on';
                app.ColorButtonPositive_8.Enable = 'on';
                app.LeftSliderLabel_8.Enable = 'on';
                app.RightSliderLabel_8.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowAmountSliderPositive_8
        function ShowAmountSliderPositive_8ValueChanged(app, event)
            value = app.ShowAmountSliderPositive_8.Value;
            app.LeftSliderPositive_8.Value = value;
            app.RightSliderPositive_8.Value = value;
            app.ShowAmountSliderPositive_8.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderPositive_8.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderPositive_8.Tooltip = sprintf('%d%%', round(value));
            app.tractset.subscore.vis.showposamount(7,:) = [app.RightSliderPositive_8.Value, app.LeftSliderPositive_8.Value];
            app.draw;

        end

        % Value changing function: ShowAmountSliderPositive_8
        function ShowAmountSliderPositive_8ValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderPositive_8.Value = changingValue;
            app.RightSliderPositive_8.Value = changingValue;
            app.ShowAmountSliderPositive_8.Tooltip = sprintf('%d%%', round(changingValue));
            app.LeftSliderPositive_8.Tooltip = sprintf('%d%%', round(changingValue));
            app.RightSliderPositive_8.Tooltip = sprintf('%d%%', round(changingValue));
            app.tractset.subscore.vis.showposamount(7,:) = [app.RightSliderPositive_8.Value, app.LeftSliderPositive_8.Value];
        end

        % Value changed function: LeftSliderPositive_8
        function LeftSliderPositive_8ValueChanged(app, event)
            value = app.LeftSliderPositive_8.Value;
            app.tractset.subscore.vis.showposamount(7,:) = [app.RightSliderPositive_8.Value, app.LeftSliderPositive_8.Value];
            app.LeftSliderPositive_8.Tooltip = sprintf('%d%%', round(app.LeftSliderPositive_8.Value));
            app.draw;
        end

        % Value changed function: RightSliderPositive_8
        function RightSliderPositive_8ValueChanged(app, event)
            value = app.RightSliderPositive_8.Value;
            app.tractset.subscore.vis.showposamount(7,:) = [app.RightSliderPositive_8.Value, app.LeftSliderPositive_8.Value];
            app.RightSliderPositive_8.Tooltip = sprintf('%d%%', round(app.RightSliderPositive_8.Value));
            app.draw;
        end

        % Value changed function: ShowNegativeFibersCheckBox_8
        function ShowNegativeFibersCheckBox_8ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_8.Value;
            app.tractset.subscore.negvisible(7) = value;
            if ~value
                %                 app.ShowAmountSliderNegative_8.Enable = 'off';
                %                 app.ShowAmountSlider_2Label_8.Enable = 'off';
                %                 app.LeftSliderNegative_8.Enable = 'off';
                %                 app.RightSliderNegative_8.Enable = 'off';
                %                 app.ColorButtonNegative_8.Enable = 'off';
                %                 app.LeftSlider_2Label_8.Enable = 'off';
                %                 app.RightSlider_2Label_8.Enable = 'off';
                app.draw;
            else
                app.ShowAmountSliderNegative_8.Enable = 'on';
                app.ShowAmountSlider_2Label_8.Enable = 'on';
                app.LeftSliderNegative_8.Enable = 'on';
                app.RightSliderNegative_8.Enable = 'on';
                app.ColorButtonNegative_8.Enable = 'on';
                app.LeftSlider_2Label_8.Enable = 'on';
                app.RightSlider_2Label_8.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowAmountSliderNegative_8
        function ShowAmountSliderNegative_8ValueChanged(app, event)
            value = app.ShowAmountSliderNegative_8.Value;
            app.LeftSliderNegative_8.Value = value;
            app.RightSliderNegative_8.Value = value;
            app.ShowAmountSliderNegative_8.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderNegative_8.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderNegative_8.Tooltip = sprintf('%d%%', round(value));
            app.tractset.subscore.vis.shownegamount(7,:) = [app.RightSliderNegative_8.Value, app.LeftSliderNegative_8.Value];
            app.draw;
        end

        % Value changing function: ShowAmountSliderNegative_8
        function ShowAmountSliderNegative_8ValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderNegative_8.Value = changingValue;
            app.RightSliderNegative_8.Value = changingValue;
            app.ShowAmountSliderNegative_8.Tooltip = sprintf('%d%%', round(changingValue));
            app.LeftSliderNegative_8.Tooltip = sprintf('%d%%', round(changingValue));
            app.RightSliderNegative_8.Tooltip = sprintf('%d%%', round(changingValue));
            app.tractset.subscore.vis.shownegamount(7,:) = [app.RightSliderNegative_8.Value, app.LeftSliderNegative_8.Value];
        end

        % Value changed function: LeftSliderNegative_8
        function LeftSliderNegative_8ValueChanged(app, event)
            value = app.LeftSliderNegative_8.Value;
            app.tractset.subscore.vis.shownegamount(7,:) = [app.RightSliderNegative_8.Value, app.LeftSliderNegative_8.Value];
            app.LeftSliderNegative_8.Tooltip = sprintf('%d%%', round(app.LeftSliderNegative_8.Value));
            app.draw;
        end

        % Value changed function: RightSliderNegative_8
        function RightSliderNegative_8ValueChanged(app, event)
            value = app.RightSliderNegative_8.Value;
            app.tractset.subscore.vis.shownegamount(7,:) = [app.RightSliderNegative_8.Value, app.LeftSliderNegative_8.Value];
            app.RightSliderNegative_8.Tooltip = sprintf('%d%%', round(app.RightSliderNegative_8.Value));
            app.draw;
        end

        % Value changed function: ShowPositiveFibersCheckBox_9
        function ShowPositiveFibersCheckBox_9ValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox_9.Value;
            app.tractset.subscore.posvisible(8) = value;

            if ~value
                %                 app.ShowAmountSliderPositive_9.Enable = 'off';
                %                 app.ShowAmountSliderLabel_9.Enable = 'off';
                %                 app.LeftSliderPositive_9.Enable = 'off';
                %                 app.RightSliderPositive_9.Enable = 'off';
                %                 app.ColorButtonPositive_9.Enable = 'off';
                %                 app.LeftSliderLabel_9.Enable = 'off';
                %                 app.RightSliderLabel_9.Enable = 'off';
                app.draw;
            else
                app.ShowAmountSliderPositive_9.Enable = 'on';
                app.ShowAmountSliderLabel_9.Enable = 'on';
                app.LeftSliderPositive_9.Enable = 'on';
                app.RightSliderPositive_9.Enable = 'on';
                app.ColorButtonPositive_9.Enable = 'on';
                app.LeftSliderLabel_9.Enable = 'on';
                app.RightSliderLabel_9.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowAmountSliderPositive_9
        function ShowAmountSliderPositive_9ValueChanged(app, event)
            value = app.ShowAmountSliderPositive_9.Value;
            app.LeftSliderPositive_9.Value = value;
            app.RightSliderPositive_9.Value = value;
            app.ShowAmountSliderPositive_9.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderPositive_9.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderPositive_9.Tooltip = sprintf('%d%%', round(value));
            app.tractset.subscore.vis.showposamount(8,:) = [app.RightSliderPositive_9.Value, app.LeftSliderPositive_9.Value];
            app.draw;
        end

        % Value changing function: ShowAmountSliderPositive_9
        function ShowAmountSliderPositive_9ValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderPositive_9.Value = changingValue;
            app.RightSliderPositive_9.Value = changingValue;
            app.ShowAmountSliderPositive_9.Tooltip = sprintf('%d%%', round(changingValue));
            app.LeftSliderPositive_9.Tooltip = sprintf('%d%%', round(changingValue));
            app.RightSliderPositive_9.Tooltip = sprintf('%d%%', round(changingValue));
            app.tractset.subscore.vis.showposamount(8,:) = [app.RightSliderPositive_9.Value, app.LeftSliderPositive_9.Value];
        end

        % Value changed function: LeftSliderPositive_9
        function LeftSliderPositive_9ValueChanged(app, event)
            value = app.LeftSliderPositive_9.Value;
            app.tractset.subscore.vis.showposamount(8,:) = [app.RightSliderPositive_9.Value, app.LeftSliderPositive_9.Value];
            app.LeftSliderPositive_9.Tooltip = sprintf('%d%%', round(app.LeftSliderPositive_9.Value));
            app.draw;
        end

        % Value changed function: RightSliderPositive_9
        function RightSliderPositive_9ValueChanged(app, event)
            value = app.RightSliderPositive_9.Value;
            app.tractset.subscore.vis.showposamount(8,:) = [app.RightSliderPositive_9.Value, app.LeftSliderPositive_9.Value];
            app.RightSliderPositive_9.Tooltip = sprintf('%d%%', round(app.RightSliderPositive_9.Value));
            app.draw;
        end

        % Value changed function: ShowNegativeFibersCheckBox_9
        function ShowNegativeFibersCheckBox_9ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_9.Value;
            app.tractset.subscore.negvisible(8) = value;
            if ~value
                %                 app.ShowAmountSliderNegative_9.Enable = 'off';
                %                 app.ShowAmountSlider_2Label_9.Enable = 'off';
                %                 app.LeftSliderNegative_9.Enable = 'off';
                %                 app.RightSliderNegative_9.Enable = 'off';
                %                 app.ColorButtonNegative_9.Enable = 'off';
                %                 app.LeftSlider_2Label_9.Enable = 'off';
                %                 app.RightSlider_2Label_9.Enable = 'off';
                app.draw;
            else
                app.ShowAmountSliderNegative_9.Enable = 'on';
                app.ShowAmountSlider_2Label_9.Enable = 'on';
                app.LeftSliderNegative_9.Enable = 'on';
                app.RightSliderNegative_9.Enable = 'on';
                app.ColorButtonNegative_9.Enable = 'on';
                app.LeftSlider_2Label_9.Enable = 'on';
                app.RightSlider_2Label_9.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowAmountSliderNegative_9
        function ShowAmountSliderNegative_9ValueChanged(app, event)
            value = app.ShowAmountSliderNegative_9.Value;
            app.LeftSliderNegative_9.Value = value;
            app.RightSliderNegative_9.Value = value;
            app.ShowAmountSliderNegative_9.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderNegative_9.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderNegative_9.Tooltip = sprintf('%d%%', round(value));
            app.tractset.subscore.vis.shownegamount(8,:) = [app.RightSliderNegative_9.Value, app.LeftSliderNegative_9.Value];
            app.draw;
        end

        % Value changing function: ShowAmountSliderNegative_9
        function ShowAmountSliderNegative_9ValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderNegative_9.Value = changingValue;
            app.RightSliderNegative_9.Value = changingValue;
            app.ShowAmountSliderNegative_9.Tooltip = sprintf('%d%%', round(changingValue));
            app.LeftSliderNegative_9.Tooltip = sprintf('%d%%', round(changingValue));
            app.RightSliderNegative_9.Tooltip = sprintf('%d%%', round(changingValue));
            app.tractset.subscore.vis.shownegamount(8,:) = [app.RightSliderNegative_9.Value, app.LeftSliderNegative_9.Value];
        end

        % Value changed function: LeftSliderNegative_9
        function LeftSliderNegative_9ValueChanged(app, event)
            value = app.LeftSliderNegative_9.Value;
            app.tractset.subscore.vis.shownegamount(8,:) = [app.RightSliderNegative_9.Value, app.LeftSliderNegative_9.Value];
            app.LeftSliderNegative_9.Tooltip = sprintf('%d%%', round(app.LeftSliderNegative_9.Value));
            app.draw;
        end

        % Value changed function: RightSliderNegative_9
        function RightSliderNegative_9ValueChanged(app, event)
            value = app.RightSliderNegative_9.Value;
            app.tractset.subscore.vis.shownegamount(8,:) = [app.RightSliderNegative_9.Value, app.LeftSliderNegative_9.Value];
            app.RightSliderNegative_9.Tooltip = sprintf('%d%%', round(app.RightSliderNegative_9.Value));
            app.draw;
        end

        % Value changed function: ShowPositiveFibersCheckBox_10
        function ShowPositiveFibersCheckBox_10ValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox_10.Value;

            app.tractset.subscore.posvisible(9) = value;

            if ~value
                %                 app.ShowAmountSliderPositive_10.Enable = 'off';
                %                 app.ShowAmountSliderLabel_10.Enable = 'off';
                %                 app.LeftSliderPositive_10.Enable = 'off';
                %                 app.RightSliderPositive_10.Enable = 'off';
                %                 app.ColorButtonPositive_10.Enable = 'off';
                %                 app.LeftSliderLabel_10.Enable = 'off';
                %                 app.RightSliderLabel_10.Enable = 'off';
                app.draw;
            else
                app.ShowAmountSliderPositive_10.Enable = 'on';
                app.ShowAmountSliderLabel_10.Enable = 'on';
                app.LeftSliderPositive_10.Enable = 'on';
                app.RightSliderPositive_10.Enable = 'on';
                app.ColorButtonPositive_10.Enable = 'on';
                app.LeftSliderLabel_10.Enable = 'on';
                app.RightSliderLabel_10.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowAmountSliderPositive_10
        function ShowAmountSliderPositive_10ValueChanged(app, event)
            value = app.ShowAmountSliderPositive_10.Value;
            app.LeftSliderPositive_10.Value = value;
            app.RightSliderPositive_10.Value = value;
            app.ShowAmountSliderPositive_10.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderPositive_10.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderPositive_10.Tooltip = sprintf('%d%%', round(value));
            app.tractset.subscore.vis.showposamount(9,:) = [app.RightSliderPositive_10.Value, app.LeftSliderPositive_10.Value];
            app.draw;
        end

        % Value changing function: ShowAmountSliderPositive_10
        function ShowAmountSliderPositive_10ValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderPositive_10.Value = changingValue;
            app.RightSliderPositive_10.Value = changingValue;
            app.ShowAmountSliderPositive_10.Tooltip = sprintf('%d%%', round(changingValue));
            app.LeftSliderPositive_10.Tooltip = sprintf('%d%%', round(changingValue));
            app.RightSliderPositive_10.Tooltip = sprintf('%d%%', round(changingValue));
            app.tractset.subscore.vis.showposamount(9,:) = [app.RightSliderPositive_10.Value, app.LeftSliderPositive_10.Value];
        end

        % Value changed function: LeftSliderPositive_10
        function LeftSliderPositive_10ValueChanged(app, event)
            value = app.LeftSliderPositive_10.Value;
            app.tractset.subscore.vis.showposamount(9,:) = [app.RightSliderPositive_10.Value, app.LeftSliderPositive_10.Value];
            app.LeftSliderPositive_10.Tooltip = sprintf('%d%%', round(app.LeftSliderPositive_10.Value));
            app.draw;
        end

        % Value changed function: RightSliderPositive_10
        function RightSliderPositive_10ValueChanged(app, event)
            value = app.RightSliderPositive_10.Value;
            app.tractset.subscore.vis.showposamount(9,:) = [app.RightSliderPositive_10.Value, app.LeftSliderPositive_10.Value];
            app.RightSliderPositive_10.Tooltip = sprintf('%d%%', round(app.RightSliderPositive_10.Value));
            app.draw;
        end

        % Value changed function: ShowNegativeFibersCheckBox_10
        function ShowNegativeFibersCheckBox_10ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_10.Value;
            app.tractset.subscore.negvisible(9) = value;
            if ~value
                %                 app.ShowAmountSliderNegative_10.Enable = 'off';
                %                 app.ShowAmountSlider_2Label_10.Enable = 'off';
                %                 app.LeftSliderNegative_10.Enable = 'off';
                %                 app.RightSliderNegative_10.Enable = 'off';
                %                 app.ColorButtonNegative_10.Enable = 'off';
                %                 app.LeftSlider_2Label_10.Enable = 'off';
                %                 app.RightSlider_2Label_10.Enable = 'off';
                app.draw;
            else
                app.ShowAmountSliderNegative_10.Enable = 'on';
                app.ShowAmountSlider_2Label_10.Enable = 'on';
                app.LeftSliderNegative_10.Enable = 'on';
                app.RightSliderNegative_10.Enable = 'on';
                app.ColorButtonNegative_10.Enable = 'on';
                app.LeftSlider_2Label_10.Enable = 'on';
                app.RightSlider_2Label_10.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowAmountSliderNegative_10
        function ShowAmountSliderNegative_10ValueChanged(app, event)
            value = app.ShowAmountSliderNegative_10.Value;
            app.LeftSliderNegative_10.Value = value;
            app.RightSliderNegative_10.Value = value;
            app.ShowAmountSliderNegative_10.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderNegative_10.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderNegative_10.Tooltip = sprintf('%d%%', round(value));
            app.tractset.subscore.vis.shownegamount(9,:) = [app.RightSliderNegative_10.Value, app.LeftSliderNegative_10.Value];
            app.draw;

        end

        % Value changing function: ShowAmountSliderNegative_10
        function ShowAmountSliderNegative_10ValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderNegative_10.Value = changingValue;
            app.RightSliderNegative_10.Value = changingValue;
            app.ShowAmountSliderNegative_10.Tooltip = sprintf('%d%%', round(changingValue));
            app.LeftSliderNegative_10.Tooltip = sprintf('%d%%', round(changingValue));
            app.RightSliderNegative_10.Tooltip = sprintf('%d%%', round(changingValue));
            app.tractset.subscore.vis.shownegamount(9,:) = [app.RightSliderNegative_10.Value, app.LeftSliderNegative_10.Value];
        end

        % Value changed function: LeftSliderNegative_10
        function LeftSliderNegative_10ValueChanged(app, event)
            value = app.LeftSliderNegative_10.Value;
            app.tractset.subscore.vis.shownegamount(9,:) = [app.RightSliderNegative_10.Value, app.LeftSliderNegative_10.Value];
            app.LeftSliderNegative_10.Tooltip = sprintf('%d%%', round(app.LeftSliderNegative_10.Value));
            app.draw;
        end

        % Value changed function: RightSliderNegative_10
        function RightSliderNegative_10ValueChanged(app, event)
            value = app.RightSliderNegative_10.Value;
            app.tractset.subscore.vis.shownegamount(9,:) = [app.RightSliderNegative_10.Value, app.LeftSliderNegative_10.Value];
            app.RightSliderNegative_10.Tooltip = sprintf('%d%%', round(app.RightSliderNegative_10.Value));
            app.draw;
        end

        % Value changed function: ShowPositiveFibersCheckBox_11
        function ShowPositiveFibersCheckBox_11ValueChanged(app, event)

            value = app.ShowPositiveFibersCheckBox_11.Value;

            app.tractset.subscore.posvisible(10) = value;

            if ~value
                %                 app.ShowAmountSliderPositive_11.Enable = 'off';
                %                 app.ShowAmountSliderLabel_11.Enable = 'off';
                %                 app.LeftSliderPositive_11.Enable = 'off';
                %                 app.RightSliderPositive_11.Enable = 'off';
                %                 app.ColorButtonPositive_11.Enable = 'off';
                %                 app.LeftSliderLabel_11.Enable = 'off';
                %                 app.RightSliderLabel_11.Enable = 'off';
                app.draw;
            else
                app.ShowAmountSliderPositive_11.Enable = 'on';
                app.ShowAmountSliderLabel_11.Enable = 'on';
                app.LeftSliderPositive_11.Enable = 'on';
                app.RightSliderPositive_11.Enable = 'on';
                app.ColorButtonPositive_11.Enable = 'on';
                app.LeftSliderLabel_11.Enable = 'on';
                app.RightSliderLabel_11.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowAmountSliderPositive_11
        function ShowAmountSliderPositive_11ValueChanged(app, event)
            value = app.ShowAmountSliderPositive_11.Value;
            app.LeftSliderPositive_11.Value = value;
            app.RightSliderPositive_11.Value = value;
            app.ShowAmountSliderPositive_11.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderPositive_11.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderPositive_11.Tooltip = sprintf('%d%%', round(value));
            app.tractset.subscore.vis.showposamount(10,:) = [app.RightSliderPositive_11.Value, app.LeftSliderPositive_11.Value];
            app.draw;
        end

        % Value changing function: ShowAmountSliderPositive_11
        function ShowAmountSliderPositive_11ValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderPositive_11.Value = changingValue;
            app.RightSliderPositive_11.Value = changingValue;
            app.ShowAmountSliderPositive_11.Tooltip = sprintf('%d%%', round(changingValue));
            app.LeftSliderPositive_11.Tooltip = sprintf('%d%%', round(changingValue));
            app.RightSliderPositive_11.Tooltip = sprintf('%d%%', round(changingValue));
            app.tractset.subscore.vis.showposamount(10,:) = [app.RightSliderPositive_11.Value, app.LeftSliderPositive_11.Value];
        end

        % Value changed function: LeftSliderPositive_11
        function LeftSliderPositive_11ValueChanged(app, event)
            value = app.LeftSliderPositive_11.Value;
            app.tractset.subscore.vis.showposamount(10,:) = [app.RightSliderPositive_11.Value, app.LeftSliderPositive_11.Value];
            app.LeftSliderPositive_11.Tooltip = sprintf('%d%%', round(app.LeftSliderPositive_11.Value));
            app.draw;

        end

        % Value changed function: RightSliderPositive_11
        function RightSliderPositive_11ValueChanged(app, event)
            value = app.RightSliderPositive_11.Value;
            app.tractset.subscore.vis.showposamount(10,:) = [app.RightSliderPositive_11.Value, app.LeftSliderPositive_11.Value];
            app.RightSliderPositive_11.Tooltip = sprintf('%d%%', round(app.RightSliderPositive_11.Value));
            app.draw;
        end

        % Value changed function: ShowNegativeFibersCheckBox_11
        function ShowNegativeFibersCheckBox_11ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_11.Value;
            app.tractset.subscore.negvisible(10) = value;
            if ~value
                %                 app.ShowAmountSliderNegative_11.Enable = 'off';
                %                 app.ShowAmountSlider_2Label_11.Enable = 'off';
                %                 app.LeftSliderNegative_11.Enable = 'off';
                %                 app.RightSliderNegative_11.Enable = 'off';
                %                 app.ColorButtonNegative_11.Enable = 'off';
                %                 app.LeftSlider_2Label_11.Enable = 'off';
                %                 app.RightSlider_2Label_11.Enable = 'off';
                app.draw;
            else
                app.ShowAmountSliderNegative_11.Enable = 'on';
                app.ShowAmountSlider_2Label_11.Enable = 'on';
                app.LeftSliderNegative_11.Enable = 'on';
                app.RightSliderNegative_11.Enable = 'on';
                app.ColorButtonNegative_11.Enable = 'on';
                app.LeftSlider_2Label_11.Enable = 'on';
                app.RightSlider_2Label_11.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowAmountSliderNegative_11
        function ShowAmountSliderNegative_11ValueChanged(app, event)
            value = app.ShowAmountSliderNegative_11.Value;
            app.LeftSliderNegative_11.Value = value;
            app.RightSliderNegative_11.Value = value;
            app.ShowAmountSliderNegative_11.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderNegative_11.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderNegative_11.Tooltip = sprintf('%d%%', round(value));
            app.tractset.subscore.vis.shownegamount(10,:) = [app.RightSliderNegative_11.Value, app.LeftSliderNegative_11.Value];
            app.draw;
        end

        % Value changing function: ShowAmountSliderNegative_11
        function ShowAmountSliderNegative_11ValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderNegative_11.Value = changingValue;
            app.RightSliderNegative_11.Value = changingValue;
            app.ShowAmountSliderNegative_11.Tooltip = sprintf('%d%%', round(changingValue));
            app.LeftSliderNegative_11.Tooltip = sprintf('%d%%', round(changingValue));
            app.RightSliderNegative_11.Tooltip = sprintf('%d%%', round(changingValue));
            app.tractset.subscore.vis.shownegamount(10,:) = [app.RightSliderNegative_11.Value, app.LeftSliderNegative_11.Value];
        end

        % Value changed function: LeftSliderNegative_11
        function LeftSliderNegative_11ValueChanged(app, event)
            value = app.LeftSliderNegative_11.Value;
            app.tractset.subscore.vis.shownegamount(10,:) = [app.RightSliderNegative_11.Value, app.LeftSliderNegative_11.Value];
            app.LeftSliderNegative_11.Tooltip = sprintf('%d%%', round(app.LeftSliderNegative_11.Value));
            app.draw;
        end

        % Value changed function: RightSliderNegative_11
        function RightSliderNegative_11ValueChanged(app, event)
            value = app.RightSliderNegative_11.Value;
            app.tractset.subscore.vis.shownegamount(10,:) = [app.RightSliderNegative_11.Value, app.LeftSliderNegative_11.Value];
            app.RightSliderNegative_11.Tooltip = sprintf('%d%%', round(app.RightSliderNegative_11.Value));
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_3
        function ColorButtonPositive_3Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_3.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,2}(2,:) = app.ColorButtonPositive_3.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(2,:) = app.ColorButtonPositive_3.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.groupcolors(2,:) = app.ColorButtonPositive_3.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_3
        function ColorButtonNegative_3Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_3.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,1}(2,:) = app.ColorButtonNegative_3.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(2,:) = app.ColorButtonNegative_3.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.groupcolors(2,:) = app.ColorButtonNegative_3.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_4
        function ColorButtonPositive_4Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_4.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,2}(3,:) = app.ColorButtonPositive_4.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(3,:) = app.ColorButtonPositive_4.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.groupcolors(3,:) = app.ColorButtonPositive_4.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_5
        function ColorButtonPositive_5Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_5.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,2}(4,:) = app.ColorButtonPositive_5.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(4,:) = app.ColorButtonPositive_5.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.groupcolors(4,:) = app.ColorButtonPositive_5.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_6
        function ColorButtonPositive_6Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_6.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,2}(5,:) = app.ColorButtonPositive_6.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(5,:) = app.ColorButtonPositive_6.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.groupcolors(5,:) = app.ColorButtonPositive_6.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_7
        function ColorButtonPositive_7Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_7.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,2}(6,:) = app.ColorButtonPositive_7.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(6,:) = app.ColorButtonPositive_7.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.groupcolors(6,:) = app.ColorButtonPositive_7.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_8
        function ColorButtonPositive_8Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_8.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,2}(7,:) = app.ColorButtonPositive_8.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(7,:) = app.ColorButtonPositive_8.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.groupcolors(7,:) = app.ColorButtonPositive_8.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_9
        function ColorButtonPositive_9Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_9.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,2}(8,:) = app.ColorButtonPositive_9.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(8,:) = app.ColorButtonPositive_9.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.groupcolors(8,:) = app.ColorButtonPositive_9.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_10
        function ColorButtonPositive_10Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_10.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,2}(9,:) = app.ColorButtonPositive_10.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(9,:) = app.ColorButtonPositive_10.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.groupcolors(9,:) = app.ColorButtonPositive_10.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_11
        function ColorButtonPositive_11Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_11.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,2}(10,:) = app.ColorButtonPositive_11.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(10,:) = app.ColorButtonPositive_11.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.groupcolors(10,:) = app.ColorButtonPositive_11.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_11
        function ColorButtonNegative_11Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_11.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,1}(10,:) = app.ColorButtonNegative_11.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(10,:) = app.ColorButtonNegative_11.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.groupcolors(10,:) = app.ColorButtonNegative_11.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_10
        function ColorButtonNegative_10Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_10.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,1}(9,:) = app.ColorButtonNegative_10.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(9,:) = app.ColorButtonNegative_10.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.groupcolors(9,:) = app.ColorButtonNegative_10.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_9
        function ColorButtonNegative_9Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_9.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,1}(8,:) = app.ColorButtonNegative_9.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(8,:) = app.ColorButtonNegative_9.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.groupcolors(8,:) = app.ColorButtonNegative_9.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_8
        function ColorButtonNegative_8Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_8.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,1}(7,:) = app.ColorButtonNegative_8.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(7,:) = app.ColorButtonNegative_8.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.groupcolors(7,:) = app.ColorButtonNegative_8.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_7
        function ColorButtonNegative_7Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_7.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,1}(6,:) = app.ColorButtonNegative_7.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(6,:) = app.ColorButtonNegative_7.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.groupcolors(6,:) = app.ColorButtonNegative_7.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_6
        function ColorButtonNegative_6Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_6.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,1}(5,:) = app.ColorButtonNegative_6.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(5,:) = app.ColorButtonNegative_6.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.groupcolors(5,:) = app.ColorButtonNegative_6.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_5
        function ColorButtonNegative_5Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_5.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,1}(4,:) = app.ColorButtonNegative_5.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(4,:) = app.ColorButtonNegative_5.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.groupcolors(4,:) = app.ColorButtonNegative_5.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_4
        function ColorButtonNegative_4Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_4.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,1}(3,:) = app.ColorButtonNegative_4.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(3,:) = app.ColorButtonNegative_4.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.groupcolors(3,:) = app.ColorButtonNegative_4.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: OpeninCleartuneButton
        function OpeninCleartuneButtonPushed(app, event)
            config.fibfiltmodelpath=fullfile(fileparts(app.tractset.leadgroup),'fiberfiltering',[app.tractset.ID,'.fibfilt']);
            config.tractset=app.tractset;
            config.resultfig=app.tractset.resultfig;
            ea_cleartune(config)
        end

        % Callback function
        function ConnectomeDropDownOpening(app, event)
            [mdl,sf]=ea_genmodlist;
            dmri_connectomenames = mdl(sf==1);
            dmri_mtconnectomenames = mdl(sf==3);
            app.ConnectomeDropDown.Items = [dmri_connectomenames,dmri_mtconnectomenames];
        end

        % Drop down opening function: ConnectomeDropDown
        function ConnectomeDropDownOpening2(app, event)
            [mdl,sf]=ea_genmodlist;
            dmri_connectomenames = mdl(sf==1);
            dmri_mtconnectomenames = mdl(sf==3);
            app.ConnectomeDropDown.Items = [dmri_connectomenames,dmri_mtconnectomenames];
        end

        % Value changed function: NestedCheckBox
        function NestedCheckBoxValueChanged(app, event)
            value = app.NestedCheckBox.Value;
            if app.NestedCheckBox.Value
                app.tractset.nestedLOO = true;
            else
                app.tractset.nestedLOO = false;
            end
        end

        % Value changed function: IterationsEditField
        function IterationsEditFieldValueChanged(app, event)
            value = app.IterationsEditField.Value;
            app.tractset.kIter = app.IterationsEditField.Value;
        end

        % Selection changed function: ConnectivityModelButtonGroup
        function ConnectivityModelButtonGroupSelectionChanged(app, event)
            selectedButton = app.ConnectivityModelButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Stimulation Volume')
                app.ModelSetupDropDown.Items = {'T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)', 'Correlations / E-fields (Irmen 2020)', 'Dice Coeffs / VTAs (binary vars)', 'Reverse T-Tests / E-Fields (binary vars)', 'Plain Connections'};
                app.tractset.switch_connectivity = 1;
                app.tractset.connectivity_type = 1;
                app.tractset.statmetric = 1;
            elseif strcmp(selectedButton.Text,'PAM')
                app.ModelSetupDropDown.Items = {'T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)','Dice Coeffs / VTAs (binary vars)', 'Reverse T-Tests / E-Fields (binary vars)', 'Plain Connections'};
                if strcmp(app.ModelSetupDropDown.Value,'Correlations / E-fields (Irmen 2020)')
                    app.ModelSetupDropDown.Value = 'T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)';
                end
                app.tractset.connectivity_type = 2;
                app.tractset.statmetric = 3;
                app.EfieldthresholdSlider.Enable = 'off';
                app.EfieldthresholdLabel.Enable = 'off';
                app.Efieldmetric.Enable = 'off';
                app.Corrtype.Enable = 'off';
                app.tractset.switch_connectivity = 1;
                app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Histogram','z-scored Histogram'};
                if ~ismember(app.tractset.basepredictionon,lower(app.BasePredictiononDropDown.Items))
                    app.tractset.basepredictionon=lower(app.BasePredictiononDropDown.Value);
                end
            end
            
            
            %%% change to connectivity type 
            %1 = vta, 2= pam
        end

        % Drop down opening function: ModelSetupDropDown
        function ModelSetupDropDownOpening(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.RefreshInformationfromLeadGroupprojectMenu = uimenu(app.ToolsMenu);
            app.RefreshInformationfromLeadGroupprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshInformationfromLeadGroupprojectMenuSelected, true);
            app.RefreshInformationfromLeadGroupprojectMenu.Text = 'Refresh Information from Lead-Group project';
            app.SetseedforrandomnumbergeneratorMenu = uimenu(app.ToolsMenu);
            app.SetseedforrandomnumbergeneratorMenu.MenuSelectedFcn = createCallbackFcn(app, @SetseedforrandomnumbergeneratorMenuSelected, true);
            app.SetseedforrandomnumbergeneratorMenu.Text = 'Set seed for random number generator';
            app.AnalysisIDEditFieldLabel = uilabel(app.UIFigure);
            app.AnalysisIDEditFieldLabel.Text = 'Analysis ID';
            app.AnalysisIDEditField = uieditfield(app.UIFigure, 'text');
            app.AnalysisIDEditField.ValueChangedFcn = createCallbackFcn(app, @AnalysisIDEditFieldValueChanged, true);
            app.AnalysisIDEditField.ValueChangingFcn = createCallbackFcn(app, @AnalysisIDEditFieldValueChanging, true);
            app.AnalysisIDEditField.Value = 'new_tract_analysis';
            app.LeadGroupPathLabel = uilabel(app.UIFigure);
            app.LeadGroupPathLabel.Text = 'Lead-Group Path';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '<';
            app.ConnectomeSelectionPanel = uipanel(app.UIFigure);
            app.ConnectomeSelectionPanel.Title = 'Connectome Selection';
            app.CalculateButton = uibutton(app.ConnectomeSelectionPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ConnectomeDropDownLabel = uilabel(app.ConnectomeSelectionPanel);
            app.ConnectomeDropDownLabel.Text = 'Connectome';
            app.ConnectomeDropDown = uidropdown(app.ConnectomeSelectionPanel);
            app.ConnectomeDropDown.Items = {'', ''};
            app.ConnectomeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ConnectomeDropDownOpening2, true);
            app.ConnectomeDropDown.ValueChangedFcn = createCallbackFcn(app, @ConnectomeDropDownValueChanged, true);
            app.ConnectomeDropDown.Value = '';
            app.ThresholdEditFieldLabel = uilabel(app.ConnectomeSelectionPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.ConnectomeSelectionPanel, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 200;
            app.VmLabel = uilabel(app.ConnectomeSelectionPanel);
            app.VmLabel.Text = 'V/m';
            app.ConnectivityModelButtonGroup = uibuttongroup(app.ConnectomeSelectionPanel);
            app.ConnectivityModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConnectivityModelButtonGroupSelectionChanged, true);
            app.ConnectivityModelButtonGroup.Title = 'Connectivity Model';
            app.StimulationVolumeButton = uiradiobutton(app.ConnectivityModelButtonGroup);
            app.StimulationVolumeButton.Text = 'Stimulation Volume';
            app.StimulationVolumeButton.Value = true;
            app.PAMButton = uiradiobutton(app.ConnectivityModelButtonGroup);
            app.PAMButton.Text = 'PAM';
            app.InteractiveModelSetupPanel = uipanel(app.UIFigure);
            app.InteractiveModelSetupPanel.Title = 'Interactive Model Setup';
            app.MirrorSidesCheckBox = uicheckbox(app.InteractiveModelSetupPanel);
            app.MirrorSidesCheckBox.ValueChangedFcn = createCallbackFcn(app, @MirrorSidesCheckBoxValueChanged, true);
            app.MirrorSidesCheckBox.Enable = 'off';
            app.MirrorSidesCheckBox.Text = 'Mirror VTAs / Efields';
            app.PatientsListBoxLabel = uilabel(app.InteractiveModelSetupPanel);
            app.PatientsListBoxLabel.Enable = 'off';
            app.PatientsListBoxLabel.Text = 'Patients';
            app.PatientsListBox = uilistbox(app.InteractiveModelSetupPanel);
            app.PatientsListBox.Multiselect = 'on';
            app.PatientsListBox.ValueChangedFcn = createCallbackFcn(app, @PatientsListBoxValueChanged, true);
            app.PatientsListBox.Enable = 'off';
            app.PatientsListBox.Value = {'Item 1'};
            app.VariableofInterestDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.VariableofInterestDropDownLabel.Enable = 'off';
            app.VariableofInterestDropDownLabel.Text = 'Variable of Interest';
            app.VariableofInterestDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.VariableofInterestDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableofInterestDropDownValueChanged, true);
            app.VariableofInterestDropDown.Enable = 'off';
            app.CovariatesListBox = uilistbox(app.InteractiveModelSetupPanel);
            app.CovariatesListBox.Multiselect = 'on';
            app.CovariatesListBox.ValueChangedFcn = createCallbackFcn(app, @CovariatesListBoxValueChanged, true);
            app.CovariatesListBox.Enable = 'off';
            app.CovariatesListBox.Value = {'Item 1'};
            app.ConnthresholdSlider = uislider(app.InteractiveModelSetupPanel);
            app.ConnthresholdSlider.Limits = [0 49];
            app.ConnthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ConnthresholdSliderValueChanged, true);
            app.ConnthresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ConnthresholdSliderValueChanging, true);
            app.ConnthresholdSlider.Enable = 'off';
            app.ConnthresholdSlider.Value = 20;
            app.ConnthresholdLabel = uilabel(app.InteractiveModelSetupPanel);
            app.ConnthresholdLabel.Enable = 'off';
            app.ConnthresholdLabel.Text = {'Tracts should be connected '; 'to at least % of VTAs'};
            app.EfieldthresholdSlider = uislider(app.InteractiveModelSetupPanel);
            app.EfieldthresholdSlider.Limits = [1 40];
            app.EfieldthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @EfieldthresholdSliderValueChanged, true);
            app.EfieldthresholdSlider.ValueChangingFcn = createCallbackFcn(app, @EfieldthresholdSliderValueChanging, true);
            app.EfieldthresholdSlider.Enable = 'off';
            app.EfieldthresholdSlider.Value = 2.5;
            app.EfieldthresholdLabel = uilabel(app.InteractiveModelSetupPanel);
            app.EfieldthresholdLabel.Enable = 'off';
            app.EfieldthresholdLabel.Text = {'Tracts are modulated > '; 'sum E-Field of  V/m'};
            app.CovariatesCheckBox = uicheckbox(app.InteractiveModelSetupPanel);
            app.CovariatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @CovariatesCheckBoxValueChanged, true);
            app.CovariatesCheckBox.Enable = 'off';
            app.CovariatesCheckBox.Text = 'Covariates';
            app.Efieldmetric = uidropdown(app.InteractiveModelSetupPanel);
            app.Efieldmetric.Items = {'Sum', 'Mean', 'Peak', 'Peak 5%'};
            app.Efieldmetric.ValueChangedFcn = createCallbackFcn(app, @EfieldmetricValueChanged, true);
            app.Efieldmetric.Enable = 'off';
            app.Efieldmetric.Value = 'Sum';
            app.ModelSetupDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.ModelSetupDropDownLabel.Enable = 'off';
            app.ModelSetupDropDownLabel.Text = 'Model Setup';
            app.ModelSetupDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.ModelSetupDropDown.Items = {'T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)', 'Correlations / E-fields (Irmen 2020)', 'Dice Coeffs / VTAs (binary vars)', 'Reverse T-Tests / E-Fields (binary vars)', 'Plain Connections'};
            app.ModelSetupDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ModelSetupDropDownOpening, true);
            app.ModelSetupDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelSetupDropDownValueChanged, true);
            app.ModelSetupDropDown.Enable = 'off';
            app.ModelSetupDropDown.Value = 'T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)';
            app.Corrtype = uidropdown(app.InteractiveModelSetupPanel);
            app.Corrtype.Items = {'Spearman', 'Pearson', 'Bend', 'Skipped Spearman', 'Skipped Pearson'};
            app.Corrtype.ValueChangedFcn = createCallbackFcn(app, @CorrtypeValueChanged, true);
            app.Corrtype.Enable = 'off';
            app.Corrtype.Value = 'Spearman';
            app.SubcohortsDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.SubcohortsDropDownLabel.Enable = 'off';
            app.SubcohortsDropDownLabel.Text = 'Subcohorts';
            app.SubcohortsDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.SubcohortsDropDown.Items = {'Choose...', 'All Patients', 'Set from Selection', 'Inverse Set from Selection'};
            app.SubcohortsDropDown.ValueChangedFcn = createCallbackFcn(app, @SubcohortsDropDownValueChanged, true);
            app.SubcohortsDropDown.Enable = 'off';
            app.SubcohortsDropDown.Value = 'Choose...';
            app.BusyLampLabel = uilabel(app.UIFigure);
            app.BusyLampLabel.Text = 'Busy';
            app.BusyLamp = uilamp(app.UIFigure);
            app.VisualizationThresholdsPanel = uipanel(app.UIFigure);
            app.VisualizationThresholdsPanel.Title = 'Visualization & Thresholds';
            app.CrossvalidationPredictionPanel = uipanel(app.UIFigure);
            app.CrossvalidationPredictionPanel.Title = 'Crossvalidation & Prediction';
            app.TrainOnListBoxLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.TrainOnListBoxLabel.Enable = 'off';
            app.TrainOnListBoxLabel.Text = 'Train On';
            app.TrainOnListBox = uilistbox(app.CrossvalidationPredictionPanel);
            app.TrainOnListBox.Multiselect = 'on';
            app.TrainOnListBox.ValueChangedFcn = createCallbackFcn(app, @TrainOnListBoxValueChanged, true);
            app.TrainOnListBox.Enable = 'off';
            app.TrainOnListBox.Value = {'Item 1'};
            app.PredictListBoxLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.PredictListBoxLabel.Enable = 'off';
            app.PredictListBoxLabel.Text = 'Predict';
            app.PredictListBox = uilistbox(app.CrossvalidationPredictionPanel);
            app.PredictListBox.Multiselect = 'on';
            app.PredictListBox.ValueChangedFcn = createCallbackFcn(app, @PredictListBoxValueChanged, true);
            app.PredictListBox.Enable = 'off';
            app.PredictListBox.Value = {'Item 1'};
            app.RunCVButton = uibutton(app.CrossvalidationPredictionPanel, 'push');
            app.RunCVButton.ButtonPushedFcn = createCallbackFcn(app, @RunCVButtonPushed, true);
            app.RunCVButton.Enable = 'off';
            app.RunCVButton.Text = 'Run';
            app.PosthoccorrectforgroupCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.PosthoccorrectforgroupCheckBox.ValueChangedFcn = createCallbackFcn(app, @PosthoccorrectforgroupCheckBoxValueChanged, true);
            app.PosthoccorrectforgroupCheckBox.Enable = 'off';
            app.PosthoccorrectforgroupCheckBox.Text = 'Post-hoc correct for group';
            app.StrategyDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.StrategyDropDownLabel.Enable = 'off';
            app.StrategyDropDownLabel.Text = 'Strategy';
            app.StrategyDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.StrategyDropDown.Items = {'Leave-Nothing-Out (Permutation-Based)', 'Leave-One-Patient-Out', 'Leave-One-Cohort-Out', 'k-fold (randomized)', 'Custom (Patients)', 'Custom (Cohorts)', 'Custom (Sets)', 'Custom (Subcohorts)'};
            app.StrategyDropDown.ValueChangedFcn = createCallbackFcn(app, @StrategyDropDownValueChanged, true);
            app.StrategyDropDown.Enable = 'off';
            app.StrategyDropDown.Value = 'Leave-Nothing-Out (Permutation-Based)';
            app.kEditFieldLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.kEditFieldLabel.Enable = 'off';
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.CrossvalidationPredictionPanel, 'text');
            app.kEditField.ValueChangedFcn = createCallbackFcn(app, @kEditFieldValueChanged, true);
            app.kEditField.Enable = 'off';
            app.LiveVisualizeCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.LiveVisualizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @LiveVisualizeCheckBoxValueChanged, true);
            app.LiveVisualizeCheckBox.Enable = 'off';
            app.LiveVisualizeCheckBox.Text = 'Live Visualize';
            app.BasePredictiononDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.BasePredictiononDropDownLabel.Enable = 'off';
            app.BasePredictiononDropDownLabel.Text = 'Base Prediction on ';
            app.BasePredictiononDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.BasePredictiononDropDown.Items = {'Profile of Scores', 'Sum of Scores', 'Mean of Scores', 'Peak of Scores', 'Peak 5% of Scores', 'Histogram', 'z-scored Histogram'};
            app.BasePredictiononDropDown.ValueChangedFcn = createCallbackFcn(app, @BasePredictiononDropDownValueChanged, true);
            app.BasePredictiononDropDown.Enable = 'off';
            app.BasePredictiononDropDown.Value = 'Profile of Scores';
            app.ModelNormalizationDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.ModelNormalizationDropDownLabel.Enable = 'off';
            app.ModelNormalizationDropDownLabel.Text = 'Model Normalization';
            app.ModelNormalizationDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.ModelNormalizationDropDown.Items = {'None', 'z-score', 'van Albada 2007'};
            app.ModelNormalizationDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelNormalizationDropDownValueChanged, true);
            app.ModelNormalizationDropDown.Enable = 'off';
            app.ModelNormalizationDropDown.Value = 'None';
            app.BinsEditFieldLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.BinsEditFieldLabel.Enable = 'off';
            app.BinsEditFieldLabel.Visible = 'off';
            app.BinsEditFieldLabel.Text = '#Bins';
            app.BinsEditField = uieditfield(app.CrossvalidationPredictionPanel, 'numeric');
            app.BinsEditField.ValueChangedFcn = createCallbackFcn(app, @BinsEditFieldValueChanged, true);
            app.BinsEditField.Enable = 'off';
            app.BinsEditField.Visible = 'off';
            app.BinsEditField.Value = 15;
            app.FittoscoresCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.FittoscoresCheckBox.ValueChangedFcn = createCallbackFcn(app, @FittoscoresCheckBoxValueChanged, true);
            app.FittoscoresCheckBox.Enable = 'off';
            app.FittoscoresCheckBox.Text = 'Fit to scores';
            app.ModeltypeDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.ModeltypeDropDown.Items = {'Constant', 'Linear', 'Quadratic'};
            app.ModeltypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeltypeDropDownValueChanged, true);
            app.ModeltypeDropDown.Enable = 'off';
            app.ModeltypeDropDown.Value = 'Linear';
            app.MultitractWeightsButton = uibutton(app.CrossvalidationPredictionPanel, 'push');
            app.MultitractWeightsButton.ButtonPushedFcn = createCallbackFcn(app, @MultitractWeightsButtonPushed, true);
            app.MultitractWeightsButton.Enable = 'off';
            app.MultitractWeightsButton.Text = 'Multitract Weights...';
            app.OpeninCleartuneButton = uibutton(app.CrossvalidationPredictionPanel, 'push');
            app.OpeninCleartuneButton.ButtonPushedFcn = createCallbackFcn(app, @OpeninCleartuneButtonPushed, true);
            app.OpeninCleartuneButton.Enable = 'off';
            app.OpeninCleartuneButton.Text = 'Open in Cleartune';
            app.IterationsEditFieldLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.IterationsEditFieldLabel.Enable = 'off';
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.CrossvalidationPredictionPanel, 'numeric');
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsEditFieldValueChanged, true);
            app.IterationsEditField.Enable = 'off';
            app.NestedCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.NestedCheckBox.ValueChangedFcn = createCallbackFcn(app, @NestedCheckBoxValueChanged, true);
            app.NestedCheckBox.Text = 'Nested';
            app.MultitractAnalysisPanel = uipanel(app.UIFigure);
            app.MultitractAnalysisPanel.Title = 'Multitract Analysis';
            app.ModelSetupOptionButtonGroup = uibuttongroup(app.MultitractAnalysisPanel);
            app.ModelSetupOptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelSetupOptionButtonGroupSelectionChanged, true);
            app.ModelSetupOptionButtonGroup.Title = 'Model Setup Option';
            app.SingleTractAnalysisButton = uiradiobutton(app.ModelSetupOptionButtonGroup);
            app.SingleTractAnalysisButton.Enable = 'off';
            app.SingleTractAnalysisButton.Text = 'Single Tract Analysis';
            app.SingleTractAnalysisButton.Value = true;
            app.SplitColorByGroupButton = uiradiobutton(app.ModelSetupOptionButtonGroup);
            app.SplitColorByGroupButton.Enable = 'off';
            app.SplitColorByGroupButton.Text = 'Split & Color By Group';
            app.SplitColorBySubscoreButton = uiradiobutton(app.ModelSetupOptionButtonGroup);
            app.SplitColorBySubscoreButton.Enable = 'off';
            app.SplitColorBySubscoreButton.Text = 'Split & Color By Subscore';
            app.SplitColorByPCAButton = uiradiobutton(app.ModelSetupOptionButtonGroup);
            app.SplitColorByPCAButton.Enable = 'off';
            app.SplitColorByPCAButton.Text = 'Split & Color By PCA';
            app.SubscoresListBox = uilistbox(app.MultitractAnalysisPanel);
            app.SubscoresListBox.Multiselect = 'on';
            app.SubscoresListBox.ValueChangedFcn = createCallbackFcn(app, @SubscoresListBoxValueChanged, true);
            app.SubscoresListBox.Enable = 'off';
            app.SubscoresListBox.Value = {'Item 1'};
            app.PCsEditFieldLabel = uilabel(app.MultitractAnalysisPanel);
            app.PCsEditFieldLabel.Enable = 'off';
            app.PCsEditFieldLabel.Text = '# PCs';
            app.PCsEditField = uieditfield(app.MultitractAnalysisPanel, 'numeric');
            app.PCsEditField.RoundFractionalValues = 'on';
            app.PCsEditField.ValueChangedFcn = createCallbackFcn(app, @PCsEditFieldValueChanged, true);
            app.PCsEditField.Enable = 'off';
            app.PCsEditField.Value = 4;
            app.PCAsettingsLabel = uilabel(app.MultitractAnalysisPanel);
            app.PCAsettingsLabel.Enable = 'off';
            app.PCAsettingsLabel.Text = 'PCA settings';
            app.VarianceExpSliderLabel = uilabel(app.MultitractAnalysisPanel);
            app.VarianceExpSliderLabel.Enable = 'off';
            app.VarianceExpSliderLabel.Text = 'Variance Exp';
            app.VarianceExpSlider = uislider(app.MultitractAnalysisPanel);
            app.VarianceExpSlider.MajorTicks = [0 20 40 60 80 100];
            app.VarianceExpSlider.ValueChangedFcn = createCallbackFcn(app, @VarianceExpSliderValueChanged, true);
            app.VarianceExpSlider.ValueChangingFcn = createCallbackFcn(app, @VarianceExpSliderValueChanging, true);
            app.VarianceExpSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.VarianceExpSlider.Enable = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleTractAnalysisTab = uitab(app.TabGroup);
            app.SingleTractAnalysisTab.Title = 'Single Tract Analysis';
            app.ColorBar = uiaxes(app.SingleTractAnalysisTab);
            xlabel(app.ColorBar, 'X')
            ylabel(app.ColorBar, 'Y')
            app.ColorBar.ButtonDownFcn = createCallbackFcn(app, @ColorBarButtonDown, true);
            app.ColorButtonPositive = uibutton(app.SingleTractAnalysisTab, 'push');
            app.ColorButtonPositive.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositivePushed, true);
            app.ColorButtonPositive.Enable = 'off';
            app.ColorButtonPositive.Text = 'Color';
            app.levelEditFieldLabel = uilabel(app.SingleTractAnalysisTab);
            app.levelEditFieldLabel.Enable = 'off';
            app.levelEditFieldLabel.Text = '𝝰-level';
            app.levelEditField = uieditfield(app.SingleTractAnalysisTab, 'numeric');
            app.levelEditField.ValueChangedFcn = createCallbackFcn(app, @levelEditFieldValueChanged, true);
            app.levelEditField.Enable = 'off';
            app.levelEditField.Value = 0.05;
            app.RightSlider_2Label = uilabel(app.SingleTractAnalysisTab);
            app.RightSlider_2Label.Enable = 'off';
            app.RightSlider_2Label.Text = 'Right';
            app.RightSliderNegative = uislider(app.SingleTractAnalysisTab);
            app.RightSliderNegative.ValueChangedFcn = createCallbackFcn(app, @RightSliderNegativeValueChanged, true);
            app.RightSliderNegative.ValueChangingFcn = createCallbackFcn(app, @RightSliderNegativeValueChanging, true);
            app.RightSliderNegative.MinorTicks = [];
            app.RightSliderNegative.Enable = 'off';
            app.RightSliderNegative.Value = 25;
            app.ShowAmountSlider_2Label = uilabel(app.SingleTractAnalysisTab);
            app.ShowAmountSlider_2Label.Enable = 'off';
            app.ShowAmountSlider_2Label.Text = 'Show Amount';
            app.ShowAmountSliderNegative = uislider(app.SingleTractAnalysisTab);
            app.ShowAmountSliderNegative.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderNegativeValueChanged, true);
            app.ShowAmountSliderNegative.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderNegativeValueChanging, true);
            app.ShowAmountSliderNegative.MinorTicks = [];
            app.ShowAmountSliderNegative.Enable = 'off';
            app.ShowAmountSliderNegative.Value = 25;
            app.LeftSlider_2Label = uilabel(app.SingleTractAnalysisTab);
            app.LeftSlider_2Label.Enable = 'off';
            app.LeftSlider_2Label.Text = 'Left';
            app.LeftSliderNegative = uislider(app.SingleTractAnalysisTab);
            app.LeftSliderNegative.ValueChangedFcn = createCallbackFcn(app, @LeftSliderNegativeValueChanged, true);
            app.LeftSliderNegative.ValueChangingFcn = createCallbackFcn(app, @LeftSliderNegativeValueChanging, true);
            app.LeftSliderNegative.MinorTicks = [];
            app.LeftSliderNegative.Enable = 'off';
            app.LeftSliderNegative.Value = 25;
            app.RightSliderLabel = uilabel(app.SingleTractAnalysisTab);
            app.RightSliderLabel.Enable = 'off';
            app.RightSliderLabel.Text = 'Right';
            app.RightSliderPositive = uislider(app.SingleTractAnalysisTab);
            app.RightSliderPositive.ValueChangedFcn = createCallbackFcn(app, @RightSliderPositiveValueChanged, true);
            app.RightSliderPositive.MinorTicks = [];
            app.RightSliderPositive.Enable = 'off';
            app.RightSliderPositive.Value = 25;
            app.LeftSliderLabel = uilabel(app.SingleTractAnalysisTab);
            app.LeftSliderLabel.Enable = 'off';
            app.LeftSliderLabel.Text = 'Left';
            app.LeftSliderPositive = uislider(app.SingleTractAnalysisTab);
            app.LeftSliderPositive.ValueChangedFcn = createCallbackFcn(app, @LeftSliderPositiveValueChanged, true);
            app.LeftSliderPositive.MinorTicks = [];
            app.LeftSliderPositive.Enable = 'off';
            app.LeftSliderPositive.Value = 25;
            app.ShowAmountSliderLabel = uilabel(app.SingleTractAnalysisTab);
            app.ShowAmountSliderLabel.Enable = 'off';
            app.ShowAmountSliderLabel.Text = 'Show Amount';
            app.ShowAmountSliderPositive = uislider(app.SingleTractAnalysisTab);
            app.ShowAmountSliderPositive.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderPositiveValueChanged, true);
            app.ShowAmountSliderPositive.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderPositiveValueChanging, true);
            app.ShowAmountSliderPositive.MinorTicks = [];
            app.ShowAmountSliderPositive.Enable = 'off';
            app.ShowAmountSliderPositive.Value = 25;
            app.ColorButtonNegative = uibutton(app.SingleTractAnalysisTab, 'push');
            app.ColorButtonNegative.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegativePushed, true);
            app.ColorButtonNegative.Enable = 'off';
            app.ColorButtonNegative.Text = 'Color';
            app.ShowPositiveFibersCheckBox = uicheckbox(app.SingleTractAnalysisTab);
            app.ShowPositiveFibersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBoxValueChanged, true);
            app.ShowPositiveFibersCheckBox.Enable = 'off';
            app.ShowPositiveFibersCheckBox.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox.Value = true;
            app.ShowNegativeFibersCheckBox = uicheckbox(app.SingleTractAnalysisTab);
            app.ShowNegativeFibersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBoxValueChanged, true);
            app.ShowNegativeFibersCheckBox.Enable = 'off';
            app.ShowNegativeFibersCheckBox.Text = 'Show Negative Fibers';
            app.ExportasAtlasButton = uibutton(app.SingleTractAnalysisTab, 'push');
            app.ExportasAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasAtlasButtonPushed, true);
            app.ExportasAtlasButton.Enable = 'off';
            app.ExportasAtlasButton.Text = 'Export as Atlas';
            app.ConsiderSignificantFibersonlyCheckBox = uicheckbox(app.SingleTractAnalysisTab);
            app.ConsiderSignificantFibersonlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConsiderSignificantFibersonlyCheckBoxValueChanged, true);
            app.ConsiderSignificantFibersonlyCheckBox.Enable = 'off';
            app.ConsiderSignificantFibersonlyCheckBox.Text = 'Consider Significant Fibers only';
            app.RefreshViewButton = uibutton(app.SingleTractAnalysisTab, 'push');
            app.RefreshViewButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButtonPushed, true);
            app.RefreshViewButton.Enable = 'off';
            app.RefreshViewButton.Text = 'Refresh View';
            app.AutoRefreshCheckBox = uicheckbox(app.SingleTractAnalysisTab);
            app.AutoRefreshCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoRefreshCheckBoxValueChanged, true);
            app.AutoRefreshCheckBox.Enable = 'off';
            app.AutoRefreshCheckBox.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox.Value = true;
            app.MultTestStrategy = uidropdown(app.SingleTractAnalysisTab);
            app.MultTestStrategy.Items = {'FDR', 'Bonferroni', 'Uncorrected'};
            app.MultTestStrategy.ValueChangedFcn = createCallbackFcn(app, @MultTestStrategyValueChanged, true);
            app.MultTestStrategy.Enable = 'off';
            app.MultTestStrategy.Value = 'FDR';
            app.Subscore_1Tab = uitab(app.TabGroup);
            app.Subscore_1Tab.Title = 'Subscore_1';
            app.ColorButtonPositive_2 = uibutton(app.Subscore_1Tab, 'push');
            app.ColorButtonPositive_2.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_2Pushed, true);
            app.ColorButtonPositive_2.Text = 'Color';
            app.RightSlider_2Label_2 = uilabel(app.Subscore_1Tab);
            app.RightSlider_2Label_2.Text = 'Right';
            app.RightSliderNegative_2 = uislider(app.Subscore_1Tab);
            app.RightSliderNegative_2.ValueChangedFcn = createCallbackFcn(app, @RightSliderNegative_2ValueChanged, true);
            app.RightSliderNegative_2.MinorTicks = [];
            app.RightSliderNegative_2.Value = 25;
            app.ShowAmountSlider_2Label_2 = uilabel(app.Subscore_1Tab);
            app.ShowAmountSlider_2Label_2.Text = 'Show Amount';
            app.ShowAmountSliderNegative_2 = uislider(app.Subscore_1Tab);
            app.ShowAmountSliderNegative_2.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderNegative_2ValueChanged, true);
            app.ShowAmountSliderNegative_2.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderNegative_2ValueChanging, true);
            app.ShowAmountSliderNegative_2.MinorTicks = [];
            app.ShowAmountSliderNegative_2.Value = 25;
            app.LeftSlider_2Label_2 = uilabel(app.Subscore_1Tab);
            app.LeftSlider_2Label_2.Text = 'Left';
            app.LeftSliderNegative_2 = uislider(app.Subscore_1Tab);
            app.LeftSliderNegative_2.ValueChangedFcn = createCallbackFcn(app, @LeftSliderNegative_2ValueChanged, true);
            app.LeftSliderNegative_2.ValueChangingFcn = createCallbackFcn(app, @LeftSliderNegative_2ValueChanging, true);
            app.LeftSliderNegative_2.MinorTicks = [];
            app.LeftSliderNegative_2.Value = 25;
            app.RightSliderLabel_2 = uilabel(app.Subscore_1Tab);
            app.RightSliderLabel_2.Text = 'Right';
            app.RightSliderPositive_2 = uislider(app.Subscore_1Tab);
            app.RightSliderPositive_2.ValueChangedFcn = createCallbackFcn(app, @RightSliderPositive_2ValueChanged, true);
            app.RightSliderPositive_2.MinorTicks = [];
            app.RightSliderPositive_2.Value = 25;
            app.LeftSliderLabel_2 = uilabel(app.Subscore_1Tab);
            app.LeftSliderLabel_2.Text = 'Left';
            app.LeftSliderPositive_2 = uislider(app.Subscore_1Tab);
            app.LeftSliderPositive_2.ValueChangedFcn = createCallbackFcn(app, @LeftSliderPositive_2ValueChanged, true);
            app.LeftSliderPositive_2.MinorTicks = [];
            app.LeftSliderPositive_2.Value = 25;
            app.ShowAmountSliderLabel_2 = uilabel(app.Subscore_1Tab);
            app.ShowAmountSliderLabel_2.Text = 'Show Amount';
            app.ShowAmountSliderPositive_2 = uislider(app.Subscore_1Tab);
            app.ShowAmountSliderPositive_2.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderPositive_2ValueChanged, true);
            app.ShowAmountSliderPositive_2.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderPositive_2ValueChanging, true);
            app.ShowAmountSliderPositive_2.MinorTicks = [];
            app.ShowAmountSliderPositive_2.Value = 25;
            app.ColorButtonNegative_2 = uibutton(app.Subscore_1Tab, 'push');
            app.ColorButtonNegative_2.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_2Pushed, true);
            app.ColorButtonNegative_2.Text = 'Color';
            app.ShowPositiveFibersCheckBox_2 = uicheckbox(app.Subscore_1Tab);
            app.ShowPositiveFibersCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_2ValueChanged, true);
            app.ShowPositiveFibersCheckBox_2.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_2.Value = true;
            app.ShowNegativeFibersCheckBox_2 = uicheckbox(app.Subscore_1Tab);
            app.ShowNegativeFibersCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_2ValueChanged, true);
            app.ShowNegativeFibersCheckBox_2.Text = 'Show Negative Fibers';
            app.RefreshViewButton_12 = uibutton(app.Subscore_1Tab, 'push');
            app.RefreshViewButton_12.Text = 'Refresh View';
            app.AutoRefreshCheckBox_12 = uicheckbox(app.Subscore_1Tab);
            app.AutoRefreshCheckBox_12.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_12.Value = true;
            app.Subscore_2Tab = uitab(app.TabGroup);
            app.Subscore_2Tab.Title = 'Subscore_2';
            app.ColorButtonPositive_3 = uibutton(app.Subscore_2Tab, 'push');
            app.ColorButtonPositive_3.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_3Pushed, true);
            app.ColorButtonPositive_3.Text = 'Color';
            app.RightSlider_2Label_3 = uilabel(app.Subscore_2Tab);
            app.RightSlider_2Label_3.Text = 'Right';
            app.RightSliderNegative_3 = uislider(app.Subscore_2Tab);
            app.RightSliderNegative_3.ValueChangedFcn = createCallbackFcn(app, @RightSliderNegative_3ValueChanged, true);
            app.RightSliderNegative_3.MinorTicks = [];
            app.RightSliderNegative_3.Value = 25;
            app.ShowAmountSlider_2Label_3 = uilabel(app.Subscore_2Tab);
            app.ShowAmountSlider_2Label_3.Text = 'Show Amount';
            app.ShowAmountSliderNegative_3 = uislider(app.Subscore_2Tab);
            app.ShowAmountSliderNegative_3.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderNegative_3ValueChanged, true);
            app.ShowAmountSliderNegative_3.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderNegative_3ValueChanging, true);
            app.ShowAmountSliderNegative_3.MinorTicks = [];
            app.ShowAmountSliderNegative_3.Value = 25;
            app.LeftSlider_2Label_3 = uilabel(app.Subscore_2Tab);
            app.LeftSlider_2Label_3.Text = 'Left';
            app.LeftSliderNegative_3 = uislider(app.Subscore_2Tab);
            app.LeftSliderNegative_3.ValueChangedFcn = createCallbackFcn(app, @LeftSliderNegative_3ValueChanged, true);
            app.LeftSliderNegative_3.MinorTicks = [];
            app.LeftSliderNegative_3.Value = 25;
            app.RightSliderLabel_3 = uilabel(app.Subscore_2Tab);
            app.RightSliderLabel_3.Text = 'Right';
            app.RightSliderPositive_3 = uislider(app.Subscore_2Tab);
            app.RightSliderPositive_3.ValueChangedFcn = createCallbackFcn(app, @RightSliderPositive_3ValueChanged, true);
            app.RightSliderPositive_3.MinorTicks = [];
            app.RightSliderPositive_3.Value = 25;
            app.LeftSliderLabel_3 = uilabel(app.Subscore_2Tab);
            app.LeftSliderLabel_3.Text = 'Left';
            app.LeftSliderPositive_3 = uislider(app.Subscore_2Tab);
            app.LeftSliderPositive_3.ValueChangedFcn = createCallbackFcn(app, @LeftSliderPositive_3ValueChanged, true);
            app.LeftSliderPositive_3.MinorTicks = [];
            app.LeftSliderPositive_3.Value = 25;
            app.ShowAmountSliderLabel_3 = uilabel(app.Subscore_2Tab);
            app.ShowAmountSliderLabel_3.Text = 'Show Amount';
            app.ShowAmountSliderPositive_3 = uislider(app.Subscore_2Tab);
            app.ShowAmountSliderPositive_3.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderPositive_3ValueChanged, true);
            app.ShowAmountSliderPositive_3.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderPositive_3ValueChanging, true);
            app.ShowAmountSliderPositive_3.MinorTicks = [];
            app.ShowAmountSliderPositive_3.Value = 25;
            app.ColorButtonNegative_3 = uibutton(app.Subscore_2Tab, 'push');
            app.ColorButtonNegative_3.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_3Pushed, true);
            app.ColorButtonNegative_3.Text = 'Color';
            app.ShowPositiveFibersCheckBox_3 = uicheckbox(app.Subscore_2Tab);
            app.ShowPositiveFibersCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_3ValueChanged, true);
            app.ShowPositiveFibersCheckBox_3.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_3.Value = true;
            app.ShowNegativeFibersCheckBox_3 = uicheckbox(app.Subscore_2Tab);
            app.ShowNegativeFibersCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_3ValueChanged, true);
            app.ShowNegativeFibersCheckBox_3.Text = 'Show Negative Fibers';
            app.RefreshViewButton_3 = uibutton(app.Subscore_2Tab, 'push');
            app.RefreshViewButton_3.Text = 'Refresh View';
            app.AutoRefreshCheckBox_3 = uicheckbox(app.Subscore_2Tab);
            app.AutoRefreshCheckBox_3.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_3.Value = true;
            app.Subscore_3Tab = uitab(app.TabGroup);
            app.Subscore_3Tab.Title = 'Subscore_3';
            app.ColorButtonPositive_4 = uibutton(app.Subscore_3Tab, 'push');
            app.ColorButtonPositive_4.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_4Pushed, true);
            app.ColorButtonPositive_4.Text = 'Color';
            app.RightSlider_2Label_4 = uilabel(app.Subscore_3Tab);
            app.RightSlider_2Label_4.Text = 'Right';
            app.RightSliderNegative_4 = uislider(app.Subscore_3Tab);
            app.RightSliderNegative_4.ValueChangedFcn = createCallbackFcn(app, @RightSliderNegative_4ValueChanged, true);
            app.RightSliderNegative_4.MinorTicks = [];
            app.RightSliderNegative_4.Value = 25;
            app.ShowAmountSlider_2Label_4 = uilabel(app.Subscore_3Tab);
            app.ShowAmountSlider_2Label_4.Text = 'Show Amount';
            app.ShowAmountSliderNegative_4 = uislider(app.Subscore_3Tab);
            app.ShowAmountSliderNegative_4.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderNegative_4ValueChanged, true);
            app.ShowAmountSliderNegative_4.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderNegative_4ValueChanging, true);
            app.ShowAmountSliderNegative_4.MinorTicks = [];
            app.ShowAmountSliderNegative_4.Value = 25;
            app.LeftSlider_2Label_4 = uilabel(app.Subscore_3Tab);
            app.LeftSlider_2Label_4.Text = 'Left';
            app.LeftSliderNegative_4 = uislider(app.Subscore_3Tab);
            app.LeftSliderNegative_4.ValueChangedFcn = createCallbackFcn(app, @LeftSliderNegative_4ValueChanged, true);
            app.LeftSliderNegative_4.MinorTicks = [];
            app.LeftSliderNegative_4.Value = 25;
            app.RightSliderLabel_4 = uilabel(app.Subscore_3Tab);
            app.RightSliderLabel_4.Text = 'Right';
            app.RightSliderPositive_4 = uislider(app.Subscore_3Tab);
            app.RightSliderPositive_4.ValueChangedFcn = createCallbackFcn(app, @RightSliderPositive_4ValueChanged, true);
            app.RightSliderPositive_4.MinorTicks = [];
            app.RightSliderPositive_4.Value = 25;
            app.LeftSliderLabel_4 = uilabel(app.Subscore_3Tab);
            app.LeftSliderLabel_4.Text = 'Left';
            app.LeftSliderPositive_4 = uislider(app.Subscore_3Tab);
            app.LeftSliderPositive_4.ValueChangedFcn = createCallbackFcn(app, @LeftSliderPositive_4ValueChanged, true);
            app.LeftSliderPositive_4.MinorTicks = [];
            app.LeftSliderPositive_4.Value = 25;
            app.ShowAmountSliderLabel_4 = uilabel(app.Subscore_3Tab);
            app.ShowAmountSliderLabel_4.Text = 'Show Amount';
            app.ShowAmountSliderPositive_4 = uislider(app.Subscore_3Tab);
            app.ShowAmountSliderPositive_4.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderPositive_4ValueChanged, true);
            app.ShowAmountSliderPositive_4.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderPositive_4ValueChanging, true);
            app.ShowAmountSliderPositive_4.MinorTicks = [];
            app.ShowAmountSliderPositive_4.Value = 25;
            app.ColorButtonNegative_4 = uibutton(app.Subscore_3Tab, 'push');
            app.ColorButtonNegative_4.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_4Pushed, true);
            app.ColorButtonNegative_4.Text = 'Color';
            app.ShowPositiveFibersCheckBox_4 = uicheckbox(app.Subscore_3Tab);
            app.ShowPositiveFibersCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_4ValueChanged, true);
            app.ShowPositiveFibersCheckBox_4.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_4.Value = true;
            app.ShowNegativeFibersCheckBox_4 = uicheckbox(app.Subscore_3Tab);
            app.ShowNegativeFibersCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_4ValueChanged, true);
            app.ShowNegativeFibersCheckBox_4.Text = 'Show Negative Fibers';
            app.RefreshViewButton_4 = uibutton(app.Subscore_3Tab, 'push');
            app.RefreshViewButton_4.Text = 'Refresh View';
            app.AutoRefreshCheckBox_4 = uicheckbox(app.Subscore_3Tab);
            app.AutoRefreshCheckBox_4.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_4.Value = true;
            app.Subscore_4Tab = uitab(app.TabGroup);
            app.Subscore_4Tab.Title = 'Subscore_4';
            app.ColorButtonPositive_5 = uibutton(app.Subscore_4Tab, 'push');
            app.ColorButtonPositive_5.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_5Pushed, true);
            app.ColorButtonPositive_5.Text = 'Color';
            app.RightSlider_2Label_5 = uilabel(app.Subscore_4Tab);
            app.RightSlider_2Label_5.Text = 'Right';
            app.RightSliderNegative_5 = uislider(app.Subscore_4Tab);
            app.RightSliderNegative_5.ValueChangedFcn = createCallbackFcn(app, @RightSliderNegative_5ValueChanged, true);
            app.RightSliderNegative_5.MinorTicks = [];
            app.RightSliderNegative_5.Value = 25;
            app.ShowAmountSlider_2Label_5 = uilabel(app.Subscore_4Tab);
            app.ShowAmountSlider_2Label_5.Text = 'Show Amount';
            app.ShowAmountSliderNegative_5 = uislider(app.Subscore_4Tab);
            app.ShowAmountSliderNegative_5.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderNegative_5ValueChanged, true);
            app.ShowAmountSliderNegative_5.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderNegative_5ValueChanging, true);
            app.ShowAmountSliderNegative_5.MinorTicks = [];
            app.ShowAmountSliderNegative_5.Value = 25;
            app.LeftSlider_2Label_5 = uilabel(app.Subscore_4Tab);
            app.LeftSlider_2Label_5.Text = 'Left';
            app.LeftSliderNegative_5 = uislider(app.Subscore_4Tab);
            app.LeftSliderNegative_5.ValueChangedFcn = createCallbackFcn(app, @LeftSliderNegative_5ValueChanged, true);
            app.LeftSliderNegative_5.MinorTicks = [];
            app.LeftSliderNegative_5.Value = 25;
            app.RightSliderLabel_5 = uilabel(app.Subscore_4Tab);
            app.RightSliderLabel_5.Text = 'Right';
            app.RightSliderPositive_5 = uislider(app.Subscore_4Tab);
            app.RightSliderPositive_5.ValueChangedFcn = createCallbackFcn(app, @RightSliderPositive_5ValueChanged, true);
            app.RightSliderPositive_5.MinorTicks = [];
            app.RightSliderPositive_5.Value = 25;
            app.LeftSliderLabel_5 = uilabel(app.Subscore_4Tab);
            app.LeftSliderLabel_5.Text = 'Left';
            app.LeftSliderPositive_5 = uislider(app.Subscore_4Tab);
            app.LeftSliderPositive_5.ValueChangedFcn = createCallbackFcn(app, @LeftSliderPositive_5ValueChanged, true);
            app.LeftSliderPositive_5.MinorTicks = [];
            app.LeftSliderPositive_5.Value = 25;
            app.ShowAmountSliderLabel_5 = uilabel(app.Subscore_4Tab);
            app.ShowAmountSliderLabel_5.Text = 'Show Amount';
            app.ShowAmountSliderPositive_5 = uislider(app.Subscore_4Tab);
            app.ShowAmountSliderPositive_5.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderPositive_5ValueChanged, true);
            app.ShowAmountSliderPositive_5.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderPositive_5ValueChanging, true);
            app.ShowAmountSliderPositive_5.MinorTicks = [];
            app.ShowAmountSliderPositive_5.Value = 25;
            app.ColorButtonNegative_5 = uibutton(app.Subscore_4Tab, 'push');
            app.ColorButtonNegative_5.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_5Pushed, true);
            app.ColorButtonNegative_5.Text = 'Color';
            app.ShowPositiveFibersCheckBox_5 = uicheckbox(app.Subscore_4Tab);
            app.ShowPositiveFibersCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_5ValueChanged, true);
            app.ShowPositiveFibersCheckBox_5.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_5.Value = true;
            app.ShowNegativeFibersCheckBox_5 = uicheckbox(app.Subscore_4Tab);
            app.ShowNegativeFibersCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_5ValueChanged, true);
            app.ShowNegativeFibersCheckBox_5.Text = 'Show Negative Fibers';
            app.RefreshViewButton_5 = uibutton(app.Subscore_4Tab, 'push');
            app.RefreshViewButton_5.Text = 'Refresh View';
            app.AutoRefreshCheckBox_5 = uicheckbox(app.Subscore_4Tab);
            app.AutoRefreshCheckBox_5.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_5.Value = true;
            app.Subscore_5Tab = uitab(app.TabGroup);
            app.Subscore_5Tab.Title = 'Subscore_5';
            app.ColorButtonPositive_6 = uibutton(app.Subscore_5Tab, 'push');
            app.ColorButtonPositive_6.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_6Pushed, true);
            app.ColorButtonPositive_6.Text = 'Color';
            app.RightSlider_2Label_6 = uilabel(app.Subscore_5Tab);
            app.RightSlider_2Label_6.Text = 'Right';
            app.RightSliderNegative_6 = uislider(app.Subscore_5Tab);
            app.RightSliderNegative_6.ValueChangedFcn = createCallbackFcn(app, @RightSliderNegative_6ValueChanged, true);
            app.RightSliderNegative_6.MinorTicks = [];
            app.RightSliderNegative_6.Value = 25;
            app.ShowAmountSlider_2Label_6 = uilabel(app.Subscore_5Tab);
            app.ShowAmountSlider_2Label_6.Text = 'Show Amount';
            app.ShowAmountSliderNegative_6 = uislider(app.Subscore_5Tab);
            app.ShowAmountSliderNegative_6.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderNegative_6ValueChanged, true);
            app.ShowAmountSliderNegative_6.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderNegative_6ValueChanging, true);
            app.ShowAmountSliderNegative_6.MinorTicks = [];
            app.ShowAmountSliderNegative_6.Value = 25;
            app.LeftSlider_2Label_6 = uilabel(app.Subscore_5Tab);
            app.LeftSlider_2Label_6.Text = 'Left';
            app.LeftSliderNegative_6 = uislider(app.Subscore_5Tab);
            app.LeftSliderNegative_6.ValueChangedFcn = createCallbackFcn(app, @LeftSliderNegative_6ValueChanged, true);
            app.LeftSliderNegative_6.MinorTicks = [];
            app.LeftSliderNegative_6.Value = 25;
            app.RightSliderLabel_6 = uilabel(app.Subscore_5Tab);
            app.RightSliderLabel_6.Text = 'Right';
            app.RightSliderPositive_6 = uislider(app.Subscore_5Tab);
            app.RightSliderPositive_6.ValueChangedFcn = createCallbackFcn(app, @RightSliderPositive_6ValueChanged, true);
            app.RightSliderPositive_6.MinorTicks = [];
            app.RightSliderPositive_6.Value = 25;
            app.LeftSliderLabel_6 = uilabel(app.Subscore_5Tab);
            app.LeftSliderLabel_6.Text = 'Left';
            app.LeftSliderPositive_6 = uislider(app.Subscore_5Tab);
            app.LeftSliderPositive_6.ValueChangedFcn = createCallbackFcn(app, @LeftSliderPositive_6ValueChanged, true);
            app.LeftSliderPositive_6.MinorTicks = [];
            app.LeftSliderPositive_6.Value = 25;
            app.ShowAmountSliderLabel_6 = uilabel(app.Subscore_5Tab);
            app.ShowAmountSliderLabel_6.Text = 'Show Amount';
            app.ShowAmountSliderPositive_6 = uislider(app.Subscore_5Tab);
            app.ShowAmountSliderPositive_6.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderPositive_6ValueChanged, true);
            app.ShowAmountSliderPositive_6.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderPositive_6ValueChanging, true);
            app.ShowAmountSliderPositive_6.MinorTicks = [];
            app.ShowAmountSliderPositive_6.Value = 25;
            app.ColorButtonNegative_6 = uibutton(app.Subscore_5Tab, 'push');
            app.ColorButtonNegative_6.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_6Pushed, true);
            app.ColorButtonNegative_6.Text = 'Color';
            app.ShowPositiveFibersCheckBox_6 = uicheckbox(app.Subscore_5Tab);
            app.ShowPositiveFibersCheckBox_6.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_6ValueChanged, true);
            app.ShowPositiveFibersCheckBox_6.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_6.Value = true;
            app.ShowNegativeFibersCheckBox_6 = uicheckbox(app.Subscore_5Tab);
            app.ShowNegativeFibersCheckBox_6.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_6ValueChanged, true);
            app.ShowNegativeFibersCheckBox_6.Text = 'Show Negative Fibers';
            app.RefreshViewButton_6 = uibutton(app.Subscore_5Tab, 'push');
            app.RefreshViewButton_6.Text = 'Refresh View';
            app.AutoRefreshCheckBox_6 = uicheckbox(app.Subscore_5Tab);
            app.AutoRefreshCheckBox_6.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_6.Value = true;
            app.Subscore_6Tab = uitab(app.TabGroup);
            app.Subscore_6Tab.Title = 'Subscore_6';
            app.ColorButtonPositive_7 = uibutton(app.Subscore_6Tab, 'push');
            app.ColorButtonPositive_7.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_7Pushed, true);
            app.ColorButtonPositive_7.Text = 'Color';
            app.RightSlider_2Label_7 = uilabel(app.Subscore_6Tab);
            app.RightSlider_2Label_7.Text = 'Right';
            app.RightSliderNegative_7 = uislider(app.Subscore_6Tab);
            app.RightSliderNegative_7.ValueChangedFcn = createCallbackFcn(app, @RightSliderNegative_7ValueChanged, true);
            app.RightSliderNegative_7.MinorTicks = [];
            app.RightSliderNegative_7.Value = 25;
            app.ShowAmountSlider_2Label_7 = uilabel(app.Subscore_6Tab);
            app.ShowAmountSlider_2Label_7.Text = 'Show Amount';
            app.ShowAmountSliderNegative_7 = uislider(app.Subscore_6Tab);
            app.ShowAmountSliderNegative_7.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderNegative_7ValueChanged, true);
            app.ShowAmountSliderNegative_7.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderNegative_7ValueChanging, true);
            app.ShowAmountSliderNegative_7.MinorTicks = [];
            app.ShowAmountSliderNegative_7.Value = 25;
            app.LeftSlider_2Label_7 = uilabel(app.Subscore_6Tab);
            app.LeftSlider_2Label_7.Text = 'Left';
            app.LeftSliderNegative_7 = uislider(app.Subscore_6Tab);
            app.LeftSliderNegative_7.ValueChangedFcn = createCallbackFcn(app, @LeftSliderNegative_7ValueChanged, true);
            app.LeftSliderNegative_7.MinorTicks = [];
            app.LeftSliderNegative_7.Value = 25;
            app.RightSliderLabel_7 = uilabel(app.Subscore_6Tab);
            app.RightSliderLabel_7.Text = 'Right';
            app.RightSliderPositive_7 = uislider(app.Subscore_6Tab);
            app.RightSliderPositive_7.ValueChangedFcn = createCallbackFcn(app, @RightSliderPositive_7ValueChanged, true);
            app.RightSliderPositive_7.MinorTicks = [];
            app.RightSliderPositive_7.Value = 25;
            app.LeftSliderLabel_7 = uilabel(app.Subscore_6Tab);
            app.LeftSliderLabel_7.Text = 'Left';
            app.LeftSliderPositive_7 = uislider(app.Subscore_6Tab);
            app.LeftSliderPositive_7.ValueChangedFcn = createCallbackFcn(app, @LeftSliderPositive_7ValueChanged, true);
            app.LeftSliderPositive_7.MinorTicks = [];
            app.LeftSliderPositive_7.Value = 25;
            app.ShowAmountSliderLabel_7 = uilabel(app.Subscore_6Tab);
            app.ShowAmountSliderLabel_7.Text = 'Show Amount';
            app.ShowAmountSliderPositive_7 = uislider(app.Subscore_6Tab);
            app.ShowAmountSliderPositive_7.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderPositive_7ValueChanged, true);
            app.ShowAmountSliderPositive_7.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderPositive_7ValueChanging, true);
            app.ShowAmountSliderPositive_7.MinorTicks = [];
            app.ShowAmountSliderPositive_7.Value = 25;
            app.ColorButtonNegative_7 = uibutton(app.Subscore_6Tab, 'push');
            app.ColorButtonNegative_7.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_7Pushed, true);
            app.ColorButtonNegative_7.Text = 'Color';
            app.ShowPositiveFibersCheckBox_7 = uicheckbox(app.Subscore_6Tab);
            app.ShowPositiveFibersCheckBox_7.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_7ValueChanged, true);
            app.ShowPositiveFibersCheckBox_7.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_7.Value = true;
            app.ShowNegativeFibersCheckBox_7 = uicheckbox(app.Subscore_6Tab);
            app.ShowNegativeFibersCheckBox_7.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_7ValueChanged, true);
            app.ShowNegativeFibersCheckBox_7.Text = 'Show Negative Fibers';
            app.RefreshViewButton_7 = uibutton(app.Subscore_6Tab, 'push');
            app.RefreshViewButton_7.Text = 'Refresh View';
            app.AutoRefreshCheckBox_7 = uicheckbox(app.Subscore_6Tab);
            app.AutoRefreshCheckBox_7.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_7.Value = true;
            app.Subscore_7Tab = uitab(app.TabGroup);
            app.Subscore_7Tab.Title = 'Subscore_7';
            app.ColorButtonPositive_8 = uibutton(app.Subscore_7Tab, 'push');
            app.ColorButtonPositive_8.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_8Pushed, true);
            app.ColorButtonPositive_8.Text = 'Color';
            app.RightSlider_2Label_8 = uilabel(app.Subscore_7Tab);
            app.RightSlider_2Label_8.Text = 'Right';
            app.RightSliderNegative_8 = uislider(app.Subscore_7Tab);
            app.RightSliderNegative_8.ValueChangedFcn = createCallbackFcn(app, @RightSliderNegative_8ValueChanged, true);
            app.RightSliderNegative_8.MinorTicks = [];
            app.RightSliderNegative_8.Value = 25;
            app.ShowAmountSlider_2Label_8 = uilabel(app.Subscore_7Tab);
            app.ShowAmountSlider_2Label_8.Text = 'Show Amount';
            app.ShowAmountSliderNegative_8 = uislider(app.Subscore_7Tab);
            app.ShowAmountSliderNegative_8.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderNegative_8ValueChanged, true);
            app.ShowAmountSliderNegative_8.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderNegative_8ValueChanging, true);
            app.ShowAmountSliderNegative_8.MinorTicks = [];
            app.ShowAmountSliderNegative_8.Value = 25;
            app.LeftSlider_2Label_8 = uilabel(app.Subscore_7Tab);
            app.LeftSlider_2Label_8.Text = 'Left';
            app.LeftSliderNegative_8 = uislider(app.Subscore_7Tab);
            app.LeftSliderNegative_8.ValueChangedFcn = createCallbackFcn(app, @LeftSliderNegative_8ValueChanged, true);
            app.LeftSliderNegative_8.MinorTicks = [];
            app.LeftSliderNegative_8.Value = 25;
            app.RightSliderLabel_8 = uilabel(app.Subscore_7Tab);
            app.RightSliderLabel_8.Text = 'Right';
            app.RightSliderPositive_8 = uislider(app.Subscore_7Tab);
            app.RightSliderPositive_8.ValueChangedFcn = createCallbackFcn(app, @RightSliderPositive_8ValueChanged, true);
            app.RightSliderPositive_8.MinorTicks = [];
            app.RightSliderPositive_8.Value = 25;
            app.LeftSliderLabel_8 = uilabel(app.Subscore_7Tab);
            app.LeftSliderLabel_8.Text = 'Left';
            app.LeftSliderPositive_8 = uislider(app.Subscore_7Tab);
            app.LeftSliderPositive_8.ValueChangedFcn = createCallbackFcn(app, @LeftSliderPositive_8ValueChanged, true);
            app.LeftSliderPositive_8.MinorTicks = [];
            app.LeftSliderPositive_8.Value = 25;
            app.ShowAmountSliderLabel_8 = uilabel(app.Subscore_7Tab);
            app.ShowAmountSliderLabel_8.Text = 'Show Amount';
            app.ShowAmountSliderPositive_8 = uislider(app.Subscore_7Tab);
            app.ShowAmountSliderPositive_8.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderPositive_8ValueChanged, true);
            app.ShowAmountSliderPositive_8.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderPositive_8ValueChanging, true);
            app.ShowAmountSliderPositive_8.MinorTicks = [];
            app.ShowAmountSliderPositive_8.Value = 25;
            app.ColorButtonNegative_8 = uibutton(app.Subscore_7Tab, 'push');
            app.ColorButtonNegative_8.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_8Pushed, true);
            app.ColorButtonNegative_8.Text = 'Color';
            app.ShowPositiveFibersCheckBox_8 = uicheckbox(app.Subscore_7Tab);
            app.ShowPositiveFibersCheckBox_8.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_8ValueChanged, true);
            app.ShowPositiveFibersCheckBox_8.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_8.Value = true;
            app.ShowNegativeFibersCheckBox_8 = uicheckbox(app.Subscore_7Tab);
            app.ShowNegativeFibersCheckBox_8.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_8ValueChanged, true);
            app.ShowNegativeFibersCheckBox_8.Text = 'Show Negative Fibers';
            app.RefreshViewButton_8 = uibutton(app.Subscore_7Tab, 'push');
            app.RefreshViewButton_8.Text = 'Refresh View';
            app.AutoRefreshCheckBox_8 = uicheckbox(app.Subscore_7Tab);
            app.AutoRefreshCheckBox_8.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_8.Value = true;
            app.Subscore_8Tab = uitab(app.TabGroup);
            app.Subscore_8Tab.Title = 'Subscore_8';
            app.ColorButtonPositive_9 = uibutton(app.Subscore_8Tab, 'push');
            app.ColorButtonPositive_9.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_9Pushed, true);
            app.ColorButtonPositive_9.Text = 'Color';
            app.RightSlider_2Label_9 = uilabel(app.Subscore_8Tab);
            app.RightSlider_2Label_9.Text = 'Right';
            app.RightSliderNegative_9 = uislider(app.Subscore_8Tab);
            app.RightSliderNegative_9.ValueChangedFcn = createCallbackFcn(app, @RightSliderNegative_9ValueChanged, true);
            app.RightSliderNegative_9.MinorTicks = [];
            app.RightSliderNegative_9.Value = 25;
            app.ShowAmountSlider_2Label_9 = uilabel(app.Subscore_8Tab);
            app.ShowAmountSlider_2Label_9.Text = 'Show Amount';
            app.ShowAmountSliderNegative_9 = uislider(app.Subscore_8Tab);
            app.ShowAmountSliderNegative_9.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderNegative_9ValueChanged, true);
            app.ShowAmountSliderNegative_9.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderNegative_9ValueChanging, true);
            app.ShowAmountSliderNegative_9.MinorTicks = [];
            app.ShowAmountSliderNegative_9.Value = 25;
            app.LeftSlider_2Label_9 = uilabel(app.Subscore_8Tab);
            app.LeftSlider_2Label_9.Text = 'Left';
            app.LeftSliderNegative_9 = uislider(app.Subscore_8Tab);
            app.LeftSliderNegative_9.ValueChangedFcn = createCallbackFcn(app, @LeftSliderNegative_9ValueChanged, true);
            app.LeftSliderNegative_9.MinorTicks = [];
            app.LeftSliderNegative_9.Value = 25;
            app.RightSliderLabel_9 = uilabel(app.Subscore_8Tab);
            app.RightSliderLabel_9.Text = 'Right';
            app.RightSliderPositive_9 = uislider(app.Subscore_8Tab);
            app.RightSliderPositive_9.ValueChangedFcn = createCallbackFcn(app, @RightSliderPositive_9ValueChanged, true);
            app.RightSliderPositive_9.MinorTicks = [];
            app.RightSliderPositive_9.Value = 25;
            app.LeftSliderLabel_9 = uilabel(app.Subscore_8Tab);
            app.LeftSliderLabel_9.Text = 'Left';
            app.LeftSliderPositive_9 = uislider(app.Subscore_8Tab);
            app.LeftSliderPositive_9.ValueChangedFcn = createCallbackFcn(app, @LeftSliderPositive_9ValueChanged, true);
            app.LeftSliderPositive_9.MinorTicks = [];
            app.LeftSliderPositive_9.Value = 25;
            app.ShowAmountSliderLabel_9 = uilabel(app.Subscore_8Tab);
            app.ShowAmountSliderLabel_9.Text = 'Show Amount';
            app.ShowAmountSliderPositive_9 = uislider(app.Subscore_8Tab);
            app.ShowAmountSliderPositive_9.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderPositive_9ValueChanged, true);
            app.ShowAmountSliderPositive_9.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderPositive_9ValueChanging, true);
            app.ShowAmountSliderPositive_9.MinorTicks = [];
            app.ShowAmountSliderPositive_9.Value = 25;
            app.ColorButtonNegative_9 = uibutton(app.Subscore_8Tab, 'push');
            app.ColorButtonNegative_9.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_9Pushed, true);
            app.ColorButtonNegative_9.Text = 'Color';
            app.ShowPositiveFibersCheckBox_9 = uicheckbox(app.Subscore_8Tab);
            app.ShowPositiveFibersCheckBox_9.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_9ValueChanged, true);
            app.ShowPositiveFibersCheckBox_9.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_9.Value = true;
            app.ShowNegativeFibersCheckBox_9 = uicheckbox(app.Subscore_8Tab);
            app.ShowNegativeFibersCheckBox_9.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_9ValueChanged, true);
            app.ShowNegativeFibersCheckBox_9.Text = 'Show Negative Fibers';
            app.RefreshViewButton_9 = uibutton(app.Subscore_8Tab, 'push');
            app.RefreshViewButton_9.Text = 'Refresh View';
            app.AutoRefreshCheckBox_9 = uicheckbox(app.Subscore_8Tab);
            app.AutoRefreshCheckBox_9.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_9.Value = true;
            app.Subscore_9Tab = uitab(app.TabGroup);
            app.Subscore_9Tab.Title = 'Subscore_9';
            app.ColorButtonPositive_10 = uibutton(app.Subscore_9Tab, 'push');
            app.ColorButtonPositive_10.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_10Pushed, true);
            app.ColorButtonPositive_10.Text = 'Color';
            app.RightSlider_2Label_10 = uilabel(app.Subscore_9Tab);
            app.RightSlider_2Label_10.Text = 'Right';
            app.RightSliderNegative_10 = uislider(app.Subscore_9Tab);
            app.RightSliderNegative_10.ValueChangedFcn = createCallbackFcn(app, @RightSliderNegative_10ValueChanged, true);
            app.RightSliderNegative_10.MinorTicks = [];
            app.RightSliderNegative_10.Value = 25;
            app.ShowAmountSlider_2Label_10 = uilabel(app.Subscore_9Tab);
            app.ShowAmountSlider_2Label_10.Text = 'Show Amount';
            app.ShowAmountSliderNegative_10 = uislider(app.Subscore_9Tab);
            app.ShowAmountSliderNegative_10.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderNegative_10ValueChanged, true);
            app.ShowAmountSliderNegative_10.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderNegative_10ValueChanging, true);
            app.ShowAmountSliderNegative_10.MinorTicks = [];
            app.ShowAmountSliderNegative_10.Value = 25;
            app.LeftSlider_2Label_10 = uilabel(app.Subscore_9Tab);
            app.LeftSlider_2Label_10.Text = 'Left';
            app.LeftSliderNegative_10 = uislider(app.Subscore_9Tab);
            app.LeftSliderNegative_10.ValueChangedFcn = createCallbackFcn(app, @LeftSliderNegative_10ValueChanged, true);
            app.LeftSliderNegative_10.MinorTicks = [];
            app.LeftSliderNegative_10.Value = 25;
            app.RightSliderLabel_10 = uilabel(app.Subscore_9Tab);
            app.RightSliderLabel_10.Text = 'Right';
            app.RightSliderPositive_10 = uislider(app.Subscore_9Tab);
            app.RightSliderPositive_10.ValueChangedFcn = createCallbackFcn(app, @RightSliderPositive_10ValueChanged, true);
            app.RightSliderPositive_10.MinorTicks = [];
            app.RightSliderPositive_10.Value = 25;
            app.LeftSliderLabel_10 = uilabel(app.Subscore_9Tab);
            app.LeftSliderLabel_10.Text = 'Left';
            app.LeftSliderPositive_10 = uislider(app.Subscore_9Tab);
            app.LeftSliderPositive_10.ValueChangedFcn = createCallbackFcn(app, @LeftSliderPositive_10ValueChanged, true);
            app.LeftSliderPositive_10.MinorTicks = [];
            app.LeftSliderPositive_10.Value = 25;
            app.ShowAmountSliderLabel_10 = uilabel(app.Subscore_9Tab);
            app.ShowAmountSliderLabel_10.Text = 'Show Amount';
            app.ShowAmountSliderPositive_10 = uislider(app.Subscore_9Tab);
            app.ShowAmountSliderPositive_10.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderPositive_10ValueChanged, true);
            app.ShowAmountSliderPositive_10.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderPositive_10ValueChanging, true);
            app.ShowAmountSliderPositive_10.MinorTicks = [];
            app.ShowAmountSliderPositive_10.Value = 25;
            app.ColorButtonNegative_10 = uibutton(app.Subscore_9Tab, 'push');
            app.ColorButtonNegative_10.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_10Pushed, true);
            app.ColorButtonNegative_10.Text = 'Color';
            app.ShowPositiveFibersCheckBox_10 = uicheckbox(app.Subscore_9Tab);

            app.ShowPositiveFibersCheckBox_10.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_10ValueChanged, true);
            app.ShowPositiveFibersCheckBox_10.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_10.Value = true;
            app.ShowNegativeFibersCheckBox_10 = uicheckbox(app.Subscore_9Tab);
            app.ShowNegativeFibersCheckBox_10.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_10ValueChanged, true);
            app.ShowNegativeFibersCheckBox_10.Text = 'Show Negative Fibers';
            app.RefreshViewButton_10 = uibutton(app.Subscore_9Tab, 'push');
            app.RefreshViewButton_10.Text = 'Refresh View';
            app.AutoRefreshCheckBox_10 = uicheckbox(app.Subscore_9Tab);
            app.AutoRefreshCheckBox_10.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_10.Value = true;
            app.Subscore_10Tab = uitab(app.TabGroup);
            app.Subscore_10Tab.Title = 'Subscore_10';
            app.ColorButtonPositive_11 = uibutton(app.Subscore_10Tab, 'push');
            app.ColorButtonPositive_11.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_11Pushed, true);
            app.ColorButtonPositive_11.Text = 'Color';
            app.RightSlider_2Label_11 = uilabel(app.Subscore_10Tab);
            app.RightSlider_2Label_11.Text = 'Right';
            app.RightSliderNegative_11 = uislider(app.Subscore_10Tab);
            app.RightSliderNegative_11.ValueChangedFcn = createCallbackFcn(app, @RightSliderNegative_11ValueChanged, true);
            app.RightSliderNegative_11.MinorTicks = [];
            app.RightSliderNegative_11.Value = 25;
            app.ShowAmountSlider_2Label_11 = uilabel(app.Subscore_10Tab);
            app.ShowAmountSlider_2Label_11.Text = 'Show Amount';
            app.ShowAmountSliderNegative_11 = uislider(app.Subscore_10Tab);
            app.ShowAmountSliderNegative_11.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderNegative_11ValueChanged, true);
            app.ShowAmountSliderNegative_11.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderNegative_11ValueChanging, true);
            app.ShowAmountSliderNegative_11.MinorTicks = [];
            app.ShowAmountSliderNegative_11.Value = 25;
            app.LeftSlider_2Label_11 = uilabel(app.Subscore_10Tab);
            app.LeftSlider_2Label_11.Text = 'Left';
            app.LeftSliderNegative_11 = uislider(app.Subscore_10Tab);
            app.LeftSliderNegative_11.ValueChangedFcn = createCallbackFcn(app, @LeftSliderNegative_11ValueChanged, true);
            app.LeftSliderNegative_11.MinorTicks = [];
            app.LeftSliderNegative_11.Value = 25;
            app.RightSliderLabel_11 = uilabel(app.Subscore_10Tab);
            app.RightSliderLabel_11.Text = 'Right';
            app.RightSliderPositive_11 = uislider(app.Subscore_10Tab);
            app.RightSliderPositive_11.ValueChangedFcn = createCallbackFcn(app, @RightSliderPositive_11ValueChanged, true);
            app.RightSliderPositive_11.MinorTicks = [];
            app.RightSliderPositive_11.Value = 25;
            app.LeftSliderLabel_11 = uilabel(app.Subscore_10Tab);
            app.LeftSliderLabel_11.Text = 'Left';
            app.LeftSliderPositive_11 = uislider(app.Subscore_10Tab);
            app.LeftSliderPositive_11.ValueChangedFcn = createCallbackFcn(app, @LeftSliderPositive_11ValueChanged, true);
            app.LeftSliderPositive_11.MinorTicks = [];
            app.LeftSliderPositive_11.Value = 25;
            app.ShowAmountSliderLabel_11 = uilabel(app.Subscore_10Tab);
            app.ShowAmountSliderLabel_11.Text = 'Show Amount';
            app.ShowAmountSliderPositive_11 = uislider(app.Subscore_10Tab);
            app.ShowAmountSliderPositive_11.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderPositive_11ValueChanged, true);
            app.ShowAmountSliderPositive_11.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderPositive_11ValueChanging, true);
            app.ShowAmountSliderPositive_11.MinorTicks = [];
            app.ShowAmountSliderPositive_11.Value = 25;
            app.ColorButtonNegative_11 = uibutton(app.Subscore_10Tab, 'push');
            app.ColorButtonNegative_11.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_11Pushed, true);
            app.ColorButtonNegative_11.Text = 'Color';
            app.ShowPositiveFibersCheckBox_11 = uicheckbox(app.Subscore_10Tab);
            app.ShowPositiveFibersCheckBox_11.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_11ValueChanged, true);
            app.ShowPositiveFibersCheckBox_11.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_11.Value = true;
            app.ShowNegativeFibersCheckBox_11 = uicheckbox(app.Subscore_10Tab);
            app.ShowNegativeFibersCheckBox_11.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_11ValueChanged, true);
            app.ShowNegativeFibersCheckBox_11.Text = 'Show Negative Fibers';
            app.RefreshViewButton_11 = uibutton(app.Subscore_10Tab, 'push');
            app.RefreshViewButton_11.Text = 'Refresh View';
            app.AutoRefreshCheckBox_11 = uicheckbox(app.Subscore_10Tab);
            app.AutoRefreshCheckBox_11.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_11.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_discfiberexplorer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end